% description: Stuart Fogel
% School of Psychology, University of Ottawa, Canada
% uOttawa Sleep Research Laboratory
% https://www.uottawa-sleeplab.ca
% sfogel@uottawa.ca
% A revision of sleepSMG. sleepSMG originally developed by:
% Stephanie Greer and Jared Saletin
% Walker Lab, UC Berkeley, 2011
% https://sleepsmg.sourceforge.net
% https://www.jaredsaletin.org/hume
% REQUIREMENTS:
% MATLAB version R2020b or later
% Signal Processing Toolbox
% Statistics and Machine Learning Toolbox
% EEGLAB 2023 or later (https://eeglab.org)
% Copyright (C) Stuart Fogel & Sleep Well, 2022.
% See the GNU General Public License v3.0 for more information.
% This file is part of 'Counting Sheep PSG'.
% See https://github.com/stuartfogel/CountingSheepPSG for details.
classdef countingSheepPSG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        mainMenu_m                 matlab.ui.container.Menu
        preferences_m              matlab.ui.container.Menu
        newMontage_m               matlab.ui.container.Menu
        deleteMontage_m            matlab.ui.container.Menu
        ImportMenu_m               matlab.ui.container.Menu
        importEDFeeglab_m          matlab.ui.container.Menu
        importEDFmatlab_m          matlab.ui.container.Menu
        importBrainProducts_m      matlab.ui.container.Menu
        ImportSleepStagesMat_m     matlab.ui.container.Menu
        ImportEventsMat_m          matlab.ui.container.Menu
        loadDataset_m              matlab.ui.container.Menu
        saveDataset_m              matlab.ui.container.Menu
        saveDatasetAs_m            matlab.ui.container.Menu
        generateSleepReport_m      matlab.ui.container.Menu
        generateEventReport_m      matlab.ui.container.Menu
        generateHypnogram_m        matlab.ui.container.Menu
        clearDataset_m             matlab.ui.container.Menu
        quit_m                     matlab.ui.container.Menu
        chan_m                     matlab.ui.container.Menu
        setScale_m                 matlab.ui.container.Menu
        markChannelAsBadData_m     matlab.ui.container.Menu
        interpolateBadChannel_m    matlab.ui.container.Menu
        downsampleChannels_m       matlab.ui.container.Menu
        rereferenceChannels_m      matlab.ui.container.Menu
        filterChannels_m           matlab.ui.container.Menu
        editChannels_m             matlab.ui.container.Menu
        ASR_m                      matlab.ui.container.Menu
        ICA_m                      matlab.ui.container.Menu
        runICA_m                   matlab.ui.container.Menu
        displayICA_m               matlab.ui.container.Menu
        deleteICA_m                matlab.ui.container.Menu
        PowerSpectralAnalysis_m    matlab.ui.container.Menu
        FFT_m                      matlab.ui.container.Menu
        Plot_FFT_m                 matlab.ui.container.Menu
        Plot_Spectrogram_m         matlab.ui.container.Menu
        Plot_Topo_m                matlab.ui.container.Menu
        hideChan_m                 matlab.ui.container.Menu
        showChan_m                 matlab.ui.container.Menu
        rescaleSelectedChannels_m  matlab.ui.container.Menu
        mark_m                     matlab.ui.container.Menu
        detectMovement_m           matlab.ui.container.Menu
        detectSpindles_m           matlab.ui.container.Menu
        detectSlowWaves_m          matlab.ui.container.Menu
        detectEyeMovements_m       matlab.ui.container.Menu
        OnOffEventMark_m           matlab.ui.container.Menu
        clearEvents_m              matlab.ui.container.Menu
        hideEvents_m               matlab.ui.container.Menu
        showEvents_m               matlab.ui.container.Menu
        editLightsTags_m           matlab.ui.container.Menu
        editRecordingStart_m       matlab.ui.container.Menu
        info_m                     matlab.ui.container.Menu
        openUserManual_m           matlab.ui.container.Menu
        checkUpdate_m              matlab.ui.container.Menu
        registerCountingSheep_m    matlab.ui.container.Menu
        hKeysInfo_m                matlab.ui.container.Menu
        aboutCountingSheep_m       matlab.ui.container.Menu
        uitoolbar1                 matlab.ui.container.Toolbar
        open_t                     matlab.ui.container.toolbar.PushTool
        save_t                     matlab.ui.container.toolbar.PushTool
        scaleSmall_t               matlab.ui.container.toolbar.PushTool
        scaleBigger_t              matlab.ui.container.toolbar.PushTool
        resetScale_t               matlab.ui.container.toolbar.PushTool
        playPause_t                matlab.ui.container.toolbar.ToggleTool
        lightsOff_t                matlab.ui.container.toolbar.PushTool
        lightsOn_t                 matlab.ui.container.toolbar.PushTool
        jumpTo_t                   matlab.ui.container.toolbar.PushTool
        markMode_t                 matlab.ui.container.toolbar.ToggleTool
        ICA_t                      matlab.ui.container.toolbar.ToggleTool
        help_t                     matlab.ui.container.toolbar.PushTool
        EpochsSpinnerLabel         matlab.ui.control.Label
        numEpochs                  matlab.ui.control.Spinner
        DisplayMontageLabel        matlab.ui.control.Label
        plotSleepIN                matlab.ui.control.DropDown
        markStr                    matlab.ui.control.EditField
        delMark                    matlab.ui.control.Button
        mergeMark                  matlab.ui.control.Button
        scroll                     matlab.ui.control.Button
        EventMarkingToolsLabel     matlab.ui.control.Label
        allChannelsMode            matlab.ui.control.CheckBox
        SleepScoringToolsLabel     matlab.ui.control.Label
        arrowLeft_B                matlab.ui.control.Button
        Wake_B                     matlab.ui.control.Button
        NREM1_B                    matlab.ui.control.Button
        NREM2_B                    matlab.ui.control.Button
        SWS_B                      matlab.ui.control.Button
        REM_B                      matlab.ui.control.Button
        arrowRight_B               matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        time_S                     matlab.ui.control.Label
        dataset_S                  matlab.ui.control.Label
        traces                     matlab.ui.control.UIAxes
        hypnogram                  matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    %% Counting Sheep PSG
    %
    % EEGLAB-compatible manual sleep stage scoring, signal processing and event
    % marking of polysomnographic (PSG) data in MATLAB
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    % Stuart Fogel
    % School of Psychology, University of Ottawa, Canada
    % uOttawa Sleep Research Laboratory
    % https://www.uottawa-sleeplab.ca
    % sfogel@uottawa.ca
    %
    % A revision of sleepSMG. sleepSMG originally developed by:
    % Stephanie Greer and Jared Saletin
    % Walker Lab, UC Berkeley, 2011
    % https://sleepsmg.sourceforge.net
    % https://www.jaredsaletin.org/hume
    %
    % HOW TO CITE:
    % L.B. Ray, D. Baena & S.M. Fogel (2024). “Counting sheep PSG”: EEGLAB-compatible 
    % open-source matlab software for signal processing, visualization, event marking 
    % and staging of polysomnographic data, Journal of Neuroscience Methods, 407, 110162. 
    % https://doi.org/10.1016/j.jneumeth.2024.110162.
    %
    % REQUIREMENTS:
    % MATLAB version R2020b or later
    % Signal Processing Toolbox
    % Statistics and Machine Learning Toolbox
    % EEGlab 2023 or later (https://eeglab.org)
    %
    % USAGE:
    % See included 'User Manual.pdf' for details.
    % 1. Launch by typing 'countingSheepPSG' at command prompt.
    % 2. Customize display montage in:
    % ~/montages/sleep_montage_default.m (rename and save to new file)
    % 3. Load an EEGLAB PSG dataset for signal preprocessing and annotation.
    %
    % Works best if recording includes:
    % - Recording start time event (at first data point)
    % - Lights Off and Lights On events
    %
    % Sleep scoring keyboard shortcuts:
    % 0 - Wake
    % 1 - NREM 1
    % 2 - NREM 2
    % 3 - SWS
    % 4 - REM
    % . - Unscored
    % backspace/delete - delete selected event(s)
    %
    % Event marking mode:
    % Single-point events can be created using single mouse click
    % Events with a duration can be created using mouse click-and-drag
    % Enable 'all channels' mode to mark events without a channel
    % Single-click existing event to select/deselect for delete/merge

    % Copyright (C) Stuart Fogel & Sleep Well, 2022.
    % See the GNU General Public Licence v3.0 for more information.
    %
    % This file is part of 'Counting Sheep PSG'.
    % See https://github.com/stuartfogel/CountingSheepPSG for details.
    %
    % Redistribution and use in source and binary forms, with or without
    % modification, are permitted provided that the following conditions are met:
    %
    % 1. Redistributions of source code must retain the above author, licence,
    % copyright notice, this list of conditions, and the following disclaimer.
    %
    % 2. Redistributions in binary form must reproduce the above author, licence,
    % copyright notice, this list of conditions, and the following disclaimer in
    % the documentation and/or other materials provided with the distribution.
    %
    % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
    % AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
    % IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE
    % ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT HOLDER OR CONTRIBUTORS BE
    % LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR
    % CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF
    % SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS
    % INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN
    % CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE)
    % ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED OF
    % THE POSSIBILITY OF SUCH DAMAGE.
    %
    % Counting Sheep PSG is intended for research purposes only. Any commercial
    % or medical use of this software and source code is strictly prohibited.
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Properties that correspond to app components
    properties (Access = public)
        handles     % handle to store app data
        EEG         % EEGLAB dataset (used to flag output argument to EEGLAB)
        filepath    % path for current directory in list
        filename    % name of file selected
    end

    % Properties that correspond to app components
    properties (Access = private)
        fileSelectData % temp output from fileSelect
        saveDatasetData % temp output from saveDatasetAs
    end

    methods (Access = public)
        
        % fileSelect app calls this function to return vales as argin
        function getFileSelectData(app,filepath,filename)
            app.filepath = filepath;
            app.filename = filename;
        end
        
        % saveDatasetAs app calls this function to return vales as argin
        function getSaveDatasetData(app,filepath,filename)
            app.filepath = filepath;
            app.filename = filename;
            if isempty(filepath) || isempty(filename)
                saveON(app);
            else
                saveOFF(app);
                app.filename = filename;
                app.dataset_S.Text = [filename '.set'];
            end
        end

    end

    methods (Access = private)
        
        %% Ensures date format is valid for use with datetime function
        function validDate(app)
            % validate recStart time format
            if ~strcmp(app.handles.userRecStartFormat, 'yyyy-MM-dd HH:mm:ss.SSS') % not default datetime compatible format?
                if strcmp(app.handles.userRecStartFormat, 'yyyy-mm-dd HH:MM:SS.FFF') % old format?
                    app.handles.userRecStartFormat = 'yyyy-MM-dd HH:mm:ss.SSS'; % update with datetime compatible time format
                elseif ~strcmp(app.handles.userRecStartFormat, 'yyyy-mm-dd HH:MM:SS.FFF') % not legacy default format?
                    help datetime
                    warning('Non-default MATLAB datetime format entered. Suggested format: yyyy-MM-dd HH:mm:ss.SSS. See datetime help documentation above.')
                    try
                        datetime(char(datetime('now'), app.handles.userRecStartFormat), 'InputFormat', app.handles.userRecStartFormat); % check if user-defined time format works
                    catch % that didn't work
                        choice = uiconfirm(app.figure1,['Invalid / legacy MATLAB datetime format entered.', newline, 'Suggested format: yyyy-MM-dd HH:mm:ss.SSS', newline, 'Would you like to automatically update your montage?'],'Invalid time format','Icon','question','Options',{'Yes','No'});
                        waitfor(choice);
                        if strcmp(choice,'Yes')
                            recStartFormat = 'yyyy-MM-dd HH:mm:ss.SSS';
                            newMontage_m_Callback(app,recStartFormat);
                        end
                    end                    
                else
                    help datetime
                    warning('Invalid MATLAB datetime format entered. Suggested format: yyyy-MM-dd HH:mm:ss.SSS. See datetime help documentation above.')
                end
            end
        end
        
        %% Loads new EEGlab dataset
        function loadNewDataset(app,clearSet,noPopUp)
            if clearSet == 0
                % load the dataset
                if noPopUp == 0
                    if isfield(app.handles,'EEG')
                        app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'set');
                    else
                        app.fileSelectData = fileSelect(app, '', '', 'set');
                    end
                    waitfor(app.fileSelectData);
                    if isempty(app.filename)
                        returnFocus2Figure(app);
                        return
                    end
                    app.handles.EEG.setname = app.filename(1:end-4);
                    app.handles.EEG.filename = app.filename;
                    app.handles.EEG.filepath = [app.filepath filesep];
                    progress = uiprogressdlg(app.figure1,'Title','Loading Dataset','Indeterminate','on');
                    app.handles.EEG = pop_loadset(app.handles.EEG.filename, app.handles.EEG.filepath); % load the set file
                elseif noPopUp == 1
                    progress = uiprogressdlg(app.figure1,'Title','Loading Dataset','Indeterminate','on');
                    app.handles.EEG = pop_loadset('filename',app.handles.EEG.filename,'filepath',app.handles.EEG.filepath); % load the set file
                end
                % rebuild the EEG.event.urevent field (needed for event handling) and check dataset
                app.handles.EEG = eeg_checkset(app.handles.EEG);
                app.handles.EEG = eeg_checkset(app.handles.EEG, 'makeur');
                app.handles.EEG = eeg_checkset(app.handles.EEG,'eventconsistency');
                % file name for countingSheep scoring data (*.mat) file
                timeStamp = char(datetime("now"),'yyyy-MM-dd-HH-mm-ss');
                app.handles.stageFileIN = fullfile(app.handles.EEG.filepath,strcat(app.handles.EEG.setname, '_', timeStamp, '.mat'));
                close(progress);
                saveON(app);
            else
                clearDataset_m_Callback(app);
                saveOFF(app);
            end
            % progress bar
            progress = uiprogressdlg(app.figure1,'Title','Intializing Dataset','Indeterminate','on');
            % initialize sleep stage app.handles
            initStaging(app);
            % get recording start event
            getRecStart(app, clearSet);
            % get lights on / off tags
            getLightsOnOff(app, clearSet);
            % load montage
            plotFCN = app.plotSleepIN.Items;
            boxInd = ismember(plotFCN, app.plotSleepIN.Value);
            eval([plotFCN{boxInd}, '(app);']); % calls study-specific montage
            % get sleep stage events
            getSleepStages(app, clearSet);
            % get other events
            overwrite = 1;
            stageData = [];
            getEvents(app, stageData, clearSet, overwrite);
            % add the last of the plotting options and plot stages and data
            app.handles.hideChans = {};
            app.handles.scaleChans = {''};
            app.handles.curScale = 100;
            app.handles.scrollMode = [];
            app.handles.reScaledChannels = [];
            app.handles.reScaleFactors = ones(length(app.handles.EEG.chanlocs),1)';
            app.handles.ica = 0; % turn off option to plot ICA data
            runPlot(app); % plot traces
            arrowLeft_B_Callback(app, []); % doesn't hurt and can resolve some issues when loading hypnogram
            % save it to the mat file
            app.handles.stageData.events = app.handles.EEG.event;
            stageData = app.handles.stageData;
            if isfield(app.handles, 'stageFileIN')
                save(app.handles.stageFileIN, 'stageData');
            end
            close(progress);
        end

        %% initialize sleep scoring defaults
        % recording start, lights off, lights on, stage lables, stage onsets, stage times
        function initStaging(app)
            winSize = str2double(app.handles.winIN);
            srate = app.handles.EEG.srate;
            % set stage info to user input
            app.handles.stageData = [];
            app.handles.stageData.win = winSize;
            % set defaults
            app.handles.stageData.recStart = datetime(year(now),month(1),day(1),0,0,0,000);
            app.handles.stageData.recStop = app.handles.stageData.recStart + seconds(app.handles.EEG.pnts/app.handles.EEG.srate - 1);
            app.handles.stageData.lightsOFF = app.handles.stageData.recStart; % lights off at start of recording
            app.handles.stageData.lightsON = app.handles.stageData.recStop; % lights on at end of recording
            app.handles.stageData.srate = srate;
            app.handles.stageData.stages = (ones(fix(length(app.handles.EEG.data)/(winSize*srate)), 1)*5)';
            app.handles.stageData.onsets = ((0:(length(app.handles.stageData.stages) - 1)).*winSize*srate)+1;
            app.handles.stageData.stageTime = (0:(length(app.handles.stageData.stages) - 1))./(60/winSize);
            clear winSize srate
            % create default EEGlab event structure if empty
            if isempty(app.handles.EEG.event)
                app.handles.EEG.event = struct('type',[],'latency',[],'duration',[],'channel',[]); % default
                % this makes the new struct 'empty'
                fields = fieldnames(app.handles.EEG.event)';
                fields{2,1} = {};
                app.handles.EEG.event = struct(fields{:});
                clear fields
            end
            % make sure channel is a field in the event structure
            if ~isfield(app.handles.EEG.event,'channel')
                app.handles.EEG.event(1).channel = [];
            end
        end

        %% Get recording start date and time from EEGlab dataset
        function getRecStart(app,clearSet)
            recStartFound = 0;
            if clearSet ~= 1
                % look for recording start in EEGlab dataset events
                % if found, will replace default entered by initStaging function call
                if ~isempty(app.handles.EEG.event)
                    app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                    app.handles.EEG = eeg_checkset(app.handles.EEG);
                    idx = find([app.handles.EEG.event.latency] == 1); % default is that recording start marker is on the first data point
                    if ~isempty(idx)
                        for nevt = idx
                            try
                                app.handles.stageData.recStart = datetime(app.handles.EEG.event(nevt).type, 'InputFormat', app.handles.userRecStartFormat);  % this can bug, so, we do it in a try - catch
                                app.handles.EEG.comments = char(app.handles.stageData.recStart, app.handles.userRecStartFormat); % that worked, lets also put it in the comments
                                recStartFound = 1;
                                break
                            catch
                                continue
                            end
                        end
                    end
                end
                if recStartFound == 0 % nothing found in the dataset events
                    try % see if it's in the EEG.comments
                        if ~isempty(app.handles.EEG.comments)
                            app.handles.stageData.recStart = datetime(datetime(app.handles.EEG.comments), 'InputFormat', app.handles.userRecStartFormat); % this can bug, so, we do it in a try - catch
                            if ~isempty(app.handles.stageData.recStart)
                                % add recording start marker at first data point
                                app.handles.EEG.event(end+1).type = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                                app.handles.EEG.event(end).latency = 1;
                                app.handles.EEG.event(end).duration = 1;
                                app.handles.EEG.event(end).channel = '';
                            else
                                error('No recording date found in EEG.comments') % force catch below
                            end
                        else
                            error('No recording date found in EEG.comments') % force catch below
                        end
                    catch
                        % nothing found in the file, lets just ask for it
                        choice = uiconfirm(app.figure1,['No recording start time found at start of EEG dataset. If unexpected, check montage.' newline newline 'Do you want to enter a Recording Start time?'],'Missing Tag','Icon','question','Options',{'Yes','No'});
                        app.handles.userRecStartFormat = 'yyyy-MM-dd HH:mm:ss.SSS'; % use default format
                        if strcmp(choice,'Yes')
                            prompt = {['Default Recording Start Time: ',char(app.handles.stageData.recStart,'yyyy-MM-dd HH:mm:ss.SSS'),newline,newline, ... % use the default time format
                                ' Enter recording start time in 24-hr time format: yyyy-MM-dd HH:mm:ss.SSS',newline]};
                            dlgtitle = 'Enter Recording Start Time';
                            dims = [1 100];
                            definput = {char(app.handles.stageData.recStart, 'yyyy-MM-dd HH:mm:ss.SSS')};
                            recStart = inputdlg(prompt,dlgtitle,dims,definput);
                            returnFocus2Figure(app);
                            if ~isempty(recStart)
                                try
                                    app.handles.stageData.recStart = datetime(recStart, 'InputFormat', app.handles.userRecStartFormat); % this can bug, so, we do it in a try - catch
                                    if ~isempty(app.handles.stageData.recStart)
                                        % add valid date to the comments
                                        if isempty(app.handles.EEG.comments)
                                            app.handles.EEG.comments = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                                        end
                                        if ~any(ismember({app.handles.EEG.event.type},{char(app.handles.stageData.recStart, app.handles.userRecStartFormat)})) % make sure it does not already exist in event structure
                                            app.handles.EEG.event(end+1).type = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                                            app.handles.EEG.event(end).latency = 1;
                                            app.handles.EEG.event(end).duration = 1;
                                            app.handles.EEG.event(end).channel = '';
                                        end
                                    end
                                catch
                                    choice = uiconfirm(app.figure1,['Incorrect date format entered.',newline,'Clear dataset and reload.'],'Input Error','Icon','error');
                                    waitfor(choice);
                                    return
                                end
                            end
                        else
                            % none of that worked, just put the default
                            if isempty(app.handles.EEG.comments)
                                app.handles.EEG.comments = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                            end
                            % add recording start marker at first data point
                            app.handles.EEG.event(end+1).type = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                            app.handles.EEG.event(end).latency = 1;
                            app.handles.EEG.event(end).duration = 1;
                            app.handles.EEG.event(end).channel = '';
                        end
                    end
                end
                % we also need to include the recording end time
                app.handles.stageData.recStop = app.handles.stageData.recStart + seconds(app.handles.EEG.pnts/app.handles.EEG.srate - 1);
            end
            app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
            clear idx clearSet nevt
        end

        %% Get lights on and off from EEGlab dataset
        function getLightsOnOff(app, clearSet)
            if ~isempty(app.handles.EEG.event)
                app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                app.handles.EEG = eeg_checkset(app.handles.EEG);
                % look for lights OFF event
                lightsOffIdx = find(cell2mat(cellfun(@(a) strcmpi(a,app.handles.userlightsOFFtag),{app.handles.EEG.event.type}','uniform',false)));
                if any(lightsOffIdx)
                    if length(lightsOffIdx) ~= 1  % there are duplicate lights off tags. WTF.
                        choice = uiconfirm(app.figure1,'Your EEGlab dataset has more than one ''Lights Off'' tag. That can''t be correct! Please fix ''Lights off'' tag in EEGlab dataset.','Multiple Tags','Icon','warning');
                        waitfor(choice);
                        return
                    else
                        app.handles.stageData.lightsOFF = app.handles.stageData.recStart + seconds((app.handles.EEG.event(lightsOffIdx).latency - 1)/app.handles.EEG.srate);
                    end
                else % there is no lights OFF tag in the EEG dataset
                    if clearSet ~= 1
                        choice = uiconfirm(app.figure1,['No ''Lights Off'' tag found in EEG dataset. If unexpected, check montage or datset.' newline newline 'Do you want to enter a ''Lights Off'' time?'],'Missing Tag','Icon','question','Options',{'Yes','No'});
                        if strcmp(choice,'Yes')
                            prompt = {['Default ''Lights Off'' Time: ' char(app.handles.stageData.recStart, app.handles.userRecStartFormat) newline newline...
                                'Enter a ''Lights Off'' time in 24-hr time format: ' app.handles.userRecStartFormat,newline]};
                            dlgtitle = 'Enter Lights Off time';
                            dims = [1 100];
                            definput = {char(app.handles.stageData.recStart, app.handles.userRecStartFormat)};
                            lightsOff = inputdlg(prompt,dlgtitle,dims,definput);
                            returnFocus2Figure(app);
                            if ~isempty(lightsOff)
                                try
                                    app.handles.stageData.lightsOFF = datetime(lightsOff{:}, 'Inputformat', app.handles.userRecStartFormat);
                                catch
                                    choice = uiconfirm(app.figure1,'Incorrect date format entered','Input Error','Icon','error');
                                    waitfor(choice);
                                    return
                                end
                            else % put it at the start of the recording
                                app.handles.stageData.lightsOFF = app.handles.stageData.recStart;
                            end
                        else % put it at the start of the recording
                            app.handles.stageData.lightsOFF = app.handles.stageData.recStart;
                        end
                    end
                    % lights OFF
                    app.handles.EEG.event(end+1).type = app.handles.userlightsOFFtag;
                    app.handles.EEG.event(end).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart) * app.handles.stageData.srate) + 1;
                    app.handles.EEG.event(end).duration = 1;
                    app.handles.EEG.event(end).channel = '';
                end
                clear lightsOffIdx
                % look for lights ON event
                lightsOnIdx = find(cell2mat(cellfun(@(a) strcmpi(a,app.handles.userlightsONtag),{app.handles.EEG.event.type}','uniform',false)));
                if any(lightsOnIdx)
                    if length(lightsOnIdx) ~= 1  % there are duplicate lights on tags. WTF.
                        choice = uiconfirm(app.figure1,'Your EEGlab dataset has more than one ''Lights On'' tag. That can''t be correct! Please fix ''Lights on'' tag in EEGlab dataset.','Multiple tags','Icon','warning');
                        waitfor(choice);
                        return
                    else
                        app.handles.stageData.lightsON = app.handles.stageData.recStart + seconds((app.handles.EEG.event(lightsOnIdx).latency - 1)/app.handles.EEG.srate);
                    end
                else % there is no lights ON tag in the EEG dataset
                    if clearSet ~= 1
                        choice = uiconfirm(app.figure1,['No ''Lights On'' tag found in EEG dataset. If unexpected, check montage or datset.' newline newline 'Do you want to enter a ''Lights On'' time?'],'Missing Tag','Icon','question','Options',{'Yes','No'});
                        if strcmp(choice,'Yes')
                            prompt = {['Default ''Lights On'' Time: ' char(app.handles.stageData.recStop, app.handles.userRecStartFormat) newline newline...
                                'Enter a ''Lights On'' time in 24-hr time format: ' app.handles.userRecStartFormat,newline]};
                            dlgtitle = 'Enter Lights On time';
                            dims = [1 100];
                            definput = {char(app.handles.stageData.recStop, app.handles.userRecStartFormat)};
                            lightsOn = inputdlg(prompt,dlgtitle,dims,definput);
                            returnFocus2Figure(app);
                            if ~isempty(lightsOn)
                                try
                                    app.handles.stageData.lightsON = datetime(lightsOn{:}, 'Inputformat', app.handles.userRecStartFormat);
                                catch
                                    choice = uiconfirm(app.figure1,'Incorrect date format entered','Input Error','Icon','error');
                                    waitfor(choice);
                                    return
                                end
                            else % put it at the end of the recording
                                app.handles.stageData.lightsON = app.handles.stageData.recStop; 
                            end
                        else % put it at the end of the recording
                            app.handles.stageData.lightsON = app.handles.stageData.recStop; 
                        end
                    end
                    % lights ON
                    app.handles.EEG.event(end+1).type = app.handles.userlightsONtag;
                    app.handles.EEG.event(end).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart) * app.handles.stageData.srate) + 1;
                    if app.handles.EEG.event(end).latency > app.handles.EEG.pnts % just in case it is past the end of dataset
                        app.handles.EEG.event(end).latency = app.handles.EEG.pnts;
                    end
                    app.handles.EEG.event(end).duration = 1;
                    app.handles.EEG.event(end).channel = '';
                end
                clear lightsOnIdx
            else % no events in dataset; insert Lights ON and OFF events using defaults
                app.handles.stageData.lightsOFF = app.handles.stageData.recStart; % lights off at start of recording
                app.handles.stageData.lightsON = app.handles.stageData.recStop; % lights on at end of recording
                % lights OFF
                app.handles.EEG.event(end+1).type = app.handles.userlightsOFFtag;
                app.handles.EEG.event(end).latency = 1;
                app.handles.EEG.event(end).duration = 1;
                app.handles.EEG.event(end).channel = '';
                % lights ON
                app.handles.EEG.event(end+1).type = app.handles.userlightsONtag;
                app.handles.EEG.event(end).latency = app.handles.EEG.pnts;
                app.handles.EEG.event(end).duration = 1;
                app.handles.EEG.event(end).channel = '';
            end
            app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
            app.handles.EEG = eeg_checkset(app.handles.EEG);
        end

        %% Get sleep stage events from EEGlab dataset
        function getSleepStages(app, clearSet)
            % look for stage data in existing dataset
            % if found, will replace default entered by initStaging function call
            % select only events that match sleep stage labels
            app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
            app.handles.EEG = eeg_checkset(app.handles.EEG);
            EEGtemp = app.handles.EEG;
            if clearSet == 0
                EEGtemp = pop_selectevent(EEGtemp,'type',app.handles.userStageNames(1:6),'deleteevents','on');
            else
                EEGtemp.event = [];
            end
            % delete boundary events (n.b., not deleted by pop_selectevent by default)
            if ~isempty(EEGtemp.event)
                boundaryIdx = eeg_findboundaries(EEGtemp);
                EEGtemp.event(boundaryIdx) = []; % delete boundary events
            end
            if ~isempty(EEGtemp.event)
                % check for gaps in sleep scoring (from first event to last)
                if any(diff(round(diff([EEGtemp.event.latency]'),0)) ~= 0)
                    win = app.handles.stageData.win*app.handles.EEG.srate;
                    diffIdx = find(round(diff([EEGtemp.event.latency]'),0) ~= win); % find the gaps
                    for nIdx = 1:length(diffIdx)
                        latDiff = EEGtemp.event(diffIdx(nIdx)+1).latency - EEGtemp.event(diffIdx(nIdx)).latency; % get the length of the gap in sleep stages
                        if mod(latDiff,win) == 0 % make sure the gap is a multiple of the epoch window length
                            % fill in the gap with 'Unscored' sleep stages
                            EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                            for nUnscored = 1:latDiff/win
                                EEGtemp.event(end+1).type = 'Unscored';
                                EEGtemp.event(end).latency = EEGtemp.event(diffIdx).latency + win;
                                EEGtemp.event(end).duration = win;
                                EEGtemp.event(end).channel = '';
                                EEGtemp.event(end).urevent = [];
                            end
                            EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                        else
                            choice = uiconfirm(app.figure1,['Inconsistent stage scoring epoch lengths, or missing stage scores.' newline newline 'Check montage settings and consistency of events.'],'Check Montage or Dataset','Icon','warning');
                            waitfor(choice);
                            return
                        end
                    end
                end
                % now, check that sleep stages will fit in scoring matrix
                if length(EEGtemp.event)-1 > length(app.handles.stageData.stages)
                    choice = uiconfirm(app.figure1,['Too many sleep stage events to fit.' newline newline 'Check event type, event alignment with data, and number of stage events.'],'Check Dataset','Icon','warning');
                    waitfor(choice);
                    return
                else
                    % first, convert event type strings to numeric stage codes
                    tempevents = EEGtemp.event;
                    % recode to numerical stages
                    for nStage = 1:length(app.handles.userStageNames)
                        stageIdx = find(strcmp({EEGtemp.event.type},app.handles.userStageNames{nStage}));
                        if ~isempty(stageIdx)
                            for nIdx = stageIdx
                                tempevents(nIdx).type = nStage-1; % this will recode to numerical stages
                                clear stageIdx
                            end
                        end
                    end
                    EEGtemp.event = tempevents;
                    % get timing of first epoch, and determine if aligned with stageData.onsets if not, round down to align
                    firstIdx = find(app.handles.stageData.onsets == fix(EEGtemp.event(1).latency)); % one data point precision
                    if ~isempty(firstIdx) % find if exact match exists in stageTimes
                        nEpochs = length(EEGtemp.event) - length(app.handles.stageData.stages(firstIdx:end));
                        if length(EEGtemp.event) <= length(app.handles.stageData.stages(firstIdx:end)) % it fits
                            for nEvt = 1:length(EEGtemp.event)
                                app.handles.stageData.stages(firstIdx) = EEGtemp.event(nEvt).type;
                                firstIdx = firstIdx + 1;
                            end
                        else
                            for nEvt = 1:length(app.handles.stageData.stages(firstIdx:end)) % it doesn't quite fit. This will truncate extra stage labels
                                app.handles.stageData.stages(firstIdx) = EEGtemp.event(nEvt).type;
                                firstIdx = firstIdx + 1;
                            end
                            choice = uiconfirm(app.figure1,['Existing sleep scoring exceeds record by ' num2str(nEpochs) ' or fewer epochs. If this is unexpected, check dataset.'],'Warning','Icon','warning');
                            waitfor(choice);
                        end
                    else % its misaligned by more than one data point
                        % find the closest value to realign stages
                        minOffset = min(abs(app.handles.stageData.onsets - EEGtemp.event(1).latency));
                        minOffsetIdx = find(abs(app.handles.stageData.onsets - EEGtemp.event(1).latency) == minOffset);
                        offsetInPnts = app.handles.stageData.onsets(minOffsetIdx) - EEGtemp.event(1).latency;
                        % User confirm that they are ok to realign stage labels to epoch boundaries
                        dlgTitle    = 'Warning!';
                        dlgQuestion = {['Realigning latency of sleep scoring events to epoch boundaries by ' num2str(offsetInPnts/EEGtemp.srate) ' seconds.' newline newline 'Do you wish to continue?']};
                        choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                        if strcmp(choice,'Yes')
                            if length(EEGtemp.event) <= length(app.handles.stageData.stages(minOffsetIdx:end))
                                firstIdx = minOffsetIdx;
                                for nEvt = 1:length(EEGtemp.event)
                                    app.handles.stageData.stages(firstIdx) = EEGtemp.event(nEvt).type;
                                    firstIdx = firstIdx + 1;
                                end
                            else % the number of stages are too many to fit. Need to truncate.
                                choice = uiconfirm(app.figure1,['Too many sleep stage events to fit.' newline newline 'Check event type, event alignment with data, and number of stage events.'],'Check Dataset','Icon','warning');
                                waitfor(choice);
                                firstIdx = minOffsetIdx;
                                for nEvt = 1:length(app.handles.stageData.stages(minOffsetIdx:end))
                                    app.handles.stageData.stages(firstIdx) = EEGtemp.event(nEvt).type;
                                    firstIdx = firstIdx + 1;
                                end
                            end
                        else
                            choice = uiconfirm(app.figure1,['Latencies of stage scoring events are not aligned with epoch boundaries.' newline newline 'This can be corrected by truncating start of dataset to be aligned with latency of first sleep stage event.'],'Check Dataset','Icon','warning');
                            waitfor(choice);
                            return
                        end
                        clear dlgTitle dlgQuestion choice
                    end
                end
                clear EEGtemp nEvt nStage stageIdx nIdx tempevents minOffsetIdx minOffset offsetInPnts firstIdx nEpochs
            else
                if clearSet ~= 1
                    choice = uiconfirm(app.figure1,['No sleep stages found.' newline newline 'If this is unexpected, check montage settings to define sleep stage labels.'],'Unscored record?','Icon','warning');
                    waitfor(choice);
                end
            end
        end

        %% Get events from EEGlab dataset
        function getEvents(app, stageData, clearSet, overwrite)
            % now, lets add the events that are not sleep stages
            % remove old sleep stages, keep other original events
            if ~isempty(app.handles.EEG.setname) % this checks if its an empty dataset
                if ~isempty(stageData)
                    EEGtemp = app.handles.EEG;
                    EEGtemp.event = stageData.events;
                    EEGtemp = pop_selectevent(EEGtemp,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                    % select events to import
                    tempEvents = unique({EEGtemp.event.type});
                    [idx,~] = listdlg('PromptString','Select event types to import', 'SelectionMode', 'multiple', 'ListString', tempEvents, 'ListSize', [250,250]);
                    if ~isempty(idx)
                        evtIdx = ismember({stageData.events.type},tempEvents(idx));
                        EEGtemp.event = stageData.events(evtIdx);
                        % need to make sure the fields are the same
                        eegfields = fieldnames(app.handles.EEG.event(1));
                        for i = 1:length(eegfields)
                            if ~isfield(EEGtemp.event(1),eegfields{i})
                                EEGtemp.event(1).(eegfields{i}) = []; % add an empty entry to create field
                            end
                        end
                        Mrkfields = fieldnames(EEGtemp.event(1));
                        for i = 1:length(Mrkfields)
                            if ~isfield(app.handles.EEG.event(1),Mrkfields{i})
                                app.handles.EEG.event(1).(Mrkfields{i}) = []; % add an empty entry to create field
                            end
                        end
                        returnFocus2Figure(app);
                    else
                        choice = uiconfirm(app.figure1,['No events found.' newline newline 'If this is unexpected, check original dataset.'],'No events found','Icon','warning');
                        waitfor(choice);
                    end
                else
                    EEGtemp = pop_selectevent(app.handles.EEG,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                end
            else
                EEGtemp.event = []; % for when we are clearing the dataset
            end
            if ~isempty(EEGtemp.event)
                % change from 0, which is not a valid channel, to empty char, which means 'no channel'.
                for nEvt = 1:length(EEGtemp.event)
                    if any(double(EEGtemp.event(nEvt).channel) == 0) || isempty(EEGtemp.event(nEvt).channel)
                        EEGtemp.event(nEvt).channel = '';
                    end
                end
                if overwrite == 1
                    app.handles.EEG.event = EEGtemp.event;
                    app.handles.stageData.events = EEGtemp.event;
                else % merge
                    app.handles.EEG.event = [app.handles.EEG.event EEGtemp.event];
                    app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                    app.handles.EEG = eeg_checkset(app.handles.EEG);
                    app.handles.stageData.events = app.handles.EEG.event;
                end
            else
                if clearSet == 0
                    choice = uiconfirm(app.figure1,['No events found.' newline newline 'If this is unexpected, check original dataset.'],'No events found','Icon','warning');
                    waitfor(choice);
                end
                app.handles.EEG.event = [];
            end
            clear EEGtemp emptyChIdx chIdx
        end

        %% Saves EEGlab dataset
        function saveDataset(app,saveAs)
            progress = uiprogressdlg(app.figure1,'Title','Saving Dataset','Indeterminate','on');
            if isfield(app.handles, 'stageData')
                % check if rescaling was done previously
                if ~isempty(app.handles.reScaledChannels)
                    chIdx = app.handles.reScaledChannels;
                    for nIdx = 1:length(chIdx) % undo scaling of EEG data
                        app.handles.EEG.data(chIdx(nIdx),:) = app.handles.EEG.data(chIdx(nIdx),:)*1/app.handles.reScaleFactors(chIdx(nIdx));
                    end
                    % reset scaling handles
                    app.handles.reScaledChannels = [];
                    app.handles.reScaleFactors = ones(length(app.handles.EEG.chanlocs),1)';
                    % plot rescaled data
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                end
                app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                app.handles.EEG = eeg_checkset(app.handles.EEG);
                % convert stage times to onsets in data points
                winlen = app.handles.stageData.win * app.handles.EEG.srate;
                stages = num2cell(app.handles.stageData.stages);
                onsets = app.handles.stageData.onsets;
                % convert stage codes to labels
                stages(app.handles.stageData.stages == 0) = app.handles.userStageNames(1); % wake
                stages(app.handles.stageData.stages == 1) = app.handles.userStageNames(2); % NREM1
                stages(app.handles.stageData.stages == 2) = app.handles.userStageNames(3); % NREM2
                stages(app.handles.stageData.stages == 3) = app.handles.userStageNames(4); % SWS
                stages(app.handles.stageData.stages == 4) = app.handles.userStageNames(5); % REM
                stages(app.handles.stageData.stages == 5) = app.handles.userStageNames(6); % Unscored
                % create empty EEGlab event structure to match existing to merge with
                if isempty(app.handles.EEG.event) % very unlikely, but just checking
                    stageEvents = struct('type',[],'latency',[],'duration',[],'channel',[]);
                else
                    fields = fieldnames(app.handles.EEG.event)';
                    fields{2,1} = {};
                    stageEvents = struct(fields{:});
                    clear fields
                end
                % create eeglab stage events from countingSheep scoring
                for nevt = 1:length(onsets)
                    if nevt == length(onsets) % if its the last event, so only make duration up until end of recording if necessary
                        if ischar(stages{nevt})
                            stageEvents(end+1).type = char(stages(nevt));
                            stageEvents(end).latency = onsets(nevt);
                            if stageEvents(end).latency + winlen > app.handles.EEG.pnts
                                choice = uiconfirm(app.figure1,['Duration of last epoch exceeds end of recording by ' num2str((app.handles.EEG.pnts - stageEvents(end).latency + winlen)/app.handles.EEG.srate) ' seconds. Truncating last epoch.' newline],'Truncate last event','Icon','warning');
                                waitfor(choice);
                                stageEvents(end).duration = app.handles.EEG.pnts - stageEvents(end).latency;
                            else
                                stageEvents(end).duration = winlen;
                            end
                        end
                    else
                        % add the stage scoring events
                        stageEvents(end+1).type = char(stages(nevt));
                        stageEvents(end).latency = onsets(nevt);
                        stageEvents(end).duration = winlen;
                    end
                end
                % now, lets add it to the events structure
                stageEvents = [stageEvents app.handles.EEG.event];
                EEGtemp = app.handles.EEG;
                EEGtemp.event = stageEvents;
                % change from [], which is not consistent, to empty char, which means 'no channel'.
                evidx = find(isempty([EEGtemp.event.channel]));
                for nEvt = evidx
                    if isempty(EEGtemp.event(nEvt).channel)
                        EEGtemp.event(nEvt).channel = '';
                    end
                end
                % sort and check dataset
                EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                EEGtemp = eeg_checkset(EEGtemp,'makeur');
                EEGtemp = eeg_checkset(EEGtemp);
                % save it to the mat file
                app.handles.stageData.events = EEGtemp.event;
                stageData = app.handles.stageData;
                stageData.events = EEGtemp.event;
                save(app.handles.stageFileIN, 'stageData');
                clear EEG stageEvents evidx nEvt
            else
                EEGtemp = app.handles.EEG;
            end
            % save out the eeglab dataset
            if saveAs == 0 % saves over current dataset without pop-up
                save(fullfile(app.handles.EEG.filepath, app.handles.EEG.filename), '-v7.3', '-mat', '-struct', 'EEGtemp');
            elseif  saveAs == 1 % pop-up with option to save as another dataset
                app.saveDatasetData = saveDatasetAs(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'set');
                waitfor(app.saveDatasetData);
                if isempty(app.filename)
                    returnFocus2Figure(app);
                    return
                end
                EEGtemp.setname = app.filename;
                EEGtemp.filename = [app.filename '.set'];
                EEGtemp.filepath = [app.filepath filesep];
                save(fullfile(EEGtemp.filepath, EEGtemp.filename), '-v7.3', '-mat', '-struct', 'EEGtemp');
                timeStamp = strrep(strrep(char(datetime("now")),' ','-'),':','-');
                app.handles.stageFileIN = fullfile(EEGtemp.filepath,strcat(EEGtemp.setname, '_', timeStamp, '.mat'));
                app.handles.EEG.setname = EEGtemp.setname;
                app.handles.EEG.filename = EEGtemp.filename;
                app.handles.EEG.filepath = EEGtemp.filepath;
            end
            close(progress);
            returnFocus2Figure(app);
        end

        %% Create and save new montage .m file
        function createMontage(app,scriptname)
            curDir = pwd;
            montageDir = which('sleep_montage_default');
            montageDir = fileparts(montageDir);
            cd(montageDir)
            fid = fopen(scriptname, 'w');
            fprintf(fid, ['function [electrodes , colors] = ' scriptname(1:end-2) '(app)\n']);
            fprintf(fid, ['electrodes = {''' strjoin(app.handles.userChannelLabels,''';''') '''};\n']);
            fprintf(fid, ['colors = ''' app.handles.userChannelColors ''';\n']);
            fprintf(fid, ['stages = {''' strjoin(app.handles.userStageNames,''';''') '''};\n']);
            fprintf(fid, ['winSize = ' app.handles.winIN ';\n']);
            fprintf(fid, ['lightsONtag = ''' app.handles.userlightsONtag ''';\n']);
            fprintf(fid, ['lightsOFFtag = ''' app.handles.userlightsOFFtag ''';\n']);
            fprintf(fid, ['recStartFormat = ''' app.handles.userRecStartFormat ''';\n']);
            fprintf(fid, 'app.handles.userChannelLabels = electrodes;\n');
            fprintf(fid, 'app.handles.userChannelColors = colors;\n');
            fprintf(fid, 'app.handles.userStageNames = stages;\n');
            fprintf(fid, 'app.handles.userlightsONtag = lightsONtag;\n');
            fprintf(fid, 'app.handles.userlightsOFFtag = lightsOFFtag;\n');
            fprintf(fid, 'app.handles.userRecStartFormat = recStartFormat;\n');
            fprintf(fid, 'app.handles.winIN = num2str(winSize);\n');
            fprintf(fid, 'end');
            fclose(fid);
            cd(curDir)
            clear curDir montageDir
            sleepPath = which('countingSheepPSG');
            updateMontageList(app,sleepPath);
        end

        %% Set Montage List based on montage directory
        function updateMontageList(app,sleepPath)
            montageNames = dir(fullfile([fileparts(sleepPath) filesep 'montages']));
            montages = [];
            if any(contains({montageNames(:).name},'_montage','IgnoreCase',1))
                montageIdx = find(contains({montageNames(:).name},'_montage','IgnoreCase',1));
                for idx=1:length(montageIdx)
                    montages{idx} = montageNames(montageIdx(idx)).name(1:end-2);
                end
            else
                choice = uiconfirm(app.figure1,['No montage files found.' newline newline 'Requires at least one montage m-file using the word ''montage'' in the filename. If default montage has been moved, modified or deleted, please reinstall Counting Sheep PSG.'],'Missing Montage Files','Icon','warning');
                waitfor(choice);
                return
            end
            app.plotSleepIN.Items = montages;
            clear idx sleepPath montages montageNames montageIdx
        end

        %% jump to epoch
        function jumpto(app, ptX)
            winSize = str2double(app.handles.winIN);
            srate = app.handles.EEG.srate;
            newX = [ptX+1, ptX + winSize*srate];
            range = newX(1):newX(2);
            plotSleepData(app, range);
        end

        %% plot EEG data
        function runPlot(app)
            winSize = str2double(app.handles.winIN);
            if ~isfield(app.handles,'EEG')
                srate = 500;
            else
                srate = app.handles.EEG.srate;
            end
            range = 1:(winSize*srate);
            plotSleepData(app, range);
        end

        %% call user-defined montages and loads them
        function plotSleepData(app, range)
            plotFCN = app.plotSleepIN.Items;
            boxInd = ismember(plotFCN, app.plotSleepIN.Value);
            [electrodes , colors] = eval([plotFCN{boxInd}, '(app);']); % calls study-specific montage, then, load montage
            validDate(app); % validate recStart time format
            loadMontage(app, range, electrodes, colors); % loads montage and plots data
            updateStage(app, []); % plot hypnogram
        end

        %% Plots custom montage, stage scoring and events: traces
        function loadMontage(app, range, electrodes, colors)
            % check that montage matches data
            range = fix(range); % some systems allow non-integer values!
            if isfield(app.handles, 'EEG') && ~isempty(app.handles.EEG.chanlocs) && app.handles.ica ~= 1
                if ~all(ismember(app.handles.userChannelLabels,{app.handles.EEG.chanlocs.labels}'))
                    choice = uiconfirm(app.figure1,'Missing channel(s) in montage. Edit or create new montage.','Montage Incorrect','Icon','error');
                    waitfor(choice);
                    return
                end
            end
            % handle channel scaling and hidden channels
            if isfield(app.handles,'curScale')
                scaleSpace = app.handles.curScale;
            else
                scaleSpace = 100;
            end
            if isfield(app.handles,'hideChans')
                hideCh = app.handles.hideChans;
            else
                hideCh = {};
            end
            if isfield(app.handles,'scaleChans')
                scaleCh = app.handles.scaleChans;
            else
                scaleCh = {''};
            end
            hold(app.traces, 'off');
            % Plot all of the electrode data on the main axes (app.traces)
            % Loop through each electrode in the above list
            if isfield(app.handles, 'EEG')
                if app.handles.ica == 1 % display ICA components instead of EEG
                    for e = 1:length(electrodes)
                        % Makes sure the current range is legal.
                        if range(1) > 0 && range(end) < length(app.handles.EEG.icaact)
                            % extracts the data to be ploted and multiples by -1 so that the data is ploted inverted.
                            centerData = (app.handles.EEG.icaact(e,range))*-1;
                            % plots the data for this electrode at the appropriate y axis location.
                            plotLines = plot(app.traces, range, centerData + e*scaleSpace, colors(e), 'PickableParts', 'none');
                            % Adds a tag to each plot line for use with event marking
                            for i = 1:length(plotLines)
                                set(plotLines(i), 'Tag', sprintf('scale:%d;chan:%d',e*scaleSpace,e));
                            end
                            hold(app.traces, 'on');
                            % Here the scale lines are plotted if the current electrode is in the scaleCh list.
                            if ismember(electrodes{e}, scaleCh)
                                plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace - 75, 'r');
                                plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace - 37.5, 'r');
                                plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace, 'r--');
                                plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace + 37.5, 'r');
                                plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace + 75, 'r');
                            end
                        end
                    end
                else % display EEG, not ICA
                    for e = 1:length(electrodes)
                        % find data for that electrode by name
                        for c = 1:length(app.handles.EEG.chanlocs)
                            Elect = app.handles.EEG.chanlocs(c).labels;
                            % Checks to see if this electrode should be ploted
                            if strcmp(electrodes{e}, Elect) == 1 && ~ismember(electrodes{e}, hideCh)
                                % Makes sure the current range is legal.
                                if range(1) > 0 && range(end) < length(app.handles.EEG.data)
                                    % extracts the data to be ploted and multiples by -1 so that the data is ploted inverted.
                                    centerData = (app.handles.EEG.data(c,range))*-1;
                                    % plots the data for this electrode at the appropriate y axis location.
                                    plotLines = plot(app.traces, range, centerData + e*scaleSpace, colors(e), 'PickableParts', 'none');
                                    % Adds a tag to each plot line for use with event marking
                                    for i = 1:length(plotLines)
                                        set(plotLines(i), 'Tag', sprintf('scale:%d;chan:%d',e*scaleSpace,c));
                                    end
                                    hold(app.traces, 'on');
                                    % Here the scale lines are plotted if the current electrode is in the scaleCh list.
                                    if ismember(electrodes{e}, scaleCh)
                                        plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace - 75, 'r');
                                        plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace - 37.5, 'r');
                                        plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace, 'r--');
                                        plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace + 37.5, 'r');
                                        plot(app.traces, range, zeros(size(centerData)) + e*scaleSpace + 75, 'r');
                                    end
                                end
                            end
                        end
                    end
                end
            end
            clear e c hideCh scaleCh scaleCh centerData plotLines i
            % Fix the axes labels and ranges
            % Y axis - uses electrode list as labels
            set(gca,'TickLabelInterpreter','none');
            maxY = scaleSpace*(length(electrodes) + 1);
            set(app.traces, 'Ylim', [0, maxY], 'YTick', scaleSpace:scaleSpace:(maxY - scaleSpace), 'YTickLabel', electrodes);
            % X axis
            if isfield(app.handles, 'EEG')
                srate = app.handles.EEG.srate;
                newX = [range(1), range(end)];
                xlim(app.traces, newX);
                set(app.traces, 'XTick', newX(1):1*srate:newX(2), 'XTickLabel', ((newX(1) - 1)/srate):1:((newX(2) - 1)/srate), 'XGrid', 'on');
                % plot lights on and lights off lines
                onT = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart))*srate;
                offT = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart))*srate;
                plot(app.traces, [onT, onT], [0, maxY], 'r', 'LineWidth', 4);
                plot(app.traces, [offT, offT], [0, maxY], 'g', 'LineWidth', 4);
                clear srate plotLines onT offT
                % add stage label watermark label to traces
                if app.numEpochs.Value > 1
                    curStage = app.handles.stageData.stages(find(ismember(app.handles.stageData.onsets, app.traces.XLim(1):app.traces.XLim(end))));
                else
                    curStage = app.handles.stageData.stages(find(app.handles.stageData.onsets == app.traces.XLim(1)));
                end
                % convert stage codes to labels
                if ~isempty(curStage)
                    curStage = {app.handles.userStageNames{curStage+1}};
                    x = 1:length(curStage)+length(curStage)-1;
                    idx = mod(x,2);
                    odd_num = x(logical(idx));
                    counter = 1;
                    for nSt = odd_num
                        xpos = app.traces.XLim(1) + (app.traces.XLim(2) - (app.traces.XLim(1)-1))*(nSt/(length(curStage)*2));
                        ypos = (app.traces.YLim(2) - (app.traces.YLim(1)))/2;
                        text(app.traces,xpos,ypos,curStage{counter},'FontSize',100,'Color',[0 0 0 0.25],'HorizontalAlignment','center','VerticalAlignment','middle','PickableParts','none');
                        counter = counter + 1;
                    end
                    clear counter
                end
                clear curStage
                if gca ~= app.traces % fixes axes, if needed
                    axes(app.traces);
                end
                % handle events
                if ~isempty(app.handles.EEG.event) % checks for events
                    if isfield(app.handles,'hideEvents') % check for events to hide
                        hideEvt = app.handles.hideEvents;
                    else
                        hideEvt = {};
                    end
                    if isfield(app.handles, 'recPosition')
                        app.handles = rmfield(app.handles, 'recPosition'); % clear cache of any previous event selections
                        app.handles = rmfield(app.handles, 'curPosition'); % clear cache of any previous event selections
                        app.handles = rmfield(app.handles, 'recLabel'); % clear cache of any previous event selections
                    end
                    % Checks for any events in the current data range and plot them
                    % create empty EEGlab event structure to match existing to merge with
                    fields = fieldnames(app.handles.EEG.event)';
                    fields{2,1} = {};
                    tempEvent = struct(fields{:});
                    clear fields
                    evtPnts = cell(1,length(app.handles.EEG.event)); % preallocate
                    % using a for loop
                    nCurEvt = 1;
                    for nEvt=1:length(app.handles.EEG.event)
                        evtPnts{nEvt} = app.handles.EEG.event(nEvt).latency:app.handles.EEG.event(nEvt).latency+app.handles.EEG.event(nEvt).duration-1; % get event range in data
                        evtIn(nCurEvt) = any(ismember(range(1):range(end), fix(evtPnts{nEvt}))); % does any of this range overlap with the current epoch?
                        nCurEvt = nCurEvt + 1;
                    end
%                     % using a more vectorized approach. May be faster for very long loop?
%                     for nEvt=1:length(app.handles.EEG.event)
%                         evtPnts{nEvt} = app.handles.EEG.event(nEvt).latency:app.handles.EEG.event(nEvt).latency+app.handles.EEG.event(nEvt).duration-1; % get event range in data
%                     end
%                     evtPnts = cellfun(@(f)fix(f),evtPnts,'UniformOutput',false);
%                     evtIn = cellfun(@(m)any(ismember(range(1):range(end),m)),evtPnts); % does any of this range overlap with the current epoch?
                    clear evtPnts nCurEvt
                    if any(evtIn)
                        curEvt = app.handles.EEG.event(find(evtIn));
                        for nEvt = 1:length(curEvt)
                            if curEvt(nEvt).latency < range(1) % starts before current epoch
                                curEvt(nEvt).duration = curEvt(nEvt).duration - (range(1) - curEvt(nEvt).latency);
                                curEvt(nEvt).latency = range(1); % change latency to min of current epoch for display purposes
                            end
                            if curEvt(nEvt).latency+curEvt(nEvt).duration-1 > range(end) % extends past current epoch
                                curEvt(nEvt).duration = range(end)-curEvt(nEvt).latency; % change durantion to max of current epoch for display purposes
                            end
                            tempEvent = [tempEvent curEvt(nEvt)]; % concatenate
                        end
                    end
                    clear curEvt evtIn
                    maxY = scaleSpace*(length(electrodes) + 1);
                    xy = [];
                    for nEvt = 1:length(tempEvent)
                        % checks for any events that are in the current range
                        if tempEvent(nEvt).latency >= range(1) && tempEvent(nEvt).latency <= range(end) && ~ismember(tempEvent(nEvt).type, hideEvt)
                            % plots a rectangle for each event
                            if isfield(tempEvent(nEvt), 'channel')
                                if ~isempty(tempEvent(nEvt).channel) && str2double(tempEvent(nEvt).channel) ~= 0
                                    chIdx = find(strcmp(electrodes, tempEvent(nEvt).channel));
                                    if ~isempty(chIdx)
                                        xy = [tempEvent(nEvt).latency, chIdx*scaleSpace - 50, tempEvent(nEvt).duration, (length(electrodes)*scaleSpace + 50) - (length(electrodes)*scaleSpace - 50)];
                                        if tempEvent(nEvt).duration > 1 % if the event has a duration > 1
                                            text(tempEvent(nEvt).latency + 50, chIdx*scaleSpace - 30, tempEvent(nEvt).type, 'Interpreter','none');
                                        else % rotate the text
                                            text(tempEvent(nEvt).latency - 100, chIdx*scaleSpace - 20, tempEvent(nEvt).type, 'Interpreter','none', 'Rotation',90);
                                        end
                                    else % not specific to a channel
                                        xy = [tempEvent(nEvt).latency, 0, tempEvent(nEvt).duration, maxY];
                                        if tempEvent(nEvt).duration > 1 % if the event has a duration > 1
                                            text(tempEvent(nEvt).latency + 50, 30, tempEvent(nEvt).type, 'Interpreter','none');
                                        else % rotate the text
                                            text(tempEvent(nEvt).latency - 100, chIdx*scaleSpace - 20, tempEvent(nEvt).type, 'Interpreter','none', 'Rotation',90);
                                        end
                                    end
                                else % not specific to a channel
                                    xy = [tempEvent(nEvt).latency, 0, tempEvent(nEvt).duration, maxY];
                                    if tempEvent(nEvt).duration > 1 % if the event has a duration > 1
                                        text(tempEvent(nEvt).latency + 50, 30, tempEvent(nEvt).type, 'Interpreter','none');
                                    else % rotate the text
                                        text(tempEvent(nEvt).latency - 100, 20, tempEvent(nEvt).type, 'Interpreter','none', 'Rotation',90);
                                    end
                                end
                            else % not specific to a channel
                                xy = [tempEvent(nEvt).latency, 0, tempEvent(nEvt).duration, maxY];
                                if tempEvent(nEvt).duration > 1 % if the event has a duration > 1
                                    text(tempEvent(nEvt).latency + 50, 30, tempEvent(nEvt).type, 'Interpreter','none');
                                else % rotate the text
                                    text(tempEvent(nEvt).latency - 100, 20, tempEvent(nEvt).type, 'Interpreter','none', 'Rotation',90);
                                end
                            end
                        end
                        if ~isempty(xy)
                            if isMATLABReleaseOlderThan('R2024a')
                                plot(app.traces, rectangle('Position', xy, ...
                                    'FaceColor', [1 1 0 0.25], ...
                                    'LineWidth', 1.5, ...
                                    'PickableParts', 'visible', ...
                                    'HitTest', 'on', ...
                                    'ButtonDownFcn', @selectEvent, ...
                                    'Parent', app.traces, ...
                                    'tag', tempEvent(nEvt).type));
                            else
                                plot(app.traces, rectangle('Position', xy, ...
                                    'FaceColor', [1 1 0], ...
                                    'FaceAlpha', 0.25, ... % introduced in R2024a, for no apparent reason at all
                                    'LineWidth', 1.5, ...
                                    'PickableParts', 'visible', ...
                                    'HitTest', 'on', ...
                                    'ButtonDownFcn', @selectEvent, ...
                                    'Parent', app.traces, ...
                                    'tag', tempEvent(nEvt).type));
                            end
                        end
                    end
                    clear maxY tempEvent nEvt chIdx xy 
                end
            end
            % button down function for event rectangle objects
            function selectEvent(rectangle,eventData)
                if ~isfield(app.handles, 'recPosition')
                    app.handles.curPosition = [];
                    app.handles.recPosition = [];
                    app.handles.recLabel = [];
                end
                LineWidth = eventData.Source.LineWidth;
                curPosition = eventData.IntersectionPoint;
                recPosition = eventData.Source.Position;
                label = eventData.Source.Tag;
                if LineWidth == 1.5 % if not already selected, change line and colour
                    if isMATLABReleaseOlderThan('R2024a')
                        set(rectangle, 'LineWidth',3);
                        set(rectangle,'FaceColor',[1 0 0 0.25]);
                    else
                        set(rectangle, 'LineWidth',3);
                        set(rectangle,'FaceColor',[1 0 0],'FaceAlpha',0.25);
                    end
                    app.handles.curPosition{end+1} = curPosition;
                    app.handles.recPosition{end+1} = recPosition;
                    app.handles.recLabel{end+1} = label;
                else % otherwise, it has already been selected, so change back line and colour
                    if isMATLABReleaseOlderThan('R2024a')
                        set(rectangle, 'LineWidth',1);
                        set(rectangle,'FaceColor',[1 1 0 0.25]);
                    else
                        set(rectangle, 'LineWidth',1);
                        set(rectangle,'FaceColor',[1 1 0],'FaceAlpha',0.25);
                    end
                    app.handles.curPosition = [];
                    app.handles.recPosition = [];
                    app.handles.recLabel = [];
                end
            end
        end

        %% update sleep stages: hypnogram
        function app = updateStage(app, stageCode)
            if isfield(app.handles, 'stageData')
                winSize = app.handles.stageData.win;
                srate = app.handles.stageData.srate;
                curWin = xlim(app.traces);
                ind = fix(curWin(2)/(winSize*srate));
                if ind < 1 % at start of file
                    ind = 1;
                elseif ind >= length(app.handles.stageData.stageTime) % at end of file
                    ind = length(app.handles.stageData.stageTime);
                end
                app.dataset_S.Text = app.handles.EEG.filename;
                rstart = app.handles.stageData.recStart + (fix(curWin(1)/srate)/86400);
                app.time_S.Text = sprintf('Date: %s Time: %s Epoch: %d', char(rstart, 'yyyy-MM-dd'), char(rstart, 'HH:mm:ss'), ind);
                onT = minutes(app.handles.stageData.lightsON - app.handles.stageData.recStart);
                offT = minutes(app.handles.stageData.lightsOFF - app.handles.stageData.recStart);
                if ~isempty(stageCode)
                    if ~isempty(app.handles.stageData.stages)
                        app.handles.stageData.stages(ind) = stageCode;
                        app.handles.stageData.onsets(ind) = curWin(1);
                    end
                else
                    if ~isempty(app.handles.stageData.stages)
                        stageCode = app.handles.stageData.stages(ind);
                    end
                end
                hold(app.hypnogram, 'off')
                if ~isempty(app.handles.stageData.stageTime)
                    curT = app.handles.stageData.stageTime(ind);
                else
                    curT = 0;
                end
                plot(app.hypnogram, [curT, curT], [0, 5], 'k', 'LineWidth', 2);
                hold(app.hypnogram, 'on');
                plot(app.hypnogram, [onT, onT], [0, 5], 'r', 'LineWidth', 2);
                plot(app.hypnogram, [offT, offT], [0, 5], 'g', 'LineWidth', 2);
                plotmap = [5 3 2 1 4 0];
                stageColors = [0.4660 0.6740 0.1880; 0.3010 0.7450 0.9330; 0 0.4470 0.7410; 0.9290 0.6940 0.1250; 0.6350 0.0780 0.1840; 0.4940 0.1840 0.5560];
                % stageColors = {'#77AC30'; '#4DBEEE'; '#0072BD'; '#EDB120'; '#A2142F'; '#7E2F8E'};
                stageNames = {'Wake'; 'NREM1'; 'NREM2'; 'SWS'; 'REM'}; % internal names of button functions
                connectData = ones(length(app.handles.stageData.stageTime),1)'*5;
                for i = 0:5
                    curInds = find(app.handles.stageData.stages == i);
                    plot(app.hypnogram, app.handles.stageData.stageTime(curInds), ones(length(curInds), 1)*plotmap(i + 1), '.', 'MarkerSize', 20, 'Color', stageColors(i+1, :), 'PickableParts', 'none');
                    connectData(curInds) = plotmap(i + 1);
                    if i < 5
                        b = app.([stageNames{i + 1} '_B']);
                        if i == stageCode
                            set(b, 'BackgroundColor', stageColors(i + 1, :));
                            plot(app.hypnogram, 0.1, plotmap(i + 1), '<', 'Color', stageColors(i + 1, :), 'MarkerSize', 7, 'LineWidth', 3, 'PickableParts', 'none');
                        else
                            set(b, 'BackgroundColor', [1 1 1]);
                        end
                    end
                end
                plot(app.hypnogram, app.handles.stageData.stageTime, connectData, 'k', 'PickableParts', 'none');
                if app.hypnogram.XLim(2) < 60 % less than 1 hour
                    set(app.hypnogram, 'XTick', 1:app.hypnogram.XLim(2), 'XTickLabel', 1:app.hypnogram.XLim(2));
                else % more than 1 hour
                    set(app.hypnogram, 'XTick', 1:60:app.hypnogram.XLim(2), 'XTickLabel', 0:1:app.hypnogram.XLim(2)/60);
                end
                if ~isempty(app.handles.stageData.stageTime)
                    set(app.hypnogram, 'Xlim', app.handles.stageData.stageTime([1, end]), 'Ylim', [0, 5], 'YTick', 0:5, 'YTickLabel', {'None'; 'SWS'; 'N2'; 'N1'; 'REM'; 'Wake'});
                else
                    set(app.hypnogram, 'Xlim', [0, 1], 'Ylim', [0, 5], 'YTick', 0:5, 'YTickLabel', {'None'; 'SWS'; 'N2'; 'N1'; 'REM'; 'Wake'});
                end
            end
        end

        %% Turn off interactivity with items when no dataset loaded
        function menuItemsOFF(app)
            % menu items
            if app.handles.ica == 0
                app.ImportSleepStagesMat_m.Enable = "off";
                app.ImportEventsMat_m.Enable = "off";
                app.clearDataset_m.Enable = 'off';
                app.saveDataset_m.Enable = "off";
                app.saveDatasetAs_m.Enable = "off";
                app.generateEventReport_m.Enable = "off";
                app.generateHypnogram_m.Enable = "off";
                app.clearDataset_m.Enable = "off";
            end
            app.hideChan_m.Enable = "off";
            app.showChan_m.Enable = "off";
            app.setScale_m.Enable = "off";
            app.rescaleSelectedChannels_m.Enable = "off";
            app.markChannelAsBadData_m.Enable = "off";
            app.interpolateBadChannel_m.Enable = "off";
            app.filterChannels_m.Enable = "off";
            app.downsampleChannels_m.Enable = "off";
            app.rereferenceChannels_m.Enable = "off";
            app.editChannels_m.Enable = "off";
            app.ASR_m.Enable = "off";
            app.ICA_m.Enable = "off";
            if app.handles.ica == 0
                app.runICA_m.Enable = "off";
                app.displayICA_m.Enable = "off";
                app.deleteICA_m.Enable = "off";
            end
            app.FFT_m.Enable = "off";
            app.detectMovement_m.Enable = "off";
            app.detectSpindles_m.Enable = "off";
            app.detectSlowWaves_m.Enable = "off";
            app.detectEyeMovements_m.Enable = "off";
            app.OnOffEventMark_m.Enable = "off";
            app.hideEvents_m.Enable = "off";
            app.showEvents_m.Enable = "off";
            app.clearEvents_m.Enable = "off";
            app.editLightsTags_m.Enable = "off";
            app.editRecordingStart_m.Enable = "off";
            % toolbar buttons
            if app.handles.ica == 0
                app.save_t.Enable = "off";
                app.scaleSmall_t.Enable = "off";
                app.scaleBigger_t.Enable = "off";
                app.resetScale_t.Enable = "off";
                app.playPause_t.Enable = "off";
                app.lightsOff_t.Enable = "off";
                app.lightsOn_t.Enable = "off";
                app.jumpTo_t.Enable = "off";
                app.ICA_t.Enable = "off";
            end
            app.markMode_t.Enable = "off";
            % buttons
            app.markStr.Enable = "off";
            app.delMark.Enable = "off";
            app.mergeMark.Enable = "off";
            app.numEpochs.Editable = "off";
            app.numEpochs.Enable = "off";
            app.scroll.Enable = "off";
            app.allChannelsMode.Enable = 'off';
            if app.handles.ica == 0
                app.arrowLeft_B.Enable = "off";
                app.arrowRight_B.Enable = "off";
            end
            app.Wake_B.Enable = "off";
            app.REM_B.Enable = "off";
            app.SWS_B.Enable = "off";
            app.NREM1_B.Enable = "off";
            app.NREM2_B.Enable = "off";
        end

        %% Turn on interactivity with items when dataset loaded
        function menuItemsON(app)
            % menu items
            app.ImportSleepStagesMat_m.Enable = "on";
            app.ImportEventsMat_m.Enable = "on";
            app.generateEventReport_m.Enable = "on";
            app.generateHypnogram_m.Enable = "on";
            app.clearDataset_m.Enable = 'on';
            app.clearDataset_m.Enable = "on";
            app.hideChan_m.Enable = "on";
            app.showChan_m.Enable = "on";
            app.rescaleSelectedChannels_m.Enable = "on";
            app.setScale_m.Enable = "on";
            app.markChannelAsBadData_m.Enable = "on";
            app.interpolateBadChannel_m.Enable = "on";
            app.filterChannels_m.Enable = "on";
            app.downsampleChannels_m.Enable = "on";
            app.rereferenceChannels_m.Enable = "on";
            app.editChannels_m.Enable = "on";
            app.ASR_m.Enable = "on";
            app.ICA_m.Enable = "on";
            app.runICA_m.Enable = "on";
            if app.handles.ica == 1
                app.displayICA_m.Enable = "off";
                app.deleteICA_m.Enable = "off";
            end
            app.FFT_m.Enable = "on";
            app.detectMovement_m.Enable = "on";
            app.detectSpindles_m.Enable = "on";
            app.detectSlowWaves_m.Enable = "on";
            app.detectEyeMovements_m.Enable = "on";
            app.OnOffEventMark_m.Enable = "on";
            app.hideEvents_m.Enable = "on";
            app.showEvents_m.Enable = "on";
            app.clearEvents_m.Enable = "on";
            app.editLightsTags_m.Enable = "on";
            app.editRecordingStart_m.Enable = "on";
            % toolbar buttons
            app.scaleSmall_t.Enable = "on";
            app.scaleBigger_t.Enable = "on";
            app.playPause_t.Enable = "on";
            app.resetScale_t.Enable = "on";
            app.lightsOff_t.Enable = "on";
            app.lightsOn_t.Enable = "on";
            app.jumpTo_t.Enable = "on";
            app.markMode_t.Enable = "on";
            if app.handles.ica == 1
                app.ICA_t.Enable = "on";
            end
            % buttons
            app.delMark.Enable = "on";
            app.mergeMark.Enable = "on";
            app.arrowLeft_B.Enable = "on";
            app.arrowRight_B.Enable = "on";
            app.Wake_B.Enable = "on";
            app.REM_B.Enable = "on";
            app.SWS_B.Enable = "on";
            app.NREM1_B.Enable = "on";
            app.NREM2_B.Enable = "on";
        end

        %% Enable the save button
        function saveON(app)
            app.saveDataset_m.Enable = "on";
            app.saveDatasetAs_m.Enable = "on";
            app.save_t.Enable = "on";
        end

        %% Disable the save button
        function saveOFF(app)
            app.saveDataset_m.Enable = "off";
            app.saveDatasetAs_m.Enable = "off";
            app.save_t.Enable = "off";
        end
        
        %% Return focus to figure
        function returnFocus2Figure(app)
            % gives focus to main figure window to keypresses can be processed after button push
            if ~isMATLABReleaseOlderThan('R2022a') % focus introduced in R2022a (v9.12)
                focus(app.figure1); 
            end
        end
        
        function quitCountingSheep(app)
            desktop = com.mathworks.mde.desk.MLDesktop.getInstance();
            mf = desktop.getMainFrame();
            mf.setMaximized(true);
            % handle output arguments, e.g., if EEG structure used as input argument
            if app.EEG == 1 % EEG was used as input argument
                assignin('base','EEG',app.handles.EEG); % output EEG to base workspace
            end
            app.delete;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function countingSheep_OpenFcn(app, EEG)
            % initialization
            app.figure1.WindowState = 'maximized';
            refresh
            progress = uiprogressdlg(app.figure1,'Title','Initializing Counting Sheep PSG','Indeterminate','on');
            disableDefaultInteractivity(app.traces);
            disableDefaultInteractivity(app.hypnogram);
            warning off backtrace
            warning off verbose
            zoom off
            sleepPath = which('countingSheepPSG');
            addpath(genpath(fileparts(sleepPath)));
            % hide matlab desktop
            desktop = com.mathworks.mde.desk.MLDesktop.getInstance();
            mf = desktop.getMainFrame();
            mf.setMinimized(true);
            % Get eeglab path
            matlabSetPathDirsString = path();
            if ispc % on Windows
                dirSep = ';';
            elseif ismac % on MAC
                dirSep = ':';
            elseif isunix
                dirSep = ':';
            end
            matlabSetPathDirs = split(matlabSetPathDirsString, dirSep);
            dirName = matlabSetPathDirs(contains(matlabSetPathDirs, 'eeglab202'));
            % eeglab is not on the Matlab path, try to add it if it is on the user environment path
            if isempty(dirName)
                userpath('reset')
                dirNames = dir(fullfile([userpath() filesep 'eeglab202*']));
                try
                    dirName = dirNames(startsWith({dirNames([dirNames.isdir]).name}, 'eeglab202')).folder;
                    folderName = dirNames(logical(startsWith({dirNames([dirNames.isdir]).name}, 'eeglab202') .* ~endsWith({dirNames([dirNames.isdir]).name}, '_old'))).name;
                    if isempty('dirName') || isempty('folderName')
                        choice = uiconfirm(app.figure1,'Please add EEGLAB version 2023 (or newer) main folder to Matlab path.','EEGLAB not found','Icon','warning');
                        waitfor(choice);
                        return
                    else
                        addpath(fullfile(dirName, folderName));
                    end
                catch
                    choice = uiconfirm(app.figure1,'Please add EEGLAB version 2023 (or newer) main folder to Matlab path.','EEGLAB not found','Icon','warning');
                    waitfor(choice);
                    return
                end
                clear dirNames folderName
            end
            clear matlabSetPathDirsString matlabSetPathDirs dirName dirSep
            % check for licence
            [OK] = licenceCheck(app);
            if OK == 0 % did not complete registration
                quitCountingSheep(app)
                return
            end
            % Get user-defined settings from montage file
            sleep_montage_default(app);
            % Set Montage List based on montage directory
            updateMontageList(app,sleepPath);
            clear sleepPath
            % load eelglab to add subdirectories to path
            if isempty(which('eeglab'))
                choice = uiconfirm(app.figure1,'Please add EEGLAB main folder to Matlab path.','EEGLAB not found','Icon','warning');
                waitfor(choice);
                return
            elseif ~isempty(which('eeglab')) && isempty(which('pop_loadset'))
                eeglab nogui;clc
            end
            % handle input arguments, e.g., if EEG structure used as input argument
            if nargin == 2
                % Check for ICA data
                if isfield(app.handles, 'EEG')
                    if isfield(app.handles.EEG, 'icaact')
                        if ~isempty(app.handles.EEG.icaact)
                            app.handles.ica = 1; % turn on option to plot ICA data
                        else
                            app.handles.ica = 0; % turn off option to plot ICA data
                        end
                    else
                        app.handles.ica = 0; % turn off option to plot ICA data
                    end
                else
                    app.handles.ica = 0; % turn off option to plot ICA data
                end
                % handle some basic info required from new dataset
                EEG.setname = EEG.filename(1:end-4);
                app.handles.EEG = EEG;
                app.EEG = 1; % EEG dataset was used as input argument
                timeStamp = strrep(strrep(char(datetime("now")),' ','-'),':','-');
                app.handles.stageFileIN = fullfile(app.handles.EEG.filepath,strcat(app.handles.EEG.setname, '_', timeStamp, '.mat'));
                % create new montage, based on channel info and defaults
                prompt = {['Enter name for new montage:',newline]};
                dlgtitle = 'Save new montage for new dataset';
                dims = [1 50];
                definput = {'new_dataset'};
                scriptname = inputdlg(prompt,dlgtitle,dims,definput);
                returnFocus2Figure(app);
                scriptname = ['sleep_montage_' scriptname{:}];
                app.handles.userChannelLabels = flip({app.handles.EEG.chanlocs(:).labels}'); % based on channel list
                colors(1:length({app.handles.EEG.chanlocs.labels})) = 'k'; % all black. boring!
                app.handles.userChannelColors = colors;
                createMontage(app,[scriptname '.m']);
                app.plotSleepIN.Value = scriptname;
                % load with new montage
                clearSet = 1; noPopUp = 1;
                loadNewDataset(app,clearSet,noPopUp);
                menuItemsON(app);
                clear colors prompt clearSet noPopUp scriptname dlgtitle dims definput
            else
                % pop-up for montage selection
                montages = app.plotSleepIN.Items;
                [idx,ok] = listdlg('PromptString','Select a montage to load:','ListString',montages,'ListSize',[250,250],'SelectionMode','single');
                % load montage
                if ok == 1
                    eval([montages{idx}, '(app);']); % calls study-specific montage
                    app.plotSleepIN.Value = montages{idx};
                    runPlot(app); % plot traces
                end
                app.handles.ica = 0; % turn off option to plot ICA data
                menuItemsOFF(app);
            end
            close(progress);
            returnFocus2Figure(app);
        end

        % Callback function: figure1, quit_m
        function countingSheep_CloseFcn(app, event)
            % Executes just before countingSheep closes
            if app.save_t.Enable == "on"
                dlgTitle    = 'Save changes?';
                dlgQuestion = ['Warning: You have unsaved changes to the dataset.' newline newline 'Do you want to exit without saving?'];
                choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                if strcmp(choice,'Yes')
                    quitCountingSheep(app);
                else
                    return
                end
            else
                quitCountingSheep(app);
            end
        end

        % Value changed function: plotSleepIN
        function plotSleepIN_Callback(app, event)
            % load montage from drop-down and plot the data
            validDate(app); % validate recStart time format
            curX = xlim(app.traces);
            range = curX(1):curX(2);
            plotSleepData(app, range);
            returnFocus2Figure(app);
        end

        % Callback function: open_t
        function open_t_Callback(app, event)
            loadDataset_m_Callback(app, event);
            returnFocus2Figure(app);
        end

        % Callback function: save_t
        function save_t_Callback(app, event)
            saveDataset_m_Callback(app, event);
            returnFocus2Figure(app);
        end

        % Callback function: scaleSmall_t
        function scaleSmall_t_Callback(app, event)
            % decrease channel scaing
            if isfield(app.handles, 'curScale')
                app.handles.curScale = app.handles.curScale + 10;
                curX = xlim(app.traces);
                range = curX(1):curX(2);
                plotSleepData(app, range);
            end
            returnFocus2Figure(app);
        end

        % Callback function: scaleBigger_t
        function scaleBigger_t_Callback(app, event)
            % increase channel scaing
            if isfield(app.handles, 'curScale')
                app.handles.curScale = app.handles.curScale - 10;
                curX = xlim(app.traces);
                range = curX(1):curX(2);
                plotSleepData(app, range);
            end
            returnFocus2Figure(app);
        end

        % Callback function: resetScale_t
        function resetScale_t_Callback(app, event)
            % reset to default channel scaing
            if isfield(app.handles, 'curScale')
                app.handles.curScale = 100;
                curX = xlim(app.traces);
                range = curX(1):curX(2);
                plotSleepData(app, range);
            end
            returnFocus2Figure(app);
        end

        % Callback function: playPause_t
        function playPause_t_Callback(app, event)
            while app.playPause_t.State == "on"
                pause(0.25)
                scroll_B_Callback(app, event)
                pause(0.25)
            end
            returnFocus2Figure(app);
        end

        % Callback function: lightsOff_t
        function lightsOff_t_Callback(app, event)
            % jump to lights off
            winSize = str2double(app.handles.winIN);
            srate = app.handles.EEG.srate;
            pt = minutes(app.handles.stageData.lightsOFF - app.handles.stageData.recStart);
            ptX = fix(pt(1)*(60/winSize))*winSize*srate;
            jumpto(app, ptX);
        end

        % Callback function: lightsOn_t
        function lightsOn_t_Callback(app, event)
            % jump to lights on
            winSize = str2double(app.handles.winIN);
            srate = app.handles.EEG.srate;
            pt = minutes(app.handles.stageData.lightsON - app.handles.stageData.recStart);
            ptX = (fix(pt(1)*(60/winSize))*winSize*srate);
            EOF = (fix(app.handles.EEG.pnts/app.handles.EEG.srate/60*(60/winSize))*winSize*srate);
            if ptX == EOF % its the end of the file, so, we subtract one epoch to jump to
                ptX = ptX - winSize*srate;
            end
            jumpto(app, ptX);
            returnFocus2Figure(app);
        end

        % Callback function: jumpTo_t
        function jumpTo_t_Callback(app, event)
            % jump to a user-specified epoch
            app.handles.scrollMode = []; % reset if in scroll mode
            out = inputdlg('Enter Epoch #:');
            returnFocus2Figure(app);
            if ~isempty(out)
                epoch = out{1};
            else
                epoch = '';
            end
            if ~isempty(epoch)
                epochNum = fix((str2double(epoch)));
                if epochNum < 1 % can't be lower than 1
                    epochNum  = 1;
                end
                if epochNum > length(app.handles.stageData.stages) % can't be higher than number of epochs 
                    epochNum = length(app.handles.stageData.stages);
                end
                winSize = str2double(app.handles.winIN);
                srate = app.handles.EEG.srate;
                jumpto(app, (epochNum - 1)*winSize*srate);
            end
            returnFocus2Figure(app);
        end

        % On callback: markMode_t
        function markMode_On_t_Callback(app, event)
            % turn on event marking mode
            app.markMode_t.State = "on";
            app.markStr.Enable = 'on';
            app.delMark.Enable = 'on';
            app.mergeMark.Enable = 'on';
            app.numEpochs.Editable = "on";
            app.numEpochs.Enable = "on";
            app.scroll.Enable = 'on';
            app.allChannelsMode.Enable = 'on';
            app.Wake_B.Enable = 'off';
            app.NREM1_B.Enable = 'off';
            app.NREM2_B.Enable = 'off';
            app.SWS_B.Enable = 'off';
            app.REM_B.Enable = 'off';
            returnFocus2Figure(app);
        end

        % Off callback: markMode_t
        function markMode_Off_t_Callback(app, event)
            % turn off event marking mode
            app.markMode_t.State = "off";
            app.markStr.Enable = 'off';
            app.delMark.Enable = 'on';
            app.mergeMark.Enable = 'on';
            app.numEpochs.Editable = "off";
            app.numEpochs.Enable = "off";
            app.scroll.Enable = 'off';
            app.allChannelsMode.Enable = 'off';
            app.Wake_B.Enable = 'on';
            app.NREM1_B.Enable = 'on';
            app.NREM2_B.Enable = 'on';
            app.SWS_B.Enable = 'on';
            app.REM_B.Enable = 'on';
            returnFocus2Figure(app);
        end

        % On callback: ICA_t
        function ICA_On_t_Callback(app, event)
            if isfield(app.handles.EEG, 'icaact')
                if ~isempty(app.handles.EEG.icaact)
                    % Display independent components
                    app.handles.ica = 1; % turn on option to plot ICA data
                    menuItemsOFF(app);
                    % create new montage, based on ICA channel info and show ICA data
                    scriptname = 'sleep_montage_ICA';
                    app.handles.userChannelLabels = cellstr(strcat('ICA_', num2str([1:size(app.handles.EEG.icaact,1)]')))'; % based on output from ICA
                    colors(1:length(app.handles.EEG.icaact,1)) = 'k'; % all black. boring!
                    app.handles.userChannelColors = colors;
                    createMontage(app,[scriptname '.m']);
                    app.plotSleepIN.Value = scriptname;
                    runPlot(app);
                    clear scriptname colors
                end
            end
        end

        % Off callback: ICA_t
        function ICA_Off_t_Callback(app, event)
            % Close ICA
            montages = app.plotSleepIN.Items; % pop-up for montage selection
            [idx,ok] = listdlg('PromptString','Select a montage to load:','ListString',montages,'ListSize',[250,250],'SelectionMode','single');
            % load montage
            if ok == 1
                app.handles.ica = 0; % turn off option to plot ICA data
                menuItemsON(app);
                eval([montages{idx}, '(app);']); % calls study-specific montage
                app.plotSleepIN.Value = montages{idx};
                runPlot(app); % plot traces
            else
                app.handles.ica = 1; % keep option to plot ICA data on
            end
            returnFocus2Figure(app);
        end

        % Button pushed function: delMark
        function delMark_B_Callback(app, event)
            % delete a user-selected event
            if isfield(app.handles, 'recPosition')
                cursorData = app.handles.recPosition;
                if ~isempty(cursorData)
                    for nCursor = 1:length(cursorData)
                        evtIdx1 = find(ismember([app.handles.EEG.event.latency], [cursorData{nCursor}(1)])); % find matching latency
                        if length(evtIdx1) > 1 % check duration to make sure you have the matching event
                            for nEvt = evtIdx1
                                evtIdx2 = find(ismember([app.handles.EEG.event.duration], [app.handles.EEG.event(evtIdx1).duration])); % find matching duration
                                evtIdx = intersect(evtIdx1,evtIdx2);
                            end
                        else % there is only one match for that latency, so that must be the one
                            evtIdx = evtIdx1;
                        end
                        if ~isempty(evtIdx)
                            app.handles.EEG.event(evtIdx) = []; % delete 'em
                        end
                    end
                    app.handles.stageData.events = app.handles.EEG.event;
                    stageData = app.handles.stageData;
                    save(app.handles.stageFileIN, 'stageData');
                    app.handles = rmfield(app.handles, 'recPosition'); % clear cache of any previous event selections
                    app.handles = rmfield(app.handles, 'curPosition'); % clear cache of any previous event selections
                    app.handles = rmfield(app.handles, 'recLabel'); % clear cache of any previous event selections
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                    saveON(app);
                end
            end
            returnFocus2Figure(app);
        end

        % Button pushed function: mergeMark
        function mergeMark_B_Callback(app, event)
            % merge two user-selected events
            if isfield(app.handles, 'recPosition')
                cursorData = app.handles.recPosition;
                if ~isempty(cursorData) && length(cursorData) == 2 % merge if only 2 events selected
                    if cursorData{1}(1) > cursorData{2}(1) % sort them
                        cdTemp(1) = cursorData(2);
                        cdTemp(2) = cursorData(1);
                        cursorData = cdTemp;
                        clear cdTemp
                    end
                    for nCursor = 1:2
                        evtIdx1 = find(ismember([app.handles.EEG.event.latency], [cursorData{nCursor}(1)])); % find matching latency
                        if length(evtIdx1) > 1 % check duration to make sure you have the matching event
                            for nEvt = evtIdx1
                                evtIdx2 = find(ismember([app.handles.EEG.event.duration], [app.handles.EEG.event(evtIdx1).duration])); % find matching duration
                                evtIdx(nCursor) = intersect(evtIdx1,evtIdx2);
                            end
                        else % there is only one match for that latency, so that must be the one
                            evtIdx(nCursor) = evtIdx1;
                        end
                    end
                    if ~isempty(evtIdx)
                        app.handles.EEG.event(evtIdx(1)).duration = app.handles.EEG.event(evtIdx(2)).latency + app.handles.EEG.event(evtIdx(2)).duration - app.handles.EEG.event(evtIdx(1)).latency; % calculate new event duration for first event
                        app.handles.EEG.event(evtIdx(2)) = []; % delete original 2nd event
                    end
                    app.handles.stageData.events = app.handles.EEG.event;
                    stageData = app.handles.stageData;
                    save(app.handles.stageFileIN, 'stageData');
                    app.handles = rmfield(app.handles, 'recPosition'); % clear cache of any previous event selections
                    app.handles = rmfield(app.handles, 'curPosition'); % clear cache of any previous event selections
                    app.handles = rmfield(app.handles, 'recLabel'); % clear cache of any previous event selections
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                    saveON(app);
                end
            end
            returnFocus2Figure(app);
        end

        % Value changed function: numEpochs
        function numEpochs_B_Callback(app, event)
            if app.numEpochs.Value > 1
                winSize = str2double(app.handles.winIN);
                srate = app.handles.EEG.srate;
                curX = xlim(app.traces);
                range = curX(1):curX(1)+(winSize*srate*app.numEpochs.Value)-1;
                plotSleepData(app, range);
            else
                winSize = str2double(app.handles.winIN);
                srate = app.handles.EEG.srate;
                curX = xlim(app.traces);
                range = curX(1):curX(1)+(winSize*srate)-1;
                plotSleepData(app, range);
            end
        end

        % Value changed function: allChannelsMode
        function allChannelsMode_B_Callback(app, event)
            returnFocus2Figure(app);
        end

        % Key press function: figure1
        function figure1KeyPress_Callback(app, event)
            if isfield(app.handles, 'EEG')
                % process keypresses
                c = str2double(event.Key);
                if (~isempty(c) && c >= 0 && c <= 4) % sleep stage
                    if (app.numEpochs.Value == 1 || strcmp(app.markStr.Enable, 'off')) && isempty(app.handles.scrollMode)
                        app = updateStage(app, c);
                        arrowRight_B_Callback(app, event);
                        saveON(app);
                    end
                elseif strcmp(event.Key, 'period') % unscored
                    if (app.numEpochs.Value == 1 || strcmp(app.markStr.Enable, 'off')) && isempty(app.handles.scrollMode)
                        app = updateStage(app, 5);
                        arrowRight_B_Callback(app, event);
                        saveON(app);
                    end
                elseif strcmp(event.Key, 'leftarrow')
                    arrowLeft_B_Callback(app, event);
                elseif strcmp(event.Key, 'rightarrow')
                    arrowRight_B_Callback(app, event);
                elseif strcmp(event.Key, 'backspace') % delete event
                    delMark_B_Callback(app, event);
                    saveON(app);
                end
            end
        end

        % Button pushed function: scroll
        function scroll_B_Callback(app, event)
            arrowRight_B_Callback(app, event);
            returnFocus2Figure(app);
        end

        % Button pushed function: arrowLeft_B
        function arrowLeft_B_Callback(app, event)
            % scroll left (back) from button push
            winSize = str2double(app.handles.winIN);
            srate = app.handles.EEG.srate;
            curX = xlim(app.traces);
            if ~isempty(app.handles.scrollMode) % previsouly in scroll mode
                newX = app.handles.scrollMode;
            else
                newX = [curX(1) - winSize*srate, curX(1) - 1];
            end
            if app.numEpochs.Value > 1 && isempty(app.handles.scrollMode) % if in multi-epoch mode & not scroll mode
                range = newX(1) - winSize*srate*(app.numEpochs.Value-1):newX(2);
            elseif app.numEpochs.Value > 1 && ~isempty(app.handles.scrollMode) % if in multi-epoch mode & scroll mode
                range = newX(1):newX(2);
                app.handles.scrollMode = [];
                app.numEpochs.Editable = "on";
                app.numEpochs.Enable = "on";
            else
                range = newX(1):newX(2);
                app.handles.scrollMode = [];
                app.numEpochs.Editable = "on";
                app.numEpochs.Enable = "on";
            end
            if ~any(range<0) % plot only if not moving before start of recording
                plotSleepData(app, range);
            end
            updateStage(app, []);
            returnFocus2Figure(app);
        end

        % Button pushed function: arrowRight_B
        function arrowRight_B_Callback(app, event)
            % scroll right (forward) from button push
            winSize = str2double(app.handles.winIN);
            srate = app.handles.EEG.srate;
            curX = xlim(app.traces);
            % determine if advancing 1 epoch or scrolling by 1 second
            if isprop(event, 'Key')
                if strcmp(event.Key, 'rightarrow') || strcmp(event.Key, 'period') || str2double(event.Key) >= 0 && str2double(event.Key) <= 4 % advance one epoch
                    if ~isempty(app.handles.scrollMode) % previsouly in scroll mode
                        curX = app.handles.scrollMode;
                        newX = [curX(2) + 1, curX(2) + winSize*srate];
                        app.handles.scrollMode = [];
                    else
                        newX = [curX(2) + 1, curX(2) + winSize*srate];
                    end
                end
            elseif isprop(event, 'EventName')
                if strcmp(event.EventName, 'ButtonPushed') && strcmp(event.Source.Text, '>') || strcmp(event.EventName, 'ToolClicked') % advance 1 second
                    if ~isempty(app.handles.scrollMode) % already in scroll mode
                        if curX(2) < app.handles.scrollMode(2) + (winSize-1)*srate % don't exceed 1 epoch advance in 1 sec scroll mode
                            newX = [curX(1) + 1*srate, curX(2) + 1*srate];
                        else % advance 1 epoch
                            newX = [app.handles.scrollMode(2) + 1, app.handles.scrollMode(2) + winSize*srate];
                            app.handles.scrollMode = [];
                        end
                    elseif isempty(app.handles.scrollMode) % start scroll mode
                        newX = [curX(1) + 1*srate, curX(2) + 1*srate]; % advance 1 second
                        app.handles.scrollMode = curX; % keep record of curX before scrolling by 1 sec
                    end
                elseif strcmp(event.EventName, 'ButtonPushed') && strcmp(event.Source.Text, '>>') || strcmp(event.Source.Text, 'Wake') || strcmp(event.Source.Text, 'N1') || strcmp(event.Source.Text, 'N2') || strcmp(event.Source.Text, 'SWS') || strcmp(event.Source.Text, 'REM') % advance 1 epoch
                    if ~isempty(app.handles.scrollMode) % previsouly in scroll mode
                        curX = app.handles.scrollMode;
                        newX = [curX(2) + 1, curX(2) + winSize*srate];
                        app.handles.scrollMode = [];
                    else
                        newX = [curX(2) + 1, curX(2) + winSize*srate];
                    end
                end
            end
            % now advance by either 1 epoch, or scroll 1 second
            if app.numEpochs.Value > 1 && isempty(app.handles.scrollMode) % if in multi-epoch mode & scroll mode
                range = newX(1):newX(2)+winSize*srate*(app.numEpochs.Value-1);
            else
                range = newX(1):newX(2);
            end
            if ~any(range>app.handles.EEG.pnts) % plot only if not moving beyond end of recording
                plotSleepData(app, range);
                if isempty(app.handles.scrollMode)
                    app.numEpochs.Editable = "on";
                    app.numEpochs.Enable = "on";
                else
                    app.numEpochs.Editable = "off";
                    app.numEpochs.Enable = "off";
                end
            end
            updateStage(app, []);
            returnFocus2Figure(app);
        end

        % Button pushed function: Wake_B
        function Wake_B_Callback(app, event)
            % score Wake
            if isempty(app.handles.scrollMode)
                app = updateStage(app, 0);
                arrowRight_B_Callback(app, event);
                saveON(app);
                returnFocus2Figure(app);
            end
        end

        % Button pushed function: NREM1_B
        function NREM1_B_Callback(app, event)
            % score NREM1
            if isempty(app.handles.scrollMode)
                app = updateStage(app, 1);
                arrowRight_B_Callback(app, event);
                saveON(app);
                returnFocus2Figure(app);
            end
        end

        % Button pushed function: NREM2_B
        function NREM2_B_Callback(app, event)
            % score NREM2
            if isempty(app.handles.scrollMode)
                app = updateStage(app, 2);
                arrowRight_B_Callback(app, event);
                saveON(app);
                returnFocus2Figure(app);
            end
        end

        % Button pushed function: SWS_B
        function SWS_B_Callback(app, event)
            % score SWS
            if isempty(app.handles.scrollMode)
                app = updateStage(app, 3);
                arrowRight_B_Callback(app, event);
                saveON(app);
                returnFocus2Figure(app);
            end
        end

        % Button pushed function: REM_B
        function REM_B_Callback(app, event)
            % score REM
            if isempty(app.handles.scrollMode)
                app = updateStage(app, 4);
                arrowRight_B_Callback(app, event);
                saveON(app);
                returnFocus2Figure(app);
            end
        end

        % Menu selected function: newMontage_m
        function newMontage_m_Callback(app, event)
            % Create a new montage from user input
            if ischar(event)
                app.handles.userRecStartFormat = event;
            end
            if strcmp(app.handles.userRecStartFormat, 'yyyy-mm-dd HH:MM:SS.FFF') % old format?
                app.handles.userRecStartFormat = 'yyyy-MM-dd HH:mm:ss.SSS'; % update with datetime compatible time format
            end
            % Get settings from input dialog
            defStages = [app.handles.userStageNames{1} ' ' app.handles.userStageNames{2} ' ' app.handles.userStageNames{3} ' ' app.handles.userStageNames{4} ' ' app.handles.userStageNames{5} ' ' app.handles.userStageNames{6}];
            defChannels = '';
            for nCh = length(app.handles.userChannelLabels):-1:1
                defChannels = [defChannels app.handles.userChannelLabels{nCh} ' '];
            end
            defChannels = strtrim(defChannels); % remove trailing zero
            definput = {defStages, ...
                app.handles.winIN, ...
                app.handles.userlightsOFFtag, ...
                app.handles.userlightsONtag, ...
                app.handles.userRecStartFormat, ...
                defChannels, ...
                flip(app.handles.userChannelColors) ...
                };
            prompt = {'Enter Sleep Stage labels (Default: W N1 N2 SWS REM Unscored):', ...
                'Enter epoch duration in seconds (Default: 30):', ...
                'Enter Lights Off tag (Default: Lights Off):', ...
                'Enter lights On tag (Default: Lights On):', ...
                'Enter Recordings Start tag date and 24-hr time format (Default: yyyy-MM-dd HH:mm:ss.SSS):', ...
                'Enter electrode labels, from TOP to BOTTOM (Default: Fz Cz Pz Oz LEOG REOG EMG ECG):', ...
                'Enter corresponding colours for channels, k=black, r=red, b=blue (Default: kkkkbbkr):'};
            dlgtitle = 'Create New / Edit Current Study-Specific Display Montage';
            dims = [1 200];
            out = inputdlg(prompt,dlgtitle,dims,definput);
            returnFocus2Figure(app);
            updated = logical([0 0 0 0 0 0 0]);
            if ~isempty(out)
                if length(strsplit(out{1},' ')') == 6 % make sure the input has the right number of elements
                    if ~isempty(out{1})
                        if ~strcmp(defStages, out{1}) % check if the input was updated / different from default current
                            app.handles.userStageNames = strsplit(out{1},' ')';
                            updated(1) = 1;
                        end
                    end
                    if ~isempty(out{2})
                        if ~strcmp(app.handles.winIN, out{2})
                            app.handles.winIN = out{2};
                            updated(2) = 1;
                        end
                    end
                    if ~isempty(out{3})
                        if ~strcmp(app.handles.userlightsOFFtag, out{3})
                            app.handles.userlightsOFFtag = out{3};
                            updated(3) = 1;
                        end
                    end
                    if ~isempty(out{4})
                        if ~strcmp(app.handles.userlightsONtag, out{4})
                            app.handles.userlightsONtag = out{4};
                            updated(4) = 1;
                        end
                    end
                    if ~isempty(out{5})
                        if ~strcmp(app.handles.userlightsONtag, out{5})
                            app.handles.userRecStartFormat = out{5};
                            updated(5) = 1;
                        end
                    end
                    if ~isempty(out{6})
                        if ~strcmp(defChannels, out{6})
                            app.handles.userChannelLabels = flip(strsplit(out{6},' ')');
                            updated(6) = 1;
                        end
                    end
                    if ~isempty(out{7})
                        if ~strcmp(app.handles.userChannelColors, flip(out{7}))
                            app.handles.userChannelColors = flip(out{7});
                            updated(7) = 1;
                        end
                    end
                else
                    returnFocus2Figure(app);
                    choice = uiconfirm(app.figure1,['Incorrect number of sleep stage labels.' newline newline 'There must be 6 stage labels:' newline 'e.g., W N1 N2 SWS REM Unscored.'],'Missing Entry','Icon','warning');
                    waitfor(choice);
                    return
                end
            else
                return
            end
            clear prompt dlgtitle dims defStages defChannels definput nCh
            % save as new montage
            if any(updated)
                dlgTitle    = 'Montage Updated';
                dlgQuestion = ['Montage updated.' newline newline 'Would you like to save it as a new montage?'];
                choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                if strcmp(choice,'Yes')
                    prompt = {['Enter suffix for study name:',newline]};
                    dlgtitle = 'Save new montage';
                    dims = [1 50];
                    definput = {'NewSleepStudy'};
                    FileSuffix = inputdlg(prompt,dlgtitle,dims,definput);
                    returnFocus2Figure(app);
                    scriptname = ['sleep_montage_' FileSuffix{:} '.m'];
                    createMontage(app,scriptname);
                    app.plotSleepIN.Value = scriptname(1:end-2);
                    plotFCN = app.plotSleepIN.Items;
                    boxInd = ismember(plotFCN, app.plotSleepIN.Value);
                    eval([plotFCN{boxInd}, '(app);']); % calls study-specific montage
                else
                    return
                end
                clear dlgTitle dlgQuestion choice prompt dlgtitle dims definput FileSuffix scriptname out
            end
            clear updated
            returnFocus2Figure(app);
        end

        % Menu selected function: deleteMontage_m
        function deleteMontage_m_Callback(app, event)
            % delete an existing montage file
            sleepPath = which('countingSheepPSG');
            montageNames = dir(fullfile([fileparts(sleepPath) filesep 'montages']));
            montages = [];
            if any(contains({montageNames(:).name},'_montage','IgnoreCase',1))
                montageIdx = find(contains({montageNames(:).name},'_montage','IgnoreCase',1));
                for idx=1:length(montageIdx)
                    montages{idx} = montageNames(montageIdx(idx)).name;
                end
                if any(contains(montages,'sleep_montage_default','IgnoreCase',1)) % don't list default montage
                    montageIdx = contains(montages,'sleep_montage_default','IgnoreCase',1);
                    montages(montageIdx) = [];
                end
            else
                choice = uiconfirm(app.figure1,['No montage files found.' newline newline 'Requires at least one montage m-file using the word ''montage'' in the filename. If default montage has been modified or deleted, please reinstall Counting Sheep PSG.'],'No Montage Found','Icon','warning');
                waitfor(choice);
                return
            end
            clear idx montageIdx
            % create list box
            [idx,tf] = listdlg('PromptString','Select a montage to delete:','ListString',montages,'ListSize',[250,250],'SelectionMode','single');
            if tf == 1
                montageIdx = find(matches({montageNames(:).name},montages(idx)));
                recycle('on');
                delete([montageNames(montageIdx).folder filesep montageNames(montageIdx).name]);
            end
            updateMontageList(app,sleepPath);
            clear idx sleepPath montages montageNames montageIdx
            returnFocus2Figure(app);
        end

        % Menu selected function: importEDFeeglab_m, importEDFmatlab_m
        function importEDF_m_Callback(app, event)
            clearDataset_m_Callback(app, event);
            % import EDF dataset
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'edf');
            else
                app.fileSelectData = fileSelect(app, '', '', 'edf');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            end
            % file name for countingSheep scoring data (*.mat) file
            timeStamp = strrep(strrep(char(datetime("now")),' ','-'),':','-');
            app.handles.stageFileIN = fullfile([app.filepath filesep],[app.filename(1:end-4),'_' timeStamp '.mat']);
            progress = uiprogressdlg(app.figure1,'Title','Importing EDF','Message','Reading EDF file. This might take a while...','Indeterminate','on'); pause(.5);
            if strcmp(event.Source.Tag, 'importEDFmatlab_m') % USE MATLAB IMPORTER - VERY SLOW, BUT EFFECTIVE
                % create a new empty dataset structure
                app.handles.EEG = eeg_emptyset;
                % import EDF file
                [hdr, record] = cspsg_edfread2013([app.filepath filesep app.filename]);
                % take only channels at the same sampling rate as EEG
                srate = max(hdr.samples); % use max srate, normally EEG
                if ~all(hdr.samples == srate)
                    choice = uiconfirm(app.figure1,'Selecting only channels with same sampling rate as EEG.','Differing sampling rates','Icon','warning');
                    waitfor(choice);
                end
                chidx = hdr.samples == srate;
                data = record(chidx,:);
                % setup basic dataset header info
                app.handles.EEG.setname = app.filename(1:end-4);
                app.handles.EEG.filename = strrep(app.filename,'edf','set');
                app.handles.EEG.filepath = [app.filepath filesep];
                app.handles.EEG.data = data;
                app.handles.EEG.srate = srate; % take the srate for the EEG channels
                app.handles.EEG.times = (1:length(app.handles.EEG.data))/app.handles.EEG.srate;
                app.handles.EEG.trials = 1;
                app.handles.EEG.nbchan = size(app.handles.EEG.data,1);
                app.handles.EEG.pnts = size(app.handles.EEG.data,2);
                app.handles.EEG.xmin = app.handles.EEG.times(1);
                app.handles.EEG.xmax = app.handles.EEG.times(end);
                try
                    recStart = char(datetime([replace(hdr.startdate,'.','-') ' ' replace(hdr.starttime,'.',':') '.000'], 'Inputformat', 'dd-MM-yy HH:mm:ss.SSS'),'yyyy-MM-dd HH:mm:ss.SSS'); % recording start date and time
                    if isempty(app.handles.EEG.comments)
                        app.handles.EEG.comments = recStart;
                    end
                catch
                    warning('No recording start found in EDF')
                end
                app.handles.EEG.chanlocs = struct('labels', hdr.label(chidx));
            elseif strcmp(event.Source.Tag, 'importEDFeeglab_m') % USE EEGLAB IMPORTER; DEFAULT & FAST
                bioSigPath = which('pop_biosig');
                if ~isempty(bioSigPath)
                    app.handles.EEG.setname = app.filename(1:end-4);
                    app.handles.EEG = pop_biosig([app.filepath filesep app.filename], 'importevent','off','importannot','off'); % import edf using EEGLAB, no popup
                    app.handles.EEG.filename = strrep(app.filename,'edf','set');
                    app.handles.EEG.filepath = [app.filepath filesep];
                    if ~isempty(app.handles.EEG.etc.T0) % if possible, default for EEGLAB import to put this here
                        try
                            recStart = char(datetime(app.handles.EEG.etc.T0), 'yyyy-MM-dd HH:mm:ss.SSS');
                            if isempty(app.handles.EEG.comments)
                                app.handles.EEG.comments = recStart;
                            end
                        catch
                            warning('No recording start found in EDF')
                        end
                    else
                        if ~isempty(app.handles.EEG.comments)
                            if ischar(app.handles.EEG.comments)
                                try
                                    recStart = app.handles.EEG.comments;
                                catch
                                    warning('No recording start found in EDF')
                                end
                            end
                        end
                    end
                else
                    choice = uiconfirm(app.figure1,'BioSig toolbox not installed in EEGLAB','Missing Toolbox','Icon','error');
                    waitfor(choice);
                    return
                end
            end
            % insert recording start marker
            progress.Value = 1/3; progress.Message = 'Creating markers...'; pause(1);
            if ischar(recStart)
                app.handles.EEG.event(1).type = recStart;
                app.handles.EEG.event(1).latency = 1;
                app.handles.EEG.event(1).duration = 1;
                app.handles.EEG.event(1).channel = '';
                app.handles.EEG.event(1).urevent = 1;
            end
            % insert lights on marker at start of recording
            app.handles.EEG.event(2).type = 'Lights Off';
            app.handles.EEG.event(2).latency = 1;
            app.handles.EEG.event(2).duration = 1;
            app.handles.EEG.event(2).channel = '';
            app.handles.EEG.event(2).urevent = 2;
            % insert lights off marker at end of recording
            app.handles.EEG.event(3).type = 'Lights On';
            app.handles.EEG.event(3).latency = app.handles.EEG.pnts;
            app.handles.EEG.event(3).duration = 1;
            app.handles.EEG.event(3).channel = '';
            app.handles.EEG.event(3).urevent = 3;
            % check everything
            app.handles.EEG = eeg_checkchanlocs(app.handles.EEG);
            app.handles.EEG = eeg_checkset(app.handles.EEG);
            % create new montage, based on channel info and defaults
            progress.Value = 2/3; progress.Message = 'Creating new montage...'; pause(1);
            app.handles.userStageNames = app.handles.userStageNames; % default
            app.handles.winIN = '30'; % default
            app.handles.userlightsOFFtag = 'Lights Off'; % default
            app.handles.userlightsONtag = 'Lights On'; % default
            app.handles.userRecStartFormat = 'yyyy-MM-dd HH:mm:ss.SSS'; % default
            app.handles.userChannelLabels = {app.handles.EEG.chanlocs(:).labels}'; % based on EDF
            colors(1:length({app.handles.EEG.chanlocs.labels})) = 'k'; % all black. boring!
            app.handles.userChannelColors = colors;
            createMontage(app,'sleep_montage_EDF_import.m');
            app.plotSleepIN.Value = 'sleep_montage_EDF_import';
            progress.Value = 3/3; progress.Message = 'EDF import complete'; pause(1);
            close(progress);
            % load the new dataset
            saveDatasetAs_m_Callback(app);
            clearSet = 0; noPopUp = 1;
            loadNewDataset(app,clearSet,noPopUp);
            clear colors clearSet noPopUp srate chidx data prompt dlgtitle dims definput
            menuItemsON(app);
            saveOFF(app);
            curX = xlim(app.traces);
            range = curX(1):curX(2);
            plotSleepData(app, range);
        end

        % Menu selected function: importBrainProducts_m
        function importBrainProducts_m_Callback(app, event)
            clearDataset_m_Callback(app, event);
            % import Brain Products dataset
            bpFields = {'bvtime','bvmknum','visible','code'}; % Specify Brain Products event fields to remove. Default: Yes = {'bvtime','bvmknum','visible','code'}, No = [].
            % select Brain Products dataset
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'vhdr');
            else
                app.fileSelectData = fileSelect(app, '', '', 'vhdr');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            end
            % file name for countingSheep scoring data (*.mat) file
            timeStamp = strrep(strrep(char(datetime("now")),' ','-'),':','-');
            app.handles.stageFileIN = fullfile([app.filepath filesep],[app.filename(1:end-5),'_' timeStamp '.mat']);
            % load Brain Products dataset
            progress = uiprogressdlg(app.figure1,'Title','Importing Brain Products','Message','Reading Brain Products file. This might take a while...'); pause(.5);
            EEGtemp = pop_loadbv([app.filepath filesep], app.filename);
            progress.Value = 1/4; progress.Message = 'Creating header...'; pause(1);
            EEGtemp.setname = app.filename(1:end-5);
            EEGtemp.filename = [EEGtemp.setname '.set'];
            EEGtemp.filepath = [app.filepath filesep];
            EEGtemp.data = double(EEGtemp.data); % fixes some issues with import process
            EEGtemp.times = (1:length(EEGtemp.data))/EEGtemp.srate;
            EEGtemp.xmin = EEGtemp.times(1);
            EEGtemp.xmax = EEGtemp.times(end);
            progress.Value = 2/4; progress.Message = 'Importing markers...'; pause(1);
            if ~isempty(EEGtemp.event)
                EEGtemp.event = rmfield(EEGtemp.event,bpFields); % remove Brain Products specific fields
                evtIdx = find(strcmp({EEGtemp.event.type}, 'empty')); % remove unecessary BP events called 'empty'
                EEGtemp.event(evtIdx) = [];
                evtIdx = find(strcmp({EEGtemp.event.type}, 'boundary')); % remove unecessary BP events called 'boundary'
                EEGtemp.event(evtIdx) = [];
            elseif isempty(EEGtemp.event)
                % Well... that's awkward. Nothing really to do here. So, how's your day going so far?
            end
            % set the original event info
            EEGtemp.urevent = EEGtemp.event;
            % check updated dataset
            EEGtemp = eeg_checkset(EEGtemp);
            EEGtemp = eeg_checkset(EEGtemp, 'eventconsistency');
            EEGtemp = eeg_checkset(EEGtemp, 'makeur');
            EEGtemp = eeg_checkset(EEGtemp, 'checkur');
            app.handles.EEG = EEGtemp;
            % create new montage, based on channel info and defaults
            prompt = {['Enter name for new montage:',newline]};
            dlgtitle = 'Save new montage for Brain Products import';
            dims = [1 50];
            definput = {'BP_import'};
            scriptname = inputdlg(prompt,dlgtitle,dims,definput);
            returnFocus2Figure(app);
            if isempty(scriptname{:})
                choice = uiconfirm(app.figure1,'Name for new montage required','Missing Input');
                waitfor(choice);
                scriptname = inputdlg(prompt,dlgtitle,dims,definput,opts);
                returnFocus2Figure(app);
            end
            progress.Value = 3/4; progress.Message = 'Creating new montage...'; pause(1);
            scriptname = ['sleep_montage_' scriptname{:}];
            app.handles.userStageNames = app.handles.userStageNames; % default
            app.handles.winIN = '30'; % default
            app.handles.userlightsOFFtag = 'Lights Off'; % default
            app.handles.userlightsONtag = 'Lights On'; % default
            app.handles.userRecStartFormat = 'yyyy-MM-dd HH:mm:ss.SSS'; % default
            app.handles.userChannelLabels = {app.handles.EEG.chanlocs(:).labels}'; % based on import
            colors(1:length({app.handles.EEG.chanlocs.labels})) = 'k'; % all black. boring!
            app.handles.userChannelColors = colors;
            createMontage(app,[scriptname '.m']);
            app.plotSleepIN.Value = scriptname;
            progress.Value = 4/4; progress.Message = 'Brain Products import complete'; pause(1);
            close(progress);
            % load new dataset
            saveDatasetAs_m_Callback(app);
            clearSet = 0; noPopUp = 1;
            loadNewDataset(app,clearSet,noPopUp);
            clear EEGtemp colors clearSet noPopUp evtIdx scriptname prompt dlgtitle dims definput
            menuItemsON(app);
            saveOFF(app);
            curX = xlim(app.traces);
            range = curX(1):curX(2);
            plotSleepData(app, range);
        end

        % Menu selected function: ImportSleepStagesMat_m
        function importSleepStagesMat_m_Callback(app, event)
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat');
            else
                app.fileSelectData = fileSelect(app, '', '', 'mat');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            end
            load([app.filepath filesep app.filename], 'stageData');
            if exist('stageData','var')
                f1 = fieldnames(stageData);
                f2 = fieldnames(app.handles.stageData);
                if isequal(f1,f2) % imported stageData matches requirementes
                    if stageData.win ~= app.handles.stageData.win
                        choice = uiconfirm(app.figure1,'Incorrect epoch length','Import Error','Icon','error');
                        waitfor(choice);
                        return
                    elseif stageData.srate ~= app.handles.stageData.srate
                        choice = uiconfirm(app.figure1,'Incorrect sampling rate','Import Error','Icon','error');
                        waitfor(choice);
                        return
                    elseif length(stageData.stages) ~= length(app.handles.stageData.stages)
                        choice = uiconfirm(app.figure1,'Incorrect number of stages','Import Error','Icon','error');
                        waitfor(choice);
                        return
                    elseif length(stageData.onsets) ~= length(app.handles.stageData.onsets)
                        choice = uiconfirm(app.figure1,'Incorrect number of onsets','Import Error','Icon','error');
                        waitfor(choice);
                        return
                    elseif length(stageData.stageTime) ~= length(app.handles.stageData.stageTime)
                        choice = uiconfirm(app.figure1,'Incorrect number of stage times','Import Error','Icon','error');
                        waitfor(choice);
                        return
                    else % everything checks out
                        app.handles.stageData.stages = stageData.stages;
                        runPlot(app);
                        saveON(app);
                        returnFocus2Figure(app);
                    end
                else
                    choice = uiconfirm(app.figure1,'Not a valid Counting Sheep PSG format mat file','Import Error','Icon','error');
                    waitfor(choice);
                    return
                end
            else
                choice = uiconfirm(app.figure1,'Not a valid Counting Sheep PSG format mat file','Import Error','Icon','error');
                waitfor(choice);
                return
            end
        end

        % Menu selected function: ImportEventsMat_m
        function importEventsMat_m_Callback(app, event)
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat');
            else
                app.fileSelectData = fileSelect(app, '', '', 'mat');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            end
            load([app.filepath filesep app.filename], 'stageData');
            if exist('stageData','var')
                f1 = fieldnames(stageData);
                f2 = fieldnames(app.handles.stageData);
                if isequal(f1,f2) % imported stageData matches requirementes
                    if stageData.srate ~= app.handles.stageData.srate
                        choice = uiconfirm(app.figure1,'Incorrect sampling rate','Import Error','Icon','error');
                        waitfor(choice);
                        return                        
                    else % everything checks out
                        if isempty(app.handles.stageData.events) % this is pretty much impossible, but we should check anyways
                            app.handles.EEG.event = stageData.events;
                            app.handles.stageData.events = stageData.events;
                        else % in this case, EEG.event are not empty
                            % ask if you want to merge or overwrite events
                            dlgTitle    = 'Import Events';
                            dlgQuestion = ['Do you want to merge imported events with existing events, or overwrite existing events?' newline newline 'Only events that are not sleep stages will be imported.'];
                            choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Merge','Overwrite','Cancel'});
                            if strcmp(choice,'Overwrite')
                                overwrite = 1; clearSet = 0;
                                getEvents(app, stageData, clearSet, overwrite);
                            elseif strcmp(choice,'Merge')
                                overwrite = 0;  clearSet = 0;
                                getEvents(app, stageData, clearSet, overwrite);
                            else
                                return
                            end
                            curX = xlim(app.traces);
                            range = curX(1):curX(2);
                            plotSleepData(app, range);
                            stageData = app.handles.stageData;
                            save(app.handles.stageFileIN, 'stageData');
                            saveON(app);
                            returnFocus2Figure(app);
                        end
                    end
                else
                    choice = uiconfirm(app.figure1,'Not a valid Counting Sheep PSG format mat file','Import Error','Icon','error');
                    waitfor(choice);
                    return
                end
            else
                choice = uiconfirm(app.figure1,'Not a valid Counting Sheep PSG format mat file','Import Error','Icon','error');
                waitfor(choice);
                return
            end
        end

        % Menu selected function: loadDataset_m
        function loadDataset_m_Callback(app, event)
            % validate recStart time format
            validDate(app);
            % load an existing EEGlab dataset
            markMode_Off_t_Callback(app);
            menuItemsON(app);
            clearSet = 0;
            noPopUp = 0;
            loadNewDataset(app,clearSet,noPopUp);
            % handle ICA menu and toolbar items
            if isfield(app.handles, 'EEG')
                if isfield(app.handles.EEG, 'icaact')
                    if ~isempty(app.handles.EEG.icaact)
                        app.ICA_m.Enable = "on"; % enable main menu
                        app.ICA_t.Enable = "on"; % enable display ICA toolbar button
                        app.displayICA_m.Enable = "on"; % enable menu item to display ICA
                        app.deleteICA_m.Enable = "on"; % enable menu item to remove component
                    else
                        app.ICA_t.Enable = "off"; % disable display ICA toolbar button
                        app.displayICA_m.Enable = "off"; % disable menu item to display ICA
                        app.deleteICA_m.Enable = "off"; % disable menu item to remove component
                    end
                else
                    app.ICA_t.Enable = "off"; % disable display ICA toolbar button
                    app.displayICA_m.Enable = "off"; % disable menu item to display ICA
                    app.deleteICA_m.Enable = "off"; % disable menu item to remove component
                end
            else
                app.ICA_t.Enable = "off"; % disable display ICA toolbar button
                app.displayICA_m.Enable = "off"; % disable menu item to display ICA
                app.deleteICA_m.Enable = "off"; % disable menu item to remove component
            end
        end

        % Menu selected function: saveDataset_m
        function saveDataset_m_Callback(app, event)
            % save current dataset without popup
            saveAs = 0;
            saveDataset(app,saveAs);
            saveOFF(app);
        end

        % Menu selected function: saveDatasetAs_m
        function saveDatasetAs_m_Callback(app, event)
            % save current dataset as new file
            saveAs = 1;
            saveDataset(app,saveAs);
            saveOFF(app);
        end

        % Menu selected function: generateSleepReport_m
        function generateSleepReport_m_Callback(app, event)
            % generate a sleep architecture report in Excel (useful for stats)
            if isfield(app.handles, 'EEG')
                if ~isempty(app.handles.EEG.data)
                    completed = cspsg_generateSleepReport(app);
                else
                    if isfield(app.handles,'EEG')
                        app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat', 'multiselect','on');
                    else
                        app.fileSelectData = fileSelect(app, '', '', 'mat', 'multiselect','on');
                    end
                    waitfor(app.fileSelectData);
                    if isempty(app.filename)
                        returnFocus2Figure(app);
                        return
                    end
                    completed = cspsg_generateSleepReport(app,app.filename,app.filepath);
                end
            else
                if isfield(app.handles,'EEG')
                    app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat', 'multiselect','on');
                else
                    app.fileSelectData = fileSelect(app, '', '', 'mat', 'multiselect','on');
                end
                waitfor(app.fileSelectData);
                if isempty(app.filename)
                    returnFocus2Figure(app);
                    return
                end
                completed = cspsg_generateSleepReport(app,app.filename,app.filepath);
            end
            if completed == 1
                choice = uiconfirm(app.figure1,'Sleep report generated.','Sleep Report','Icon','success');
                waitfor(choice);
            end
        end

        % Menu selected function: generateEventReport_m
        function generateEventReport_m_Callback(app, event)
            % generate a list of events and summary info in Excel (useful for stats)
            events = struct2table(app.handles.EEG.event);
            events.urevent = [];
            evtType = unique({app.handles.EEG.event.type});
            for nEvtType = 1:length(evtType)
                evtIdx = find(strcmp(events.type,evtType{nEvtType}));
                count(nEvtType) = length(evtIdx);
                duration(nEvtType) = mean(events.duration(evtIdx));
                clear evtIdx
            end
            summary.type = evtType';
            summary.count = count';
            summary.duration = duration';
            summary = struct2table(summary);
            writetable(summary, [app.handles.EEG.filepath app.handles.EEG.setname '_events.xlsx'],'Sheet','Summary');
            writetable(events, [app.handles.EEG.filepath app.handles.EEG.setname '_events.xlsx'],'Sheet','RawData');
            choice = uiconfirm(app.figure1,'Sleep report generated.','Sleep Report','Icon','success');
            waitfor(choice);
        end

        % Menu selected function: generateHypnogram_m
        function generateHypnogram_m_Callback(app, event)
            % generate a hypnogram (useful for... looking at sleep architecture)
            stageLabels = app.handles.userStageNames; % labels of sleep stage epoch, default: {{'W'},{'REM'},{'N1'},{'N2'},{'SWS'},{'Unscored'}}. NOTE: order important for plotting
            stages = app.handles.stageData.stages;
            % recode to plot
            stageRecode(stages == 0) = 1;
            stageRecode(stages == 1) = 3;
            stageRecode(stages == 2) = 4;
            stageRecode(stages == 3) = 5;
            stageRecode(stages == 4) = 2;
            stageRecode(stages == 5) = 6;
            % convert to seconds for nicer / more conventional looking hypnogram plot
            nRecode = 1;
            stages = ones(length(stageRecode)*app.handles.stageData.win,1)';
            for nStage = 1:app.handles.stageData.win:length(stages)
                stages(nStage:nStage+app.handles.stageData.win-1) = stageRecode(nRecode);
                nRecode = nRecode + 1;
            end
            times = 1:1:length(stages);
            % plot the sleep stage data
            figure('units', 'normalized', 'outerposition', [0 0 1 1]);
            plot(times,stages, 'color',[0 0 0], 'LineWidth', 2); hold;
            % plot REM as thick line
            R = (stages == 2)*2;
            R(R == 0) = NaN;
            plot(times, R, 'color',[0 0 0], 'LineWidth', 16);
            % set axes
            ylim([min(stages)-1 max(stages)+1]); % upper and lower limits of y-axis to fit stage labels
            set(gca, 'YDir', 'reverse'); % reverse the y-axis
            yticklabels([' ', [stageLabels(1),stageLabels(5),stageLabels(2),stageLabels(3),stageLabels(4),stageLabels(6)], ' ']); % label y-axis tick marks
            xlim([0 times(end)]); % set upper and lower limits of x-axis to fit
            xticks(0:(60*60):times(end));
            set(gca,'XMinorTick','on'); 
            xticklabels(0:1:(times(end)/(60*60)));
            colorbar('off');
            % labels
            title(['Hypnogram: ' app.handles.EEG.setname], 'fontweight', 'bold', 'fontsize', 16, 'Interpreter', 'none'); % figure title
            xlabel('Time', 'fontweight', 'bold', 'fontsize', 16); % x-axis label
            ylabel('Sleep Stage', 'fontweight', 'bold', 'fontsize', 16); % y-axis label
            set(get(gca, 'YAxis'), 'FontWeight', 'bold', 'fontsize', 16); % change font
            set(get(gca, 'XAxis'), 'FontWeight', 'bold', 'fontsize', 16); % change font
            set(gca, 'LineWidth', 2); % adjust line width
            % Save it
            saveas(gcf,[app.handles.EEG.filepath app.handles.EEG.setname '_hypnogram.png']);
        end

        % Menu selected function: clearDataset_m
        function clearDataset_m_Callback(app, event)
            % clears current dataset without saving
            if app.save_t.Enable == "on"
                dlgTitle    = 'Save changes?';
                dlgQuestion = ['Warning: You have unsaved changes to the dataset.' newline newline 'Do you want to close without saving?'];
                choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                returnFocus2Figure(app);
                if strcmp(choice,'Yes')
                    markMode_Off_t_Callback(app);
                    menuItemsOFF(app);
                    saveOFF(app);
                    if isfield(app.handles, 'stageFileIN')
                        app.handles.EEG = eeg_emptyset();
                        app.handles = rmfield(app.handles, 'stageFileIN');
                        clearSet = 1; noPopUp = 0;
                        loadNewDataset(app,clearSet,noPopUp);
                    end
                else
                    return
                end
            else
                markMode_Off_t_Callback(app);
                menuItemsOFF(app);
                saveOFF(app);
                if isfield(app.handles, 'stageFileIN')
                    app.handles.EEG = eeg_emptyset();
                    app.handles = rmfield(app.handles, 'stageFileIN');
                    clearSet = 1; noPopUp = 0;
                    loadNewDataset(app,clearSet,noPopUp);
                end
            end
            app.numEpochs.Value = 1;
        end

        % Menu selected function: hideChan_m
        function hideChan_m_Callback(app, event)
            % hide a channel from user input
            if ~isempty(app.handles.EEG.chanlocs)
                if ~isfield(app.handles, 'hideChans')
                    app.handles.hideChans = {};
                end
                channels = {app.handles.EEG.chanlocs.labels};
                channels = channels(~ismember(channels,app.handles.hideChans));
                [idx,~] = listdlg('PromptString','Select channel to hide', 'SelectionMode', 'multiple', 'ListString', channels, 'ListSize', [250,250]);
                if ~isempty(idx)
                    for nCh = 1:length(idx)
                        app.handles.hideChans(end + 1) = channels(idx(nCh));
                    end
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                end
            else
                choice = uiconfirm(app.figure1,'Please load a dataset','No Dataset','Icon','warning');
                waitfor(choice);
                return
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: showChan_m
        function showChan_m_Callback(app, event)
            % show a channel from user input
            if isfield(app.handles, 'hideChans')
                if ~isempty(app.handles.hideChans)
                    if ~isempty(app.handles.EEG.chanlocs)
                        channels = app.handles.hideChans;
                        if ~isempty(channels)
                            [idx,~] = listdlg('PromptString','Select channel to show', 'SelectionMode', 'multiple', 'ListString', channels, 'ListSize', [250,250]);
                            if ~isempty(idx)
                                for nCh = 1:length(idx)
                                    app.handles.hideChans(find(strcmp(app.handles.hideChans, channels(idx(nCh))))) = [];
                                end
                                curX = xlim(app.traces);
                                range = curX(1):curX(2);
                                plotSleepData(app, range);
                            end
                        end
                    end
                else
                    choice = uiconfirm(app.figure1,'All channels are already visible.','Show channels','Icon','warning');
                    waitfor(choice);
                end
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: rescaleSelectedChannels_m
        function rescaleSelectedChannels_m_Callback(app, event)
            % select channel(s) to rescale
            if ~isempty(app.handles.EEG.chanlocs)
                channels = {app.handles.EEG.chanlocs.labels};
                [idx,~] = listdlg('PromptString','Select channel(s) to rescale', 'SelectionMode', 'multiple', 'ListString', channels, 'ListSize', [250,250]);
                if ~isempty(idx)
                    prompt = {['Enter a scaling factor (0 to <1: downscale; >1 to inf: upscale; 1: reset scaling)',newline]};
                    dlgtitle = 'Rescale selected channels';
                    dims = [1 90];
                    definput = {'1'};
                    scaleFactor = inputdlg(prompt,dlgtitle,dims,definput);
                    returnFocus2Figure(app);
                    if isempty(scaleFactor)
                        returnFocus2Figure(app);
                        return
                    end
                    scaleFactor = str2double(scaleFactor);
                    if ~isempty(app.handles.reScaledChannels)
                        if any(ismember(app.handles.reScaledChannels, idx)) % if already scaled, reset scaling to 1
                            oldIdx = app.handles.reScaledChannels(ismember(app.handles.reScaledChannels, idx));
                            notOldIdx = app.handles.reScaledChannels(~ismember(app.handles.reScaledChannels, idx));
                            for nIdx = 1:length(oldIdx)
                                app.handles.EEG.data(oldIdx(nIdx),:) = app.handles.EEG.data(oldIdx(nIdx),:)*1/app.handles.reScaleFactors(oldIdx(nIdx)); % undo scaling of EEG data
                            end
                            app.handles.reScaledChannels = sort([notOldIdx idx]);
                            app.handles.reScaleFactors(oldIdx) = 1; % reset scaling factor
                        end
                    else
                        app.handles.reScaledChannels = idx;
                    end
                    app.handles.reScaleFactors(idx) = scaleFactor;
                    app.handles.EEG.data(idx,:) = app.handles.EEG.data(idx,:)*scaleFactor;
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                else
                    returnFocus2Figure(app);
                    return
                end
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: setScale_m
        function setScale_m_Callback(app, event)
            % select channel to show/hide scale lines
            if isfield(app.handles, 'scaleChans')
                if ~isempty(app.handles.EEG.chanlocs)
                    channels = {app.handles.EEG.chanlocs.labels};
                    [idx,~] = listdlg('PromptString','Select channel to show/hide scale', 'SelectionMode', 'single', 'ListString', channels, 'ListSize', [250,250]);
                    if ~isempty(idx)
                        inds = find(strcmp(app.handles.scaleChans, app.handles.EEG.chanlocs(idx).labels));
                        if isempty(inds)
                            app.handles.scaleChans{end + 1} = app.handles.EEG.chanlocs(idx).labels;
                        else
                            app.handles.scaleChans(inds) = [];
                        end
                        curX = xlim(app.traces);
                        range = curX(1):curX(2);
                        plotSleepData(app, range);
                    end
                end
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: markChannelAsBadData_m
        function markChannelAsBadData_m_Callback(app, event)
            if ~isempty(app.handles.EEG.chanlocs)
                channels = {app.handles.EEG.chanlocs.labels};
                % create list box
                [idx,~] = listdlg('PromptString','Select a channel to mark as ''Bad Data:''', 'ListString', channels, 'ListSize', [250,250]);
                if ~isempty(idx)
                    app.handles.EEG.event(end+1).type = 'Bad Data';
                    app.handles.EEG.event(end).latency = 1;
                    app.handles.EEG.event(end).duration = app.handles.EEG.pnts-1;
                    app.handles.EEG.event(end).channel = app.handles.EEG.chanlocs(idx).labels;
                    app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                    app.handles.EEG = eeg_checkset(app.handles.EEG);
                    runPlot(app);
                    saveON(app);
                end
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: interpolateBadChannel_m
        function interpolateBadChannel_m_Callback(app, event)
            if ~isempty(app.handles.EEG.chanlocs) && isfield(app.handles.EEG.chanlocs, 'theta') && ~all(cellfun('isempty', { app.handles.EEG.chanlocs.theta }))
                % select bad channel to interpolate using good channels
                app.handles.EEG = eeg_checkchanlocs(app.handles.EEG);
                app.handles.EEG = eeg_checkset(app.handles.EEG);
                channels = {app.handles.EEG.chanlocs.labels};
                % select bad channel(s) to interpolate
                [idx,~] = listdlg('PromptString','Select bad channel(s) to interpolate', 'SelectionMode', 'multiple', 'ListString', channels, 'ListSize', [400,500]);
                if ~isempty(idx)
                    badCh = idx;
                    channels = {app.handles.EEG.chanlocs.labels};
                    % select good channels to use to interpolate bad channel(s)
                    [idx,~] = listdlg('PromptString','Select good channel(s) to use to interpolate bad channel(s)', 'ListString', channels, 'ListSize', [400,500]);
                    goodCh = idx;
                    goodCh = goodCh(~ismember(goodCh,badCh)); % take out any overlap between good and bad channels
                    % enter time point to start interpolation (optional)
                    srate = app.handles.stageData.srate;
                    curWin = xlim(app.traces);
                    startTime = app.handles.stageData.recStart + (fix(curWin(1)/srate)/86400);
                    prompt = {['Enter time in 24-hr time format: yyyy-MM-dd HH:mm:ss.SSS. Leave blank for none.',newline]};
                    dlgtitle = 'Enter Interpolation Start Time';
                    dims = [1 100];
                    definput = {char(startTime, app.handles.userRecStartFormat)};
                    interpStart = inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(interpStart{:})
                        interpStart = 0; % if empty, start interpolation from recording start
                    else
                        interpStart = seconds(interpStart - app.handles.stageData.recStart);
                    end
                    app.handles.EEG = cspsg_eeg_interp(app.handles.EEG, badCh, goodCh, interpStart);
                    clear idx channels badCh goodCh
                    runPlot(app);
                    saveON(app);
                end
            else
                dlgTitle    = 'Missing Channel Information';
                dlgQuestion = ['You will be prompted to either import channel info, or manually enter chanel info.' newline newline 'Do you wish to continue?'];
                choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                if strcmp(choice,'Yes')
                    editChannels_m_Callback(app, []);
                    interpolateBadChannel_m_Callback(app); % now you can run interpolate
                else
                    return
                end
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: filterChannels_m
        function filterChannels_m_Callback(app, event)
            % filter channels using EEGlab pop_eegfiltnew
            dlgTitle    = 'Filter warning';
            dlgQuestion = ['Warning: This will alter the raw data, but not automatically save changes.' newline newline 'Do you wish to continue?'];
            choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
            if strcmp(choice,'Yes')
                returnFocus2Figure(app);
                progress = uiprogressdlg(app.figure1,'Title','Filtering Dataset','Indeterminate','on');
                app.handles.EEG = pop_eegfiltnew(app.handles.EEG);
                runPlot(app);
                saveON(app);
                close(progress);
            else
                return
            end
            clear dlgTitle dlgQuestion choice
            returnFocus2Figure(app);
        end

        % Menu selected function: downsampleChannels_m
        function downsampleChannels_m_Callback(app, event)
            % downsample channels using EEGlab pop_resample
            dlgTitle    = 'Downsample warning';
            dlgQuestion = ['Warning: This will alter the raw data, but not automatically save changes.' newline newline 'Do you wish to continue?'];
            choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
            if strcmp(choice,'Yes')
                evtIdx = find([app.handles.EEG.event.duration] == 1);
                app.handles.EEG = pop_resample(app.handles.EEG);
                if ~isempty(evtIdx)
                    for nEvt = evtIdx
                        app.handles.EEG.event(nEvt).duration = 1; % when downsampling, it will turn single point events into less than 1 point, and when upsampling it will make them accordingly longer. This makes no sense. Need to fix it.
                    end
                end
                app.handles.stageData.srate = app.handles.EEG.srate;
                winSize = str2double(app.handles.winIN);
                srate = app.handles.EEG.srate;
                app.handles.stageData.onsets = ((0:(length(app.handles.stageData.stages) - 1)).*winSize*srate)+1;
                app.handles.stageData.stageTime = (0:(length(app.handles.stageData.stages) - 1))./(60/winSize);
                app.handles.stageData.events = app.handles.EEG.event;
                runPlot(app);
                saveON(app);
            else
                return
            end
            clear dlgTitle dlgQuestion choice
            returnFocus2Figure(app);
        end

        % Menu selected function: rereferenceChannels_m
        function rereferenceChannels_m_Callback(app, event)
            % re-reference channels using EEGlab pop_reref
            dlgTitle    = 'Rereference warning';
            dlgQuestion = ['Warning: This will alter the raw data.', 'You will have the option to save your changes.' newline newline 'Do you wish to continue?'];
            choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
            if strcmp(choice,'Yes')
                app.handles.EEG = pop_reref(app.handles.EEG);
                % create new montage, based on channel info and defaults
                prompt = {['Enter name for new montage:',newline]};
                dlgtitle = 'Save new montage';
                dims = [1 50];
                definput = {'reref'};
                scriptname = inputdlg(prompt,dlgtitle,dims,definput);
                returnFocus2Figure(app);
                scriptname = ['sleep_montage_' scriptname{:}];
                app.handles.userChannelLabels = {app.handles.EEG.chanlocs(:).labels}'; % based on output from reref
                colors(1:length({app.handles.EEG.chanlocs.labels})) = 'k'; % all black. boring!
                app.handles.userChannelColors = colors;
                createMontage(app,[scriptname '.m']);
                app.plotSleepIN.Value = scriptname;
                % save new data and reload
                saveDatasetAs_m_Callback(app);
                clearSet = 0;
                noPopUp = 1;
                loadNewDataset(app,clearSet,noPopUp);
                clear prompt clearSet noPopUp dlgtitle dims definput scriptname colors
            else
                return
            end
            clear prompt dlgTitle dlgQuestion choice
            returnFocus2Figure(app);
        end

        % Menu selected function: editChannels_m
        function editChannels_m_Callback(app, event)
            % edit channels (e.g., channel type) using EEGlab pop_chanedit
            dlgTitle    = 'Edit Channel warning';
            dlgQuestion = ['Warning: This will alter the raw data.', 'You will have the option to save your changes.' newline newline 'Do you wish to continue?'];
            choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
            if strcmp(choice,'Yes')
                app.handles.EEG = pop_chanedit(app.handles.EEG);
                % create new montage, based on channel info and defaults
                prompt = {['Enter name for new montage:',newline]};
                dlgtitle = 'Save new montage';
                dims = [1 50];
                definput = {'chanedit'};
                scriptname = inputdlg(prompt,dlgtitle,dims,definput);
                returnFocus2Figure(app);
                if ~isempty(scriptname)
                    scriptname = ['sleep_montage_' scriptname{:}];
                    app.handles.userChannelLabels = {app.handles.EEG.chanlocs(:).labels}'; % based on output from reref
                    colors(1:length({app.handles.EEG.chanlocs.labels})) = 'k'; % all black. boring!
                    app.handles.userChannelColors = colors;
                    createMontage(app,[scriptname '.m']);
                    app.plotSleepIN.Value = scriptname;
                    % save new data and reload
                    saveDatasetAs_m_Callback(app);
                    clearSet = 0;
                    noPopUp = 1;
                    loadNewDataset(app,clearSet,noPopUp);
                    clear prompt clearSet noPopUp dlgtitle dims definput scriptname colors
                else
                    return
                end
            else
                return
            end
            clear prompt dlgTitle dlgQuestion choice
            returnFocus2Figure(app);
        end

        % Menu selected function: ASR_m
        function ASR_m_Callback(app, event)
            asrPath = which('clean_rawdata');
            if isempty(asrPath)
                choice = uiconfirm(app.figure1,['''clean_rawdata'' EEGlab pluging not found.' newline newline 'Please install/update ''clean_rawdata'' EEGlab plugin.'],'Missing EEGLAB Plugin','Icon','warning');
                waitfor(choice);
            else
                saveDataset_m_Callback(app); % This will merge events with sleep stages. Required for ASR. No pop-up.
                app.handles.EEG = pop_loadset('filename',app.handles.EEG.filename,'filepath',app.handles.EEG.filepath); % load the just-saved dataset, no pop-up.
                % select channels of interest
                channels = {app.handles.EEG.chanlocs.labels};
                [chIdx,~] = listdlg('PromptString','Select channel(s) for ASR', 'SelectionMode', 'multiple', 'ListString', channels, 'ListSize', [250,250]);
                if ~isempty(chIdx)
                    EEGasr = pop_select(app.handles.EEG,'channel',chIdx);
                end
                % select sleep stages of interest
                events = app.handles.userStageNames;
                [stageIdx,~] = listdlg('PromptString','Select sleep stages for ASR', 'SelectionMode', 'multiple', 'ListString', events, 'ListSize', [250,250]);
                if ~isempty(stageIdx)
                    stageOfInterest = events(stageIdx);
                end
                clear stageIdx events
                % Input Dialog to get ASR cutoff value
                prompt = ['ASR cutoff value (Default 30-45 SD) :',newline];
                dlgtitle = 'Artifact Subspace Reconstruction (ASR) cutoff';
                dims = [1 75];
                definput = {'30'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                if isempty(answer)
                    % remove sleep stages from EEG.event structure
                    app.handles.EEG = pop_selectevent(app.handles.EEG,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                    runPlot(app);
                    returnFocus2Figure(app);
                    return
                else
                    SD = str2double(answer{:});
                end
                clear prompt dlgtitle dims definput answer
                progress = uiprogressdlg(app.figure1,'Title','Artifact Subspace Reconstruction','Message','Running ASR Analysis...'); pause(1);
                % Identify start and end of stage of interest periods
                % convert eeglab event structure to table
                events = struct2table(EEGasr.event);
                % filter events table so that it only contains sleep stages
                events(~ismember(events.type,stageOfInterest),:)=[];
                % run ASR on each sleep period
                for nStage=1:length(stageOfInterest)
                    progress.Value = nStage/(length(stageOfInterest)+1); progress.Message = 'Running ASR processing for each sleep stage...'; pause(1);
                    % find start and end of EEG periods
                    stageIdx = ismember(events{:,'type'},stageOfInterest(nStage));
                    StartIdx = strfind([ 0 stageIdx'],[0 1]);
                    EndIdx = strfind([stageIdx',0],[1 0]);
                    StartPeriod = events{[StartIdx(:)],'latency'};
                    EndPeriod = events{[EndIdx(:)],'latency'} + events{[EndIdx(:)],'duration'} - 1;
                    [stage{1:length(StartPeriod)}] = deal(stageOfInterest{nStage});
                    sleepPeriod = 1:length(StartPeriod);
                    clear stageIdx StartIdx EndIdx
                    % put it in a table for output
                    sleepPeriods = table(stage',sleepPeriod(:),StartPeriod(:),EndPeriod(:),'VariableNames',{'Stage','SleepPeriod','StartPeriod','EndPeriod'});
                    clear StartPeriod EndPeriod stage sleepPeriod
                    % run for each sleep period
                    for nPer = 1:height(sleepPeriods)
                        disp([newline 'Running ASR on stage ' stageOfInterest{nStage} ' period ' num2str(nPer) ' of ' num2str(height(sleepPeriods)) newline])
                        EEGtemp = pop_select(EEGasr,'point',[sleepPeriods.StartPeriod(nPer) sleepPeriods.EndPeriod(nPer)]); % select just the one period
                        % clean_asr USAGE:
                        % Signal = clean_asr(Signal,StandardDevCutoff,WindowLength,BlockSize,MaxDimensions,ReferenceMaxBadChannels,RefTolerances,ReferenceWindowLength,UseGPU,UseRiemannian,MaxMem);
                        try
                            EEGtemp = clean_asr(EEGtemp,SD,3,[],[],[],[],2,[],[],[]); % call ASR function from clean_rawdata plugin
                        catch
                            warning([newline 'ASR skipped/failed for this section of data. Likely due to not enough clean data in baseline, re; short section of data.'])
                        end
                        EEGasr.data(:,sleepPeriods.StartPeriod(nPer):sleepPeriods.EndPeriod(nPer)) = EEGtemp.data(:,:); % return the ASR cleaned adat to the original
                    end
                    clear sleepPeriods EEGtemp
                end
                clear SD
                progress.Value = (nStage+1)/(length(stageOfInterest)+1); progress.Message = 'Replacing original data...'; pause(1);
                % replace original data with cleaned data
                for nCh=1:length(chIdx)
                    app.handles.EEG.data(chIdx(nCh),:) = EEGasr.data(nCh,:);
                end
                % remove sleep stages from EEG.event structure
                app.handles.EEG = pop_selectevent(app.handles.EEG,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                runPlot(app);
                saveON(app);
                close(progress);
            end
            clear asrPath
            returnFocus2Figure(app);
        end

        % Menu selected function: runICA_m
        function runICA_m_Callback(app, event)
            % run independent component analysis on EEG signals
            progress = uiprogressdlg(app.figure1,'Title','Running ICA. This can take a while.','Indeterminate','on');
            app.handles.EEG = pop_runica(app.handles.EEG);
            saveON(app);
            app.ICA_m.Enable = "on"; % disable main menu
            app.ICA_t.Enable = "on"; % enable display ICA toolbar button
            app.deleteICA_m.Enable = "on"; % enable menu item to remove component
            close(progress)
            returnFocus2Figure(app);
            % save dataset
            dlgTitle    = 'Save changes?';
            dlgQuestion = ['ICA complete.' newline newline 'Do you want to save dataset?'];
            choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
            if strcmp(choice,'Yes')
                % save new data and reload
                saveDatasetAs_m_Callback(app);
                clearSet = 0;
                noPopUp = 1;
                loadNewDataset(app,clearSet,noPopUp);
            end
            app.ICA_t.State = "on"; % Call display ICA function
        end

        % Menu selected function: displayICA_m
        function displayICA_m_Callback(app, event)
            if app.ICA_t.State == "on"
                app.ICA_t.State = "off"; % Call ICA off function
            elseif app.ICA_t.State == "off"
                app.ICA_t.State = "on"; % Call display ICA function
            end
        end

        % Menu selected function: deleteICA_m
        function deleteICA_m_Callback(app, event)
            % Remove selected indepedent components from EEG signals
            if isfield(app.handles.EEG, 'icaact')
                if ~isempty(app.handles.EEG.icaact)
                    app.handles.EEG = pop_subcomp(app.handles.EEG);
                    ICA_Off_t_Callback(app);
                    saveON(app);
                    app.ICA_m.Enable = "off"; % disable main menu
                    app.ICA_t.Enable = "off"; % disable display ICA toolbar button
                    app.displayICA_m.Enable = "off"; % disable menu item to display components
                    app.deleteICA_m.Enable = "off"; % disable menu item to display components
                    % save dataset
                    dlgTitle    = 'Save changes?';
                    dlgQuestion = ['ICA components removed.' newline newline 'Do you want to save dataset?'];
                    choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                    if strcmp(choice,'Yes')
                        % save new data and reload
                        saveDatasetAs_m_Callback(app);
                        clearSet = 0;
                        noPopUp = 1;
                        loadNewDataset(app,clearSet,noPopUp);
                    end
                end
            end
        end

        % Menu selected function: FFT_m
        function FFT_m_Callback(app, event)
            % Runs power spectral density (PSD) on FFT using a Hamming
            % window to have a better estimate for non stationary signal.
            % By default, uses a window of 5 seconds, for 6 windows per 
            % 30 sec sleep stage to get a frequency resolution of 0.2Hz.
            % Output is in dB.
            saveDataset_m_Callback(app); % This will merge events with sleep stages. Required for PSD. No pop-up.
            EEGtemp = pop_loadset('filename',app.handles.EEG.filename,'filepath',app.handles.EEG.filepath); % load the just-saved dataset, no pop-up.
            % Input Dialog to get min frequency, max frequency, bad date labels, channel labels
            prompt = {'Minimum frequency (default 1):','Maximum Frequency (default 30):','Bad data event labels (comma-separated):','Channels to include (comma-separated):'};
            dlgtitle = 'Power Spectral Density Study-Specific Parameters';
            dims = [1 100];
            definput = {'1','30','Movement, BadData',strjoin({EEGtemp.chanlocs.labels},', ')};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            badData = {strsplit(answer{3},{', ',','})};
            [~,chIdx] = ismember(strsplit(answer{4},{', ',','}),{EEGtemp.chanlocs.labels});
            PARAM = struct(...
                'epoch', 30, ... length in seconds of sleep stage epoch {default: 30}
                'winsize', 5, ... size of FFT window in seconds {default: 5}. Use default for 6 windows per 30 sec sleep stage and a freq resolution of 0.2Hz.
                'plot', 'off', ... ['on'|'off'], plot result, {default: 'off'}
                'stages', {app.handles.userStageNames(1:end-1)'}, ... labels of sleep stage epoch, default: {'W','N1','N2','SWS','REM'}
                'filename', app.handles.EEG.setname, ... filename from dataset
                'resultDir', app.handles.EEG.filepath, ... path from dataset
                ... end of hard-coded parameters
                'freqrange', [str2double(answer{1}) str2double(answer{2})], ... range of frequencies {default: [1 30]}. NOTE: standard PSG processing filters EEG from 0.3 to 35Hz. If so, use default to avoid edges of filters.
                'baddata', badData, ... label for bad data, {default: {{'Movement'}}}
                'plotchans', chIdx ... vector of channel indices to include in PSD {default: [3:5 7:9 11:14]; i.e., C3,C4,Cz,F3,F4,Fz,Oz,P3,P4,Pz}
                );
            clear prompt dlgtitle dims definput badData chIdx answer
            % start with a fresh FFT structure
            FFT = [];
            % check chanlocs (needed for topoplot)
            FFT.chanlocs = EEGtemp.chanlocs(PARAM.plotchans);
            if isempty(FFT.chanlocs(1).theta) && isempty(FFT.chanlocs(1).X) && isempty(FFT.chanlocs(1).sph_theta) % no chanloc info of any kind
                EEGtempCHsel = EEGtemp;
                EEGtempCHsel = pop_select(EEGtempCHsel,'channel',PARAM.plotchans); % select only channels to process for FFT
                EEGtempCHsel = pop_chanedit(EEGtempCHsel); % get channel info for those channels
                FFT.chanlocs = EEGtempCHsel.chanlocs;
            end
            % Process The Dataset
            progress = uiprogressdlg(app.figure1,'Title','Power Spectral Analysis','Message','Running PSD Analysis...'); pause(1);
            % find stages to epoch
            stageIndex = find(ismember({EEGtemp.event.type},PARAM.stages));
            % find bad data
            badIndex = find(ismember({EEGtemp.event.type},PARAM.baddata));
            % loop over bad data events to get indices for epochs with bad data
            progress.Value = 1/3; progress.Message = 'Removing bad data...'; pause(1);
            todelete = [];
            ndel = 1;
            for nbad = 1:length(badIndex)
                for nstage = 1:length(stageIndex)
                    % find stage events that start during bad data
                    if EEGtemp.event(stageIndex(nstage)).latency > EEGtemp.event(badIndex(nbad)).latency && EEGtemp.event(stageIndex(nstage)).latency < (EEGtemp.event(badIndex(nbad)).latency + EEGtemp.event(badIndex(nbad)).duration)
                        todelete(ndel) = stageIndex(nstage);
                        ndel = ndel + 1;
                    end
                    % find stage events that end during bad data
                    if (EEGtemp.event(stageIndex(nstage)).latency + EEGtemp.event(stageIndex(nstage)).duration) > EEGtemp.event(badIndex(nbad)).latency && EEGtemp.event(stageIndex(nstage)).latency < (EEGtemp.event(badIndex(nbad)).latency + EEGtemp.event(badIndex(nbad)).duration)
                        todelete(ndel) = stageIndex(nstage);
                        ndel = ndel + 1;
                    end
                    % find stage events that have bad data during
                    if ~isequal(EEGtemp.event(badIndex(nbad)),EEGtemp.event(1)) % if it's not the first event in the original structure
                        if EEGtemp.event(badIndex(nbad)).latency > EEGtemp.event(stageIndex(nstage)).latency && EEGtemp.event(badIndex(nbad)).latency < EEGtemp.event(stageIndex(nstage)).latency + EEGtemp.event(stageIndex(nstage)).duration
                            todelete(ndel) = stageIndex(nstage);
                            ndel = ndel + 1;
                        end
                    end
                end
            end
            clear ndel nbad nstage
            % remove stages with bad data
            goodepochs = stageIndex;
            todelete = unique(todelete); % get unique indices, re; can be duplicates from above
            todelete = ismember(stageIndex, todelete);
            goodepochs(todelete) = []; % delete the stages from stageIndex
            clear todelete stageIndex badIndex
            % get good event info from original EEG structure
            type = {EEGtemp.event(goodepochs).type};
            latency = {EEGtemp.event(goodepochs).latency};
            duration = {EEGtemp.event(goodepochs).duration};
            urevent = {EEGtemp.event(goodepochs).urevent};
            FFT.event = struct('type',type, ...
                'latency',latency, ...
                'duration',duration, ...
                'urevent',urevent ...
                );
            clear type latency duration urevent
            % epoch only good data (e.g., movement-free), according to sleep stage
            try
                EEGtemp = pop_epoch(EEGtemp, PARAM.stages, [0  PARAM.epoch], 'newname',[EEGtemp.setname '_FFT'], 'eventindices',goodepochs, 'epochinfo','yes');
            catch
                choice = uiconfirm(app.figure1,'Dataset not sleep stage scored. Sleep stage scoring required for spectral analysis.','Missing sleep stage scoring','Icon','error');
                waitfor(choice);
                return
            end
            EEGtemp = eeg_checkset(EEGtemp);
            clear goodepochs
            % run spectral analysis on each sleep stage epoch
            progress.Value = 2/3; progress.Message = 'Running PSD Analysis...'; pause(1);
            datasize = size(EEGtemp.data);
            spectra = zeros(length(PARAM.plotchans),PARAM.freqrange(2)*PARAM.winsize,datasize(3));
            for nepoch = 1:datasize(3)
                if all(all(EEGtemp.data(:,:,nepoch))) % if all elements are non-zero
                    nspectra = spectopo(EEGtemp.data(:,:,nepoch), 0, EEGtemp.srate, 'nfft',PARAM.winsize*EEGtemp.srate, 'winsize',PARAM.winsize*EEGtemp.srate, 'freqrange',PARAM.freqrange, 'plotchans',PARAM.plotchans, 'plot', PARAM.plot);
                    nspectra = nspectra(:,1:PARAM.freqrange(2)*PARAM.winsize);
                    spectra(:,:,nepoch) = nspectra;
                else % some epochs can contain zeroed data (if amp is disconnected, or reset during acquisition). Do not run spectopo, insert zeros.
                    nspectra = zeros(length(PARAM.plotchans),PARAM.freqrange(2)*PARAM.winsize);
                    spectra(:,:,nepoch) = nspectra;
                end
            end
            FFT.spectra = spectra;
            clear datasize nepoch spectra nspectra
            % Summarize the FFT output
            progress.Value = 3/3; progress.Message = 'Saving output...'; pause(1);
            datasize = size(FFT.spectra);
            mspectra = zeros(datasize(1),datasize(2),length(PARAM.stages));
            for nstage = 1:length(PARAM.stages)
                stageIndex = find(ismember({FFT.event.type},PARAM.stages(nstage)));
                mspectra(:,:,nstage) = mean(FFT.spectra(:,:,stageIndex),3);
            end
            FFT.mspectra.data = mspectra;
            FFT.mspectra.stage = PARAM.stages;
            clear datasize mspectra nstage stageIndex EEGtemp
            % Save the FFT output
            save([PARAM.resultDir PARAM.filename '_FFT.mat'], 'FFT', 'PARAM');
            close(progress);
        end

        % Menu selected function: Plot_FFT_m
        function Plot_FFT_m_Callback(app, event)
            % select FFT results mat file to load
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat');
            else
                app.fileSelectData = fileSelect(app, '', '', 'mat');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            else
                load([app.filepath filesep app.filename],'FFT','PARAM')
                if ~exist('FFT','var') || ~exist('PARAM','var')
                    choice = uiconfirm(app.figure1,'Selected file not valid FFT output','File Select Error','Icon','error');
                    waitfor(choice);
                    return
                end
            end
            % Input Dialog to get min frequency, max frequency, bad date labels, channel labels
            prompt = {'Minimum frequency (default 1):','Maximum Frequency (default 30):','Channel Label (single channel to plot; Default Cz):'};
            dlgtitle = 'Power Spectral Density Plot Settings';
            dims = [1 100];
            definput = {'1','30','Cz'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            % plot options
            loHz = str2double(answer{1}); % low frequency limit
            hiHz = str2double(answer{2}); % high frequency limit
            [~,chIdx] = ismember(strsplit(answer{3},{', ',',',' '}),{FFT.chanlocs.labels}); % channel to plot
            if length(chIdx) > 1 || isempty(chIdx)
                choice = uiconfirm(app.figure1,'Input only one correct channel label to plot','Input Error','Icon','error');
                waitfor(choice);
                return
            else
                chLabel = answer{3};
            end
            clear prompt dlgtitle dims definput answer
            % Plot mean power spectrum
            % rescale
            xlo = PARAM.winsize*loHz;
            xhi = PARAM.winsize*hiHz;
            % get min & max
            ylo = round(min(min(FFT.mspectra.data(chIdx,:,:),[],"omitmissing"),[],"omitmissing"));
            yhi = round(max(max(FFT.mspectra.data(chIdx,:,:),[],"omitmissing"),[],"omitmissing"));
            figure; plot(FFT.mspectra.data(chIdx,:,1)', 'color',[0.4660 0.6740 0.1880], 'LineWidth',2) % for stage W
            hold on
            plot(FFT.mspectra.data(chIdx,:,2)', 'color',[0.3010 0.7450 0.9330], 'LineWidth',2) % for stage N1
            plot(FFT.mspectra.data(chIdx,:,3)', 'color',[0 0.4470 0.7410], 'LineWidth',2) % for stage N2
            plot(FFT.mspectra.data(chIdx,:,4)', 'color',[0.9290 0.6940 0.1250], 'LineWidth',2) % for stage SWS
            plot(FFT.mspectra.data(chIdx,:,5)', 'color',[0.6350 0.0780 0.1840], 'LineWidth',2) % for stage REM
            axis([xlo xhi ylo yhi])
            hold off
            % additional options:
            title(['Mean Power Spectrum: Channel ' chLabel]) % figure title
            xlabel('Frequency (Hz)') % x-axis label
            ylabel('Power (dB)') % y-axis label
            legend(PARAM.stages)
            set(gca,'XTick',xlo:PARAM.winsize:xhi)
            set(gca,'XTickLabel',loHz:hiHz)
            set(gcf,'color','w'); % change figure background to white
            clearvars -except FFT PARAM
        end

        % Menu selected function: Plot_Spectrogram_m
        function Plot_Spectrogram_m_Callback(app, event)
            % select FFT results mat file to load
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat');
            else
                app.fileSelectData = fileSelect(app, '', '', 'mat');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            else
                load([app.filepath filesep app.filename],'FFT','PARAM')
                if ~exist('FFT','var') || ~exist('PARAM','var')
                    choice = uiconfirm(app.figure1,'Selected file not valid FFT output','File Select Error','Icon','error');
                    waitfor(choice);
                    return
                end
            end
            % Input Dialog to get min frequency, max frequency, bad date labels, channel labels
            prompt = {'Minimum frequency (default 1):','Maximum Frequency (default 30):','Channel Label (single channel to plot; Default Cz):'};
            dlgtitle = 'Power Spectral Density Plot Settings';
            dims = [1 100];
            definput = {'1','30','Cz'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            % plot options
            loHz = str2double(answer{1}); % low frequency limit
            hiHz = str2double(answer{2}); % high frequency limit
            [~,chIdx] = ismember(strsplit(answer{3},{', ',',',' '}),{FFT.chanlocs.labels}); % channel to plot
            if length(chIdx) > 1 || isempty(chIdx)
                choice = uiconfirm(app.figure1,'Input only one correct channel label to plot','Input Error','Icon','error');
                waitfor(choice);
                return
            else
                chLabel = answer{3};
            end
            clear prompt dlgtitle dims definput answer
            % hypnogram
            % find stages to epoch
            stages = {FFT.event.type};
            stageIndexW = ismember(stages,app.handles.userStageNames{1});
            stageIndexN1 = ismember(stages,app.handles.userStageNames{2});
            stageIndexN2 = ismember(stages,app.handles.userStageNames{3});
            stageIndexN3 = ismember(stages,app.handles.userStageNames{4});
            stageIndexR = ismember(stages,app.handles.userStageNames{5});
            stages(stageIndexW) = {5};
            stages(stageIndexR) = {4};
            stages(stageIndexN1) = {3};
            stages(stageIndexN2) = {2};
            stages(stageIndexN3) = {1};
            % plot the sleep stage data
            figure; subplot(2,1,1);
            plot(cell2mat(stages), 'color',[0 0 0], 'LineWidth',2)
            % axes
            ylim([0 6]) % upper and lower limits of y-axis
            xlim([1 length(stages)]) % upper and lower limits of x-axis
            set(gca,'YTickLabel',[' ',flip({'Wake','REM','N1','N2','SWS'}),' ']) % need to customize depending on above axis scales
            % labels
            title('Hypnogram') % figure title
            xlabel('Epoch (30 sec)') % x-axis label
            ylabel('Sleep Stages') % y-axis label
            % time-frequency plot
            % axis scales
            flo = PARAM.winsize*loHz;
            fhi = PARAM.winsize*hiHz;
            % plot the time-frequency spectrogram
            subplot(2,1,2);
            image(squeeze(FFT.spectra(chIdx,flo:fhi,:)),'CDataMapping','scaled')
            % axes
            set(gca,'YDir','normal')
            ylim([flo fhi]) % upper and lower limits of y-axis
            xlim([1 length(stages)]) % upper and lower limits of x-axis
            set(gca,'YTick',flo:PARAM.winsize*2:fhi)
            set(gca,'YTickLabel',loHz:2:hiHz)
            % colors
            set(gcf,'color','w'); % change figure background to white
            % labels
            title(['Spectrogram: Channel ' chLabel]) % figure title
            xlabel('Epoch (30 sec)') % x-axis label
            ylabel('Frequency (Hz)') % y-axis label
            % color bar
            cbar('vert',0,[-1 1].*round(max(max(abs(squeeze(FFT.spectra(chIdx,flo:fhi,:))),[],"omitmissing"),[],"omitmissing")))
            title('dB') % figure title
            clearvars -except FFT PARAM
        end

        % Menu selected function: Plot_Topo_m
        function Plot_Topo_m_Callback(app, event)
            % select FFT results mat file to load
            if isfield(app.handles,'EEG')
                app.fileSelectData = fileSelect(app, app.handles.EEG.filepath, app.handles.EEG.setname, 'mat');
            else
                app.fileSelectData = fileSelect(app, '', '', 'mat');
            end
            waitfor(app.fileSelectData);
            if isempty(app.filename)
                returnFocus2Figure(app);
                return
            else
                load([app.filepath filesep app.filename],'FFT','PARAM')
                if ~exist('FFT','var') || ~exist('PARAM','var')
                    choice = uiconfirm(app.figure1,'Selected file not valid FFT output','File Select Error','Icon','error');
                    waitfor(choice);
                    return
                end
            end
            % Input Dialog to get min frequency, max frequency, bad date labels, channel labels
            prompt = {'Lower edge of frequency band to plot:','Bandwidth to plot (if using single point, eneter 0):'};
            dlgtitle = 'Power Spectral Density Plot Settings';
            dims = [1 100];
            definput = {'1','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            % plot options
            lofreq = str2double(answer{1}); % low frequency limit
            bandwidth = str2double(answer{2}); % high frequency limit
            clear prompt dlgtitle dims definput answer
            % topographic plots
            % rescale
            lofreq = PARAM.winsize*lofreq;
            bandwidth = bandwidth*PARAM.winsize;
            % for freq band
            lof = lofreq;
            hif = lofreq + bandwidth;
            lof = fix(lof); % used as index in frequency range, needs to be nerest integer
            hif = fix(hif); % used as index in frequency range, needs to be nerest integer
            % get min & max values for scaling
            lo = fix(min(min(mean(FFT.mspectra.data(:,lof:hif,:),2),[],"omitmissing"),[],"omitmissing"));
            hi = fix(max(max(mean(FFT.mspectra.data(:,lof:hif,:),2),[],"omitmissing"),[],"omitmissing"));
            figure('Position', [200 400 1000 200]);
            set(gcf,'color','w'); % change figure background to white
            % Stage W
            W = 1;
            subplot(1,5,1);
            title('Wake')
            if ~isnan(FFT.mspectra.data(:,lof:hif,W))
                data = mean(FFT.mspectra.data(:,lof:hif,W),2);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            else
                data = zeros(height(FFT.mspectra.data(:,lof:hif,W)),1);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            end
            % Stage N1
            N1 = 2;
            subplot(1,5,2);
            title('N1')
            if ~isnan(FFT.mspectra.data(:,lof:hif,N1))
                data = mean(FFT.mspectra.data(:,lof:hif,N1),2);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            else
                data = zeros(height(FFT.mspectra.data(:,lof:hif,W)),1);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            end
            % Stage N2
            N2 = 3;
            subplot(1,5,3);
            title('N2')
            if ~isnan(FFT.mspectra.data(:,lof:hif,N2))
                data = mean(FFT.mspectra.data(:,lof:hif,N2),2);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            else
                data = zeros(height(FFT.mspectra.data(:,lof:hif,W)),1);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            end
            % Stage N3
            N3 = 4;
            subplot(1,5,4);
            title('SWS')
            if ~isnan(FFT.mspectra.data(:,lof:hif,N3))
                data = mean(FFT.mspectra.data(:,lof:hif,N3),2);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            else
                data = zeros(height(FFT.mspectra.data(:,lof:hif,W)),1);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            end
            % Stage R
            R = 5;
            subplot(1,5,5);
            title('REM')
            if ~isnan(FFT.mspectra.data(:,lof:hif,R))
                data = mean(FFT.mspectra.data(:,lof:hif,R),2);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            else
                data = zeros(height(FFT.mspectra.data(:,lof:hif,W)),1);
                topoplot(data, FFT.chanlocs, 'maplimits',[lo hi], 'whitebk','on');
            end
            cbar('vert',0,[lo hi]);
            ylabel('Power (dB)');
            clearvars -except FFT PARAM
        end

        % Menu selected function: detectMovement_m
        function detectMovement_m_Callback(app, event)
            % Movement artifact detection
            prompt = {'Enter EMG channel label to detect movement (Default: ''EMG''):', ...
                'Enter gradient threshold (Default: 25; leave blank for automatic):', ...
                'Enter minimum movement duration in sec (Default: 1):'};
            dlgtitle = 'Movement artifact detection settings';
            dims = [1 100];
            definput = {'EMG','25','1'};
            out = inputdlg(prompt,dlgtitle,dims,definput);
            returnFocus2Figure(app);
            if ~isempty(out)
                if ~isempty(out{1})
                    PARAM.EMGCh = out{1};
                    PARAM.EMGChIdx = find(strcmp(app.handles.userChannelLabels, PARAM.EMGCh));
                    if isempty(PARAM.EMGChIdx)
                        choice = uiconfirm(app.figure1,['Could not find channel: ''' PARAM.EMGCh ''' in list of channel lables.'],'Channel Not Found','Icon','warning');
                        waitfor(choice);
                        return
                    end
                else
                    choice = uiconfirm(app.figure1,'Please enter a valid channel lable for EMG.','Missing Entry','Icon','warning');
                    waitfor(choice);
                end
                if ~isempty(out{2})
                    PARAM.gradient_threshold = str2double(out{2});
                else
                    meanSig = mean(abs(app.handles.EEG.data(PARAM.EMGChIdx,:)));
                    stdSig = std(abs(app.handles.EEG.data(PARAM.EMGChIdx,:)));
                    PARAM.gradient_threshold = meanSig + stdSig;
                    clear meanSig stdSig
                end
                if ~isempty(out{3})
                    PARAM.gradient_duration = str2double(out{3});
                else
                    choice = uiconfirm(app.figure1,'Please enter a minimum duration for movement artifact.','Missing Entry','Icon','warning');
                    waitfor(choice);
                    return
                end
            else
                return
            end
            % calculate the first derivative of the signal
            progress = uiprogressdlg(app.figure1,'Title','Movement Artifact Detection','Message','Calculating the first derivative of the signal...'); pause(.5);
            gradient = diff(app.handles.EEG.data(PARAM.EMGChIdx,:),1,2);
            nb_points = app.handles.EEG.pnts;
            arttime = logical(sum(gradient>PARAM.gradient_threshold,1));
            tWIN = round(app.handles.EEG.srate*PARAM.gradient_duration);
            WIN = -tWIN:tWIN;
            t = 0;
            CONTINUE = 1;
            while CONTINUE
                t = t+1;
                if arttime(t)
                    arttime(max(min(t+WIN,nb_points-1),1)) = 1;
                    t = t+tWIN;
                end
                if t > nb_points-2
                    CONTINUE = 0;
                end
            end
            arttime(nb_points) = 0; % because of Diff
            % create empty EEGlab event structure to match existing to merge
            progress.Value = 1/3; progress.Message = 'Detecting movement...'; pause(1);
            fields = fieldnames(app.handles.EEG.event)';
            fields{2,1} = {};
            movement = struct(fields{:});
            clear fields
            t = 0;
            CONTINUE = 1;
            while CONTINUE
                temp = find(arttime((t+1):end),1);
                if isempty(temp)
                    CONTINUE = 0;
                else
                    long = find(~arttime((t+temp):end),1);
                    if isempty(long)
                        long = nb_points-t-temp;
                        CONTINUE = 0;
                    end
                    movement(end+1).type = 'Movement';
                    movement(end).latency = temp+t;
                    movement(end).duration = long;
                    movement(end).channel = PARAM.EMGCh;
                    movement(end).urevent = 0;
                    t = t+temp+long+1;
                end
            end
            % Merge markers with existing events
            progress.Value = 2/3; progress.Message = 'Merging movement events with current dataset...'; pause(1);
            app.handles.EEG.event = [app.handles.EEG.event movement];
            app.handles.EEG = eeg_checkset(app.handles.EEG,'eventconsistency');
            app.handles.EEG = eeg_checkset(app.handles.EEG);
            runPlot(app);
            progress.Value = 3/3; progress.Message = 'Movement detection complete...'; pause(1);
            close(progress);
            saveON(app);
            returnFocus2Figure(app);
        end

        % Menu selected function: detectSpindles_m
        function detectSpindles_m_Callback(app, event)
            % detect spindles using detect_spindles plugin for EEGlab
            spindlePath = which('pop_detect_spindles');
            if isempty(spindlePath)
                choice = uiconfirm(app.figure1,['''detect_spindles'' EEGlab pluging not found.' newline newline 'Please install/update ''detect_spindles'' EEGlab plugin.'],'Missing EEGLAB Plugin','Icon','warning');
                waitfor(choice);
            else
                addpath(genpath(fileparts(spindlePath)));
                saveDataset_m_Callback(app); % This will merge events with sleep stages. Required for spindle detection. No pop-up.
                app.handles.EEG = pop_loadset('filename',app.handles.EEG.filename,'filepath',app.handles.EEG.filepath); % load the just-saved dataset, no pop-up.
                app.handles.EEG = pop_detect_spindles(app.handles.EEG);
                app.handles.stageData = rmfield(app.handles.stageData, 'events');
                % remove sleep stages from EEG.event structure
                app.handles.EEG = pop_selectevent(app.handles.EEG,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                runPlot(app);
                saveON(app);
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: detectSlowWaves_m
        function detectSlowWaves_m_Callback(app, event)
            % detect slow waves using PAA plugin for EEGlab
            paaPath = which('pop_PAA');
            if isempty(paaPath)
                choice = uiconfirm(app.figure1,['''PAA'' EEGlab pluging not found.' newline newline 'Please install/update ''PAA'' EEGlab plugin.'],'Missing EEGLAB Plugin','Icon','warning');
                waitfor(choice);
            else
                addpath(genpath(fileparts(paaPath)));
                saveDataset_m_Callback(app); % This will merge events with sleep stages. Required for SW detection. No pop-up.
                app.handles.EEG = pop_loadset('filename',app.handles.EEG.filename,'filepath',app.handles.EEG.filepath); % load the just-saved dataset, no pop-up.
                app.handles.EEG = pop_PAA(app.handles.EEG);
                app.handles.stageData = rmfield(app.handles.stageData, 'events');
                % remove sleep stages from EEG.event structure
                app.handles.EEG = pop_selectevent(app.handles.EEG,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                runPlot(app);
                saveON(app);
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: detectEyeMovements_m
        function detectEyeMovements_m_Callback(app, event)
            % detect eye movements using Yetton's REM detector
            remPath = which('pop_detect_REMs');
            if isempty(remPath)
                choice = uiconfirm(app.figure1,['''detect_REMs'' EEGlab pluging not found.' newline newline 'Please install/update ''detect_REMs'' EEGlab plugin.'],'Missing EEGLAB Plugin','Icon','warning');
                waitfor(choice);
            else
                addpath(genpath(fileparts(remPath)));
                saveDataset_m_Callback(app); % This will merge events with sleep stages. Required for REM detection. No pop-up.
                app.handles.EEG = pop_loadset('filename',app.handles.EEG.filename,'filepath',app.handles.EEG.filepath); % load the just-saved dataset, no pop-up.
                app.handles.EEG = pop_detect_REMs(app.handles.EEG);
                app.handles.stageData = rmfield(app.handles.stageData, 'events');
                % remove sleep stages from EEG.event structure
                app.handles.EEG = pop_selectevent(app.handles.EEG,'type',app.handles.userStageNames(1:6),'select','inverse','deleteevents','on');
                runPlot(app);
                saveON(app);
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: OnOffEventMark_m
        function OnOffEventMark_m_Callback(app, event)
            if strcmp(app.markStr.Enable,'off')
                markMode_On_t_Callback(app, event)
            else
                markMode_Off_t_Callback(app, event)
            end
        end

        % Menu selected function: clearEvents_m
        function clearEvents_m_Callback(app, event)
            % clear all events of user-specified type
            events = unique({app.handles.EEG.event.type});
            [idx,~] = listdlg('PromptString','Select event types to delete', 'SelectionMode', 'multiple', 'ListString', events, 'ListSize', [250,250]);
            if ~isempty(idx)
                dlgTitle = 'Confirm Delete Events';
                dlgQuestion = ['This will permanently delete selected event types.' newline newline 'Continue?'];
                choice = uiconfirm(app.figure1,dlgQuestion,dlgTitle,'Options',{'Yes','No'});
                if strcmp(choice,'Yes')
                    events = events(idx);
                    % remove from original EEG.event structure
                    app.handles.EEG = pop_selectevent(app.handles.EEG,'type',events,'select','inverse','deleteevents','on');
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                    app.handles.stageData.events = app.handles.EEG.event;
                    stageData = app.handles.stageData;
                    save(app.handles.stageFileIN, 'stageData');
                    saveON(app);
                else
                    return
                end
            else
                return
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: hideEvents_m
        function hideEvents_m_Callback(app, event)
            % hide events from user input
            if ~isfield(app.handles, 'hideEvents')
                app.handles.hideEvents = {};
            end
            events = unique({app.handles.EEG.event.type});
            events = events(~ismember(events,app.handles.hideEvents));
            [idx,~] = listdlg('PromptString','Select event types to hide', 'SelectionMode', 'multiple', 'ListString', events, 'ListSize', [250,250]);
            if ~isempty(idx)
                for nEvt = 1:length(idx)
                    app.handles.hideEvents(end + 1) = events(idx(nEvt));
                end
                curX = xlim(app.traces);
                range = curX(1):curX(2);
                plotSleepData(app, range);
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: showEvents_m
        function showEvents_m_Callback(app, event)
            % show events from user input
            if isfield(app.handles, 'hideEvents')
                if ~isempty(app.handles.hideEvents)
                    if ~isempty(app.handles.EEG.event)
                        events = unique(app.handles.hideEvents);
                        if ~isempty(events)
                            [idx,~] = listdlg('PromptString','Select event types to show', 'SelectionMode', 'multiple', 'ListString', events, 'ListSize', [250,250]);
                            if ~isempty(idx)
                                for nEvt = 1:length(idx)
                                    app.handles.hideEvents(find(strcmp(app.handles.hideEvents, events(idx(nEvt))))) = [];
                                end
                                curX = xlim(app.traces);
                                range = curX(1):curX(2);
                                plotSleepData(app, range);
                            end
                        end
                    end
                end
            end
            returnFocus2Figure(app);
        end

        % Menu selected function: editLightsTags_m
        function editLightsTags_m_Callback(app, event)
            validDate(app);
            prompt = {['Current Recording Start Time: ' char(app.handles.stageData.recStart, app.handles.userRecStartFormat) newline newline...
                'Current Lights Off Time: ' char(app.handles.stageData.lightsOFF, app.handles.userRecStartFormat) newline...
                'Enter new ''Lights Off'' time in 24-hr time format: ' app.handles.userRecStartFormat newline], ...
                ['Current Recording Stop Time: ' char(app.handles.stageData.recStop, app.handles.userRecStartFormat) newline newline...
                'Current Lights On Time: ' char(app.handles.stageData.lightsON, app.handles.userRecStartFormat) newline...
                'Enter new ''Lights On'' time in 24-hr time format: ' app.handles.userRecStartFormat newline]};
            dlgtitle = 'Edit ''Lights On'' / ''Off tags''';
            definput = {char(app.handles.stageData.lightsOFF, app.handles.userRecStartFormat), char(app.handles.stageData.lightsON, app.handles.userRecStartFormat)};
            dims = [1 100];
            out = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(out)
                if ~isempty(out{1}) % edit lights off
                    try
                        app.handles.stageData.lightsOFF = datetime(out{1}, 'Inputformat', app.handles.userRecStartFormat);
                        if app.handles.stageData.lightsOFF < app.handles.stageData.recStart % just in case it is before the start of dataset
                            app.handles.stageData.lightsOFF = app.handles.stageData.recStart;
                        elseif app.handles.stageData.lightsOFF > app.handles.stageData.recStop % just in case it is after the end of dataset (which should not be the case)
                            app.handles.stageData.lightsOFF = app.handles.stageData.recStart; % just apply default, re; likely some sort of user error
                        end
                        if app.handles.stageData.lightsOFF > app.handles.stageData.lightsON % just in case it is after lights ON
                            app.handles.stageData.lightsOFF = app.handles.stageData.recStart; % just apply default, re; likely some sort of user error
                        end
                        if ~isempty(app.handles.stageData.events)
                            oldOffTag = find(ismember({app.handles.stageData.events.type},app.handles.userlightsOFFtag)); % check stageData structure for existing tag
                            if ~isempty(oldOffTag)
                                app.handles.stageData.events(oldOffTag).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            else
                                % update StageData event structure
                                app.handles.stageData.events(end+1).type = app.handles.userlightsOFFtag;
                                app.handles.stageData.events(end).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                                app.handles.stageData.events(end).duration = 1;
                                app.handles.stageData.events(end).channel = '';
                                % sort events by latency
                                EEGtemp = app.handles.EEG;
                                EEGtemp.event = app.handles.stageData.events;
                                EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                                EEGtemp = eeg_checkset(EEGtemp);
                                app.handles.stageData.events = EEGtemp.event;
                            end
                            clear oldOffTag
                        else
                            % update StageData event structure
                            app.handles.stageData.events(1).type = app.handles.userlightsOFFtag;
                            app.handles.stageData.events(1).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            app.handles.stageData.events(1).duration = 1;
                            app.handles.stageData.events(1).channel = '';
                        end
                        if ~isempty(app.handles.EEG.event)
                            oldOffTag = find(ismember({app.handles.EEG.event.type},app.handles.userlightsOFFtag)); % check EEGLAB structure for existing tag
                            if ~isempty(oldOffTag)
                                app.handles.EEG.event(oldOffTag).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            else
                                % update EEGLAB event structure
                                app.handles.EEG.event(end+1).type = app.handles.userlightsOFFtag;
                                app.handles.EEG.event(end).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                                app.handles.EEG.event(end).duration = 1;
                                app.handles.EEG.event(end).channel = '';
                                % sort events by latency
                                EEGtemp = app.handles.EEG;
                                EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                                EEGtemp = eeg_checkset(EEGtemp);
                                app.handles.EEG.event = EEGtemp.event;
                            end
                            clear oldOffTag
                        else
                            % update EEGLAB event structure
                            app.handles.EEG.event(1).type = app.handles.userlightsOFFtag;
                            app.handles.EEG.event(1).latency = (seconds(app.handles.stageData.lightsOFF - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            app.handles.EEG.event(1).duration = 1;
                            app.handles.EEG.event(1).channel = '';
                        end
                        saveON(app);
                    catch
                        choice = uiconfirm(app.figure1,'Incorrect date format entered','Input Error','Icon','error');
                        waitfor(choice);
                        return
                    end
                end
                if ~isempty(out{2}) % edit lights on
                    try
                        app.handles.stageData.lightsON = datetime(out{2}, 'Inputformat', app.handles.userRecStartFormat);
                        if app.handles.stageData.lightsON > app.handles.stageData.recStop % just in case it is past the end of dataset
                            app.handles.stageData.lightsON = app.handles.stageData.recStop;
                        elseif app.handles.stageData.lightsON < app.handles.stageData.recStart % just in case it is before the start of dataset (which should not be the case)
                            app.handles.stageData.lightsON = app.handles.stageData.recStop; % just apply default, re; likely some sort of user error
                        end
                        if app.handles.stageData.lightsON < app.handles.stageData.lightsOFF % just in case it is before lights OFF
                            app.handles.stageData.lightsON = app.handles.stageData.recStop; % just apply default, re; likely some sort of user error
                        end
                        if ~isempty(app.handles.stageData.events)
                            oldOnTag = find(ismember({app.handles.stageData.events.type},app.handles.userlightsONtag)); % check stageData structure for existing tag
                            if ~isempty(oldOnTag)
                                app.handles.stageData.events(oldOnTag).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            else
                                % update StageData event structure
                                app.handles.stageData.events(end+1).type = app.handles.userlightsONtag;
                                app.handles.stageData.events(end).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                                app.handles.stageData.events(end).duration = 1;
                                app.handles.stageData.events(end).channel = '';
                                % sort events by latency
                                EEGtemp = app.handles.EEG;
                                EEGtemp.event = app.handles.stageData.events;
                                EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                                EEGtemp = eeg_checkset(EEGtemp);
                                app.handles.stageData.events = EEGtemp.event;
                            end
                            clear oldOnTag
                        else
                            % update StageData event structure
                            app.handles.stageData.events(1).type = app.handles.userlightsONtag;
                            app.handles.stageData.events(1).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            app.handles.stageData.events(1).duration = 1;
                            app.handles.stageData.events(1).channel = '';
                        end
                        if ~isempty(app.handles.EEG.event)
                            oldOnTag = find(ismember({app.handles.EEG.event.type},app.handles.userlightsONtag),1); % check EEGLAB structure for existing tag
                            if ~isempty(oldOnTag)
                                app.handles.EEG.event(end).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            else
                                % update EEGLAB event structure
                                app.handles.EEG.event(end+1).type = app.handles.userlightsONtag;
                                app.handles.EEG.event(end).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                                app.handles.EEG.event(end).duration = 1;
                                app.handles.EEG.event(end).channel = '';
                                % sort events by latency
                                EEGtemp = app.handles.EEG;
                                EEGtemp = eeg_checkset(EEGtemp,'eventconsistency');
                                EEGtemp = eeg_checkset(EEGtemp);
                                app.handles.EEG.event = EEGtemp.event;
                            end
                            clear oldOnTag
                        else
                            % update EEGLAB event structure
                            app.handles.EEG.event(1).type = app.handles.userlightsONtag;
                            app.handles.EEG.event(1).latency = (seconds(app.handles.stageData.lightsON - app.handles.stageData.recStart)*app.handles.stageData.srate) + 1;
                            app.handles.EEG.event(1).duration = 1;
                            app.handles.EEG.event(1).channel = '';
                        end
                        saveON(app);
                    catch
                        choice = uiconfirm(app.figure1,'Incorrect date format entered','Input Error','Icon','error');
                        waitfor(choice);
                        return
                    end
                end
            end
            runPlot(app); % plot traces
            returnFocus2Figure(app);
        end

        % Menu selected function: editRecordingStart_m
        function editRecordingStart_m_Callback(app, event)
            validDate(app);
            origRecStart = app.handles.stageData.recStart;
            prompt = {['Current Recording Start Time: ' char(app.handles.stageData.recStart, app.handles.userRecStartFormat) newline newline...
                'Enter new ''Recording Start'' time in 24-hr time format: ' app.handles.userRecStartFormat newline]};
            dlgtitle = 'Edit Recording Start Time';
            definput = {char(app.handles.stageData.recStart, app.handles.userRecStartFormat)};
            dims = [1 100];
            out = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(out)
                app.handles.stageData.recStart = datetime(out{1}, 'Inputformat', app.handles.userRecStartFormat);
                app.handles.EEG.comments = char(datetime(out{1}, 'Inputformat', app.handles.userRecStartFormat),app.handles.userRecStartFormat);
                app.handles.stageData.recStop = app.handles.stageData.recStart + seconds(app.handles.EEG.pnts/app.handles.EEG.srate - 1);
                % add recording start marker at first data point
                if any(ismember({app.handles.EEG.event.type},char(origRecStart, app.handles.userRecStartFormat))) % make sure it exists in event structure
                    recStartIdx = find(ismember({app.handles.EEG.event.type},char(origRecStart, app.handles.userRecStartFormat)));
                    if isscalar(recStartIdx)
                        app.handles.EEG.event(recStartIdx).type = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                        app.handles.EEG.event(recStartIdx).latency = 1;
                        app.handles.EEG.event(recStartIdx).duration = 1;
                    else
                        choice = uiconfirm(app.figure1,'Multiple Recording Start markers found! Check dataset.','Edit Recording Start','Icon','error');
                        waitfor(choice);
                        return
                    end
                else
                    app.handles.EEG.event(end+1).type = char(app.handles.stageData.recStart, app.handles.userRecStartFormat);
                    app.handles.EEG.event(end).latency = 1;
                    app.handles.EEG.event(end).duration = 1;
                    app.handles.EEG.event(end).channel = '';
                    app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                    app.handles.EEG = eeg_checkset(app.handles.EEG);
                end
                % update lights on/off tags
                lightsOFFlatency = app.handles.stageData.lightsOFF - origRecStart;
                lightsONlatency = app.handles.stageData.lightsON - origRecStart;
                app.handles.stageData.lightsOFF = app.handles.stageData.recStart + lightsOFFlatency;
                app.handles.stageData.lightsON = app.handles.stageData.recStart + lightsONlatency;
                saveON(app); % because we changed the recstart with a manual entry
            end
            runPlot(app); % plot traces
            returnFocus2Figure(app);
        end

        % Menu selected function: openUserManual_m
        function openUserManual_m_Callback(app, event)
            open('User Manual.pdf')
        end

        % Menu selected function: checkUpdate_m
        function checkUpdate_m_Callback(app, event)
            % check for updates from git
            if ismac
                curDir = pwd;
                try
                    cd /usr/bin/
                    gitDir = exist('git', 'file');
                    cd(curDir)
                    if gitDir == 2 % git is located in default directory
                        [status, cmdOut] = cspsg_git('status');
                        disp(cmdOut)
                        if status == 1
                            choice = uiconfirm(app.figure1,'New version of ''Counting Sheep PSG'' available from GitHub.','Checking for updates','Icon','warning');
                            waitfor(choice);
                        elseif status == 0
                            choice = uiconfirm(app.figure1,'You have the most up to date version of Counting Sheep PSG.','Checking for updates','Icon','warning');
                            waitfor(choice);
                        elseif status == 128
                            choice = uiconfirm(app.figure1,'Your installation of ''Counting Sheep PSG'' is not a git repository. Unable to check for updates.','Checking for updates','Icon','warning');
                            waitfor(choice);
                        else
                            choice = uiconfirm(app.figure1,'Unable to check update status for unknown reason.','Checking for updates','Icon','warning');
                            waitfor(choice);
                        end
                    else
                        choice = uiconfirm(app.figure1,'git not installed in default directory: ''/usr/bin/''','Checking for updates','Checking for updates','Icon','warning');
                        waitfor(choice);
                    end
                catch
                    choice = uiconfirm(app.figure1,'git not installed in default directory: ''/usr/bin/''','Checking for updates','Checking for updates','Icon','warning');
                    waitfor(choice);
                end
            else
                choice = uiconfirm(app.figure1,'Check for updates not supported on PC or Linux systems.','Checking for updates','Icon','warning');
                waitfor(choice);
            end
            clear curDir gitDir status cmdOut
            pause(3);
            returnFocus2Figure(app);
        end

        % Menu selected function: registerCountingSheep_m
        function registerCountingSheep_m_Callback(app, event)
            % check for licence
            [OK] = licenceCheck(app);
            if OK == 0 % did not complete registration
                quitCountingSheep(app)
                return
            else
                choice = uiconfirm(app.figure1,'Thank you for registering Counting Sheep PSG!','Software Registration','Icon','success');
                waitfor(choice);
            end
        end

        % Callback function: hKeysInfo_m, help_t
        function hKeysInfo_m_Callback(app, event)
            % helpful info about how to use Counting Sheep PSG
            title = 'Keyboard Shortcuts';
            msg = sprintf([...
                '0 - Wake\n', ...
                '1 - NREM1 \n', ...
                '2 - NREM2\n', ...
                '3 - SWS\n', ...
                '4 - REM\n', ...
                '. - Unscored\n', ...
                'backspace/delete - delete selected event(s)\n\n', ...
                'Event marking:\n', ...
                '- Single click: single-point event\n', ...
                '- Click-and-drag: event with duration\n', ...
                '- Single-click existing event to select/deselect for delete/merge']);
            uiconfirm(app.figure1,msg,title);
        end

        % Menu selected function: aboutCountingSheep_m
        function aboutCountingSheep_m_Callback(app, event)
            % show about info
            title = 'About Counting Sheep PSG';
            msg = sprintf([...
                'EEGlab-compatible sleep scoring and event marking\n\n', ...
                'REQUIREMENTS:\n', ...
                'MATLAB version R2020b or later\n', ...
                'EEGlab 2023 or later (https://eeglab.org)\n\n', ...
                'A revision of sleepSMG. sleepSMG developed by:\n', ...
                'Stephanie Greer and Jared Saletin\n', ...
                'Walker Lab, UC Berkeley, 2011\n', ...
                'https://sleepsmg.sourceforge.net\n', ...
                'https://www.jaredsaletin.org/hume\n\n', ...
                'Stuart Fogel & Sleep Well\n', ...
                'University of Ottawa Sleep Research Laboratory\n', ...
                'School of Psychology, University of Ottawa, Canada\n', ...
                'https://www.uottawa-sleeplab.ca\n\n', ...
                'How to cite:\n\n', ...
                'L.B. Ray, D. Baena & S.M. Fogel (2024). “Counting sheep PSG”: EEGLAB-compatible open-source matlab software for signal processing, visualization, event marking and staging of polysomnographic data, Journal of Neuroscience Methods, 407, 110162. https://doi.org/10.1016/j.jneumeth.2024.110162.', ...
                ]);
            uiconfirm(app.figure1,msg,title)
        end

        % Button down function: traces
        function tracesButtonDown(app, event)
            % mark event when EEG trace is mouse clicked
            if isfield(app.handles, 'EEG')
                if strcmp(app.markMode_t.State, 'on')
                    point1 = app.traces.CurrentPoint; % button-down detected
                    initRect = rbbox; % position and size of rectangle [X Y length height]
                    point2 = app.traces.CurrentPoint; % button-up detected
                    point1 = point1(1,1:2); % extract x and y
                    point2 = point2(1,1:2); % extract x and y
                    if point2(1) < point1(1) % selection was right-to-left, reverse them
                        pt1temp = point1(1);
                        pt2temp = point2(1);
                        point1(1) = pt2temp;
                        point2(1) = pt1temp;
                        clear pt1temp pt2temp
                    end
                    % get information from clicked rectangle
                    origin = [point1(1), point1(2)]; % location of origin
                    offset = abs(point1-point2); % dimensions
                    finalRect = [origin offset]; % to plot rectangle
                    scale = app.handles.curScale;
                    yRange = app.traces.YLim;
                    channels = app.handles.userChannelLabels;
                    yMax = [yRange(1), (yRange(2)/(length(channels)+1):scale:length(channels)*scale) + scale/2];
                    yMax(end) = yRange(2);
                    yMid = (yRange(2)/(length(channels)+1):scale:length(channels)*scale);
                    chIdx = find(diff(finalRect(2) <= yMax));
                    finalRect(2) = yMid(chIdx) - scale/2;
                    finalRect(4) = yMid(chIdx) + scale/2;
                    chLabel = channels{chIdx};
                    label = app.markStr.Value;
                    if isempty(label); label = 'NoName'; end
                    label(~isstrprop(label,'alphanum')) = '_'; % replace non-alphanumeric characters with '_'
                    if finalRect(3) > 1 % event has a duration
                        app.handles.EEG.event(end+1).type = label;
                        app.handles.EEG.event(end).latency = fix(finalRect(1));
                        app.handles.EEG.event(end).duration = fix(finalRect(3));
                        if app.allChannelsMode.Value == 0
                            app.handles.EEG.event(end).channel = chLabel;
                        elseif app.allChannelsMode.Value == 1
                            app.handles.EEG.event(end).channel = '';
                        end
                        app.handles.EEG.event(end).urevent = NaN;
                    elseif finalRect(3) < 1 % it's a single point event
                        app.handles.EEG.event(end+1).type = label;
                        app.handles.EEG.event(end).latency = fix(finalRect(1));
                        app.handles.EEG.event(end).duration = 1;
                        if app.allChannelsMode.Value == 0
                            app.handles.EEG.event(end).channel = chLabel;
                        elseif app.allChannelsMode.Value == 1
                            app.handles.EEG.event(end).channel = '';
                        end
                        app.handles.EEG.event(end).urevent = NaN;
                    end
                    app.handles.EEG = eeg_checkset(app.handles.EEG, 'eventconsistency');
                    app.handles.EEG = eeg_checkset(app.handles.EEG, 'makeur');
                    app.handles.stageData.events = app.handles.EEG.event;
                    stageData = app.handles.stageData;
                    save(app.handles.stageFileIN, 'stageData');
                    curX = xlim(app.traces);
                    range = curX(1):curX(2);
                    plotSleepData(app, range);
                    saveON(app);
                end
            end
        end

        % Button down function: hypnogram
        function hypnogramButtonDown(app, event)
            % navigate dataset by clicking lower axis (hypnogram)
            if isfield(app.handles, 'EEG')
                if ~isempty(app.handles.EEG.data)
                    pt = event.Source.CurrentPoint;
                    winSize = str2double(app.handles.winIN);
                    srate = app.handles.EEG.srate;
                    ptX = fix(pt(1)*(60/winSize))*winSize*srate;
                    jumpto(app, ptX);
                    newX = [ptX+1, ptX + winSize*srate];
                    range = newX(1):newX(2);
                    plotSleepData(app, range);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Counting Sheep PSG';
            app.figure1.Icon = fullfile(pathToMLAPP, 'icons', 'sheep_t_image.png');
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @countingSheep_CloseFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress_Callback, true);
            app.figure1.Tag = 'figure1';
            app.mainMenu_m = uimenu(app.figure1);
            app.mainMenu_m.Text = 'Counting Sheep   ';
            app.mainMenu_m.Tag = 'mainMenu_m';
            app.preferences_m = uimenu(app.mainMenu_m);
            app.preferences_m.Text = 'Preferences';
            app.preferences_m.Tag = 'preferences_m';
            app.newMontage_m = uimenu(app.preferences_m);
            app.newMontage_m.MenuSelectedFcn = createCallbackFcn(app, @newMontage_m_Callback, true);
            app.newMontage_m.Text = 'Create New / Edit Montage';
            app.newMontage_m.Tag = 'newMontage_m';
            app.deleteMontage_m = uimenu(app.preferences_m);
            app.deleteMontage_m.MenuSelectedFcn = createCallbackFcn(app, @deleteMontage_m_Callback, true);
            app.deleteMontage_m.Text = 'Delete Montage';
            app.deleteMontage_m.Tag = 'deleteMontage_m';
            app.ImportMenu_m = uimenu(app.mainMenu_m);
            app.ImportMenu_m.Text = 'Import';
            app.ImportMenu_m.Tag = 'importMenu_m';
            app.importEDFeeglab_m = uimenu(app.ImportMenu_m);
            app.importEDFeeglab_m.MenuSelectedFcn = createCallbackFcn(app, @importEDF_m_Callback, true);
            app.importEDFeeglab_m.Text = 'Import EDF: EEGlab (default)';
            app.importEDFeeglab_m.Tag = 'importEDFeeglab_m';
            app.importEDFmatlab_m = uimenu(app.ImportMenu_m);
            app.importEDFmatlab_m.MenuSelectedFcn = createCallbackFcn(app, @importEDF_m_Callback, true);
            app.importEDFmatlab_m.Text = 'Import EDF: Matlab (rigorous + slow)';
            app.importEDFmatlab_m.Tag = 'importEDFmatlab_m';
            app.importBrainProducts_m = uimenu(app.ImportMenu_m);
            app.importBrainProducts_m.MenuSelectedFcn = createCallbackFcn(app, @importBrainProducts_m_Callback, true);
            app.importBrainProducts_m.Text = 'Import Brain Products';
            app.importBrainProducts_m.Tag = 'importBrainProducts_m';
            app.ImportSleepStagesMat_m = uimenu(app.ImportMenu_m);
            app.ImportSleepStagesMat_m.MenuSelectedFcn = createCallbackFcn(app, @importSleepStagesMat_m_Callback, true);
            app.ImportSleepStagesMat_m.Separator = 'on';
            app.ImportSleepStagesMat_m.Text = 'Import Sleep Stages (*.mat)';
            app.ImportSleepStagesMat_m.Tag = 'ImportSleepStagesMat_m';
            app.ImportEventsMat_m = uimenu(app.ImportMenu_m);
            app.ImportEventsMat_m.MenuSelectedFcn = createCallbackFcn(app, @importEventsMat_m_Callback, true);
            app.ImportEventsMat_m.Enable = 'off';
            app.ImportEventsMat_m.Text = 'Import Events (*.mat)';
            app.loadDataset_m = uimenu(app.mainMenu_m);
            app.loadDataset_m.MenuSelectedFcn = createCallbackFcn(app, @loadDataset_m_Callback, true);
            app.loadDataset_m.Separator = 'on';
            app.loadDataset_m.Accelerator = 'O';
            app.loadDataset_m.Text = 'Load Dataset';
            app.loadDataset_m.Tag = 'loadDataset_m';
            app.saveDataset_m = uimenu(app.mainMenu_m);
            app.saveDataset_m.MenuSelectedFcn = createCallbackFcn(app, @saveDataset_m_Callback, true);
            app.saveDataset_m.Accelerator = 'S';
            app.saveDataset_m.Text = 'Save Dataset';
            app.saveDataset_m.Tag = 'saveDataset_m';
            app.saveDatasetAs_m = uimenu(app.mainMenu_m);
            app.saveDatasetAs_m.MenuSelectedFcn = createCallbackFcn(app, @saveDatasetAs_m_Callback, true);
            app.saveDatasetAs_m.Text = 'Save Dataset As...';
            app.saveDatasetAs_m.Tag = 'saveDatasetAs_m';
            app.generateSleepReport_m = uimenu(app.mainMenu_m);
            app.generateSleepReport_m.MenuSelectedFcn = createCallbackFcn(app, @generateSleepReport_m_Callback, true);
            app.generateSleepReport_m.Separator = 'on';
            app.generateSleepReport_m.Text = 'Generate Sleep Report';
            app.generateSleepReport_m.Tag = 'generateSleepReport_m';
            app.generateEventReport_m = uimenu(app.mainMenu_m);
            app.generateEventReport_m.MenuSelectedFcn = createCallbackFcn(app, @generateEventReport_m_Callback, true);
            app.generateEventReport_m.Text = 'Generate Event Report';
            app.generateEventReport_m.Tag = 'generateEventReport_m';
            app.generateHypnogram_m = uimenu(app.mainMenu_m);
            app.generateHypnogram_m.MenuSelectedFcn = createCallbackFcn(app, @generateHypnogram_m_Callback, true);
            app.generateHypnogram_m.Text = 'Generate Hypnogram';
            app.generateHypnogram_m.Tag = 'generateHypnogram_m';
            app.clearDataset_m = uimenu(app.mainMenu_m);
            app.clearDataset_m.MenuSelectedFcn = createCallbackFcn(app, @clearDataset_m_Callback, true);
            app.clearDataset_m.Separator = 'on';
            app.clearDataset_m.Accelerator = 'W';
            app.clearDataset_m.Text = 'Clear Dataset';
            app.clearDataset_m.Tag = 'clearDatasetMenu_m';
            app.quit_m = uimenu(app.mainMenu_m);
            app.quit_m.MenuSelectedFcn = createCallbackFcn(app, @countingSheep_CloseFcn, true);
            app.quit_m.Accelerator = 'Q';
            app.quit_m.Text = 'Quit Counting Sheep';
            app.quit_m.Tag = 'quit_m';
            app.chan_m = uimenu(app.figure1);
            app.chan_m.Text = 'Channels  ';
            app.chan_m.Tag = 'chan_m';
            app.setScale_m = uimenu(app.chan_m);
            app.setScale_m.MenuSelectedFcn = createCallbackFcn(app, @setScale_m_Callback, true);
            app.setScale_m.Text = 'Show/Hide Scale Lines';
            app.setScale_m.Tag = 'setScale_m';
            app.markChannelAsBadData_m = uimenu(app.chan_m);
            app.markChannelAsBadData_m.MenuSelectedFcn = createCallbackFcn(app, @markChannelAsBadData_m_Callback, true);
            app.markChannelAsBadData_m.Separator = 'on';
            app.markChannelAsBadData_m.Text = 'Mark Channel as ''Bad Data''';
            app.markChannelAsBadData_m.Tag = 'markChannelAsBadData_m';
            app.interpolateBadChannel_m = uimenu(app.chan_m);
            app.interpolateBadChannel_m.MenuSelectedFcn = createCallbackFcn(app, @interpolateBadChannel_m_Callback, true);
            app.interpolateBadChannel_m.Text = 'Interpolate Bad Channel';
            app.interpolateBadChannel_m.Tag = 'interpolateBadChannel_m';
            app.downsampleChannels_m = uimenu(app.chan_m);
            app.downsampleChannels_m.MenuSelectedFcn = createCallbackFcn(app, @downsampleChannels_m_Callback, true);
            app.downsampleChannels_m.Separator = 'on';
            app.downsampleChannels_m.Text = 'Downsample Channels';
            app.downsampleChannels_m.Tag = 'downsampleChannels_m';
            app.rereferenceChannels_m = uimenu(app.chan_m);
            app.rereferenceChannels_m.MenuSelectedFcn = createCallbackFcn(app, @rereferenceChannels_m_Callback, true);
            app.rereferenceChannels_m.Text = 'Re-reference Channels';
            app.rereferenceChannels_m.Tag = 'rereferenceChannels_m';
            app.filterChannels_m = uimenu(app.chan_m);
            app.filterChannels_m.MenuSelectedFcn = createCallbackFcn(app, @filterChannels_m_Callback, true);
            app.filterChannels_m.Text = 'Filter Channels';
            app.filterChannels_m.Tag = 'filterChannels_m';
            app.editChannels_m = uimenu(app.chan_m);
            app.editChannels_m.MenuSelectedFcn = createCallbackFcn(app, @editChannels_m_Callback, true);
            app.editChannels_m.Text = 'Edit Channels';
            app.editChannels_m.Tag = 'editChannels_m';
            app.ASR_m = uimenu(app.chan_m);
            app.ASR_m.MenuSelectedFcn = createCallbackFcn(app, @ASR_m_Callback, true);
            app.ASR_m.Separator = 'on';
            app.ASR_m.Text = 'Artifact Subspace Reconstrcution';
            app.ICA_m = uimenu(app.chan_m);
            app.ICA_m.Text = 'Independent Component Analysis';
            app.runICA_m = uimenu(app.ICA_m);
            app.runICA_m.MenuSelectedFcn = createCallbackFcn(app, @runICA_m_Callback, true);
            app.runICA_m.Text = 'Run ICA';
            app.runICA_m.Tag = 'runICA_m';
            app.displayICA_m = uimenu(app.ICA_m);
            app.displayICA_m.MenuSelectedFcn = createCallbackFcn(app, @displayICA_m_Callback, true);
            app.displayICA_m.Text = 'Show/Hide ICA Components';
            app.displayICA_m.Tag = 'displayICA_m';
            app.deleteICA_m = uimenu(app.ICA_m);
            app.deleteICA_m.MenuSelectedFcn = createCallbackFcn(app, @deleteICA_m_Callback, true);
            app.deleteICA_m.Text = 'Remove Selected Components';
            app.deleteICA_m.Tag = 'deleteICA_m';
            app.PowerSpectralAnalysis_m = uimenu(app.chan_m);
            app.PowerSpectralAnalysis_m.Text = 'Power Spectral Analysis';
            app.PowerSpectralAnalysis_m.Tag = 'PowerSpectralAnalysis_m';
            app.FFT_m = uimenu(app.PowerSpectralAnalysis_m);
            app.FFT_m.MenuSelectedFcn = createCallbackFcn(app, @FFT_m_Callback, true);
            app.FFT_m.Text = 'Run Power Spectral Density (FFT - Welch)';
            app.FFT_m.Tag = 'FFT_m';
            app.Plot_FFT_m = uimenu(app.PowerSpectralAnalysis_m);
            app.Plot_FFT_m.MenuSelectedFcn = createCallbackFcn(app, @Plot_FFT_m_Callback, true);
            app.Plot_FFT_m.Text = 'Plot Power Spectrum Density';
            app.Plot_FFT_m.Tag = 'Plot_FFT_m';
            app.Plot_Spectrogram_m = uimenu(app.PowerSpectralAnalysis_m);
            app.Plot_Spectrogram_m.MenuSelectedFcn = createCallbackFcn(app, @Plot_Spectrogram_m_Callback, true);
            app.Plot_Spectrogram_m.Text = 'Plot Time-Frequency Spectrogram';
            app.Plot_Spectrogram_m.Tag = 'Plot_Spectrogram_m';
            app.Plot_Topo_m = uimenu(app.PowerSpectralAnalysis_m);
            app.Plot_Topo_m.MenuSelectedFcn = createCallbackFcn(app, @Plot_Topo_m_Callback, true);
            app.Plot_Topo_m.Text = 'Plot Topography';
            app.Plot_Topo_m.Tag = 'Plot_Topo_m';
            app.hideChan_m = uimenu(app.chan_m);
            app.hideChan_m.MenuSelectedFcn = createCallbackFcn(app, @hideChan_m_Callback, true);
            app.hideChan_m.Separator = 'on';
            app.hideChan_m.Text = 'Hide Channels';
            app.hideChan_m.Tag = 'hideChan_m';
            app.showChan_m = uimenu(app.chan_m);
            app.showChan_m.MenuSelectedFcn = createCallbackFcn(app, @showChan_m_Callback, true);
            app.showChan_m.Text = 'Show Hidden Channels';
            app.showChan_m.Tag = 'showChan_m';
            app.rescaleSelectedChannels_m = uimenu(app.chan_m);
            app.rescaleSelectedChannels_m.MenuSelectedFcn = createCallbackFcn(app, @rescaleSelectedChannels_m_Callback, true);
            app.rescaleSelectedChannels_m.Separator = 'on';
            app.rescaleSelectedChannels_m.Text = 'Rescale Selected Channels';
            app.rescaleSelectedChannels_m.Tag = 'rescaleSelectedChannels_m';
            app.mark_m = uimenu(app.figure1);
            app.mark_m.Text = 'Events  ';
            app.mark_m.Tag = 'mark_m';
            app.detectMovement_m = uimenu(app.mark_m);
            app.detectMovement_m.MenuSelectedFcn = createCallbackFcn(app, @detectMovement_m_Callback, true);
            app.detectMovement_m.Text = 'Detect Movement';
            app.detectMovement_m.Tag = 'detectMovement_m';
            app.detectSpindles_m = uimenu(app.mark_m);
            app.detectSpindles_m.MenuSelectedFcn = createCallbackFcn(app, @detectSpindles_m_Callback, true);
            app.detectSpindles_m.Text = 'Detect Spindles';
            app.detectSpindles_m.Tag = 'detectSpindles_m';
            app.detectSlowWaves_m = uimenu(app.mark_m);
            app.detectSlowWaves_m.MenuSelectedFcn = createCallbackFcn(app, @detectSlowWaves_m_Callback, true);
            app.detectSlowWaves_m.Text = 'Detect Slow Waves';
            app.detectSlowWaves_m.Tag = 'detectSlowWaves_m';
            app.detectEyeMovements_m = uimenu(app.mark_m);
            app.detectEyeMovements_m.MenuSelectedFcn = createCallbackFcn(app, @detectEyeMovements_m_Callback, true);
            app.detectEyeMovements_m.Text = 'Detect Eye Movements (beta)';
            app.detectEyeMovements_m.Tag = 'detectEyeMovements_m';
            app.OnOffEventMark_m = uimenu(app.mark_m);
            app.OnOffEventMark_m.MenuSelectedFcn = createCallbackFcn(app, @OnOffEventMark_m_Callback, true);
            app.OnOffEventMark_m.Separator = 'on';
            app.OnOffEventMark_m.Text = 'Turn On/Off Event Marking Mode';
            app.clearEvents_m = uimenu(app.mark_m);
            app.clearEvents_m.MenuSelectedFcn = createCallbackFcn(app, @clearEvents_m_Callback, true);
            app.clearEvents_m.Separator = 'on';
            app.clearEvents_m.Text = 'Delete Events';
            app.clearEvents_m.Tag = 'clearEvents_m';
            app.hideEvents_m = uimenu(app.mark_m);
            app.hideEvents_m.MenuSelectedFcn = createCallbackFcn(app, @hideEvents_m_Callback, true);
            app.hideEvents_m.Text = 'Hide Events';
            app.hideEvents_m.Tag = 'hideEvents_m';
            app.showEvents_m = uimenu(app.mark_m);
            app.showEvents_m.MenuSelectedFcn = createCallbackFcn(app, @showEvents_m_Callback, true);
            app.showEvents_m.Text = 'Show Hidden Events';
            app.showEvents_m.Tag = 'showEvents_m';
            app.editLightsTags_m = uimenu(app.mark_m);
            app.editLightsTags_m.MenuSelectedFcn = createCallbackFcn(app, @editLightsTags_m_Callback, true);
            app.editLightsTags_m.Separator = 'on';
            app.editLightsTags_m.Text = 'Edit ''Lights On'' / ''Lights Off'' tags';
            app.editLightsTags_m.Tag = 'editLightsTags_m';
            app.editRecordingStart_m = uimenu(app.mark_m);
            app.editRecordingStart_m.MenuSelectedFcn = createCallbackFcn(app, @editRecordingStart_m_Callback, true);
            app.editRecordingStart_m.Text = 'Edit ''Recording Start''';
            app.editRecordingStart_m.Tag = 'editRecordingStart_m';
            app.info_m = uimenu(app.figure1);
            app.info_m.Text = 'Help  ';
            app.info_m.Tag = 'info_m';
            app.openUserManual_m = uimenu(app.info_m);
            app.openUserManual_m.MenuSelectedFcn = createCallbackFcn(app, @openUserManual_m_Callback, true);
            app.openUserManual_m.Text = 'Open User Manual (pdf)';
            app.openUserManual_m.Tag = 'openUserManual_m';
            app.checkUpdate_m = uimenu(app.info_m);
            app.checkUpdate_m.MenuSelectedFcn = createCallbackFcn(app, @checkUpdate_m_Callback, true);
            app.checkUpdate_m.Text = 'Check for Updates from Git (Mac Only)';
            app.checkUpdate_m.Tag = 'checkUpdate_m';
            app.registerCountingSheep_m = uimenu(app.info_m);
            app.registerCountingSheep_m.MenuSelectedFcn = createCallbackFcn(app, @registerCountingSheep_m_Callback, true);
            app.registerCountingSheep_m.Text = 'Register Counting Sheep PSG';
            app.registerCountingSheep_m.Tag = 'registerCountingSheep_m';
            app.hKeysInfo_m = uimenu(app.info_m);
            app.hKeysInfo_m.MenuSelectedFcn = createCallbackFcn(app, @hKeysInfo_m_Callback, true);
            app.hKeysInfo_m.Text = 'Keyboard Shortcuts';
            app.hKeysInfo_m.Tag = 'hKeysInfo_m';
            app.aboutCountingSheep_m = uimenu(app.info_m);
            app.aboutCountingSheep_m.MenuSelectedFcn = createCallbackFcn(app, @aboutCountingSheep_m_Callback, true);
            app.aboutCountingSheep_m.Text = 'About Counting Sheep PSG';
            app.aboutCountingSheep_m.Tag = 'aboutCountingSheep_m';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.open_t = uipushtool(app.uitoolbar1);
            app.open_t.Tag = 'open_t';
            app.open_t.Tooltip = {'Open Dataset'};
            app.open_t.ClickedCallback = createCallbackFcn(app, @open_t_Callback, true);
            app.open_t.Icon = fullfile(pathToMLAPP, 'icons', 'open_t_image.png');
            app.save_t = uipushtool(app.uitoolbar1);
            app.save_t.Tag = 'save_t';
            app.save_t.Tooltip = {'Save Dataset'};
            app.save_t.ClickedCallback = createCallbackFcn(app, @save_t_Callback, true);
            app.save_t.Icon = fullfile(pathToMLAPP, 'icons', 'save_t_image.png');
            app.scaleSmall_t = uipushtool(app.uitoolbar1);
            app.scaleSmall_t.Tag = 'scaleSmall_t';
            app.scaleSmall_t.Tooltip = 'Scale smaller (zoom out)';
            app.scaleSmall_t.ClickedCallback = createCallbackFcn(app, @scaleSmall_t_Callback, true);
            app.scaleSmall_t.Icon = fullfile(pathToMLAPP, '', 'icons', 'scaleSmall_t_image.png');
            app.scaleSmall_t.Separator = 'on';
            app.scaleBigger_t = uipushtool(app.uitoolbar1);
            app.scaleBigger_t.Tag = 'scaleBigger_t';
            app.scaleBigger_t.Tooltip = 'Scale bigger (zoom in)';
            app.scaleBigger_t.ClickedCallback = createCallbackFcn(app, @scaleBigger_t_Callback, true);
            app.scaleBigger_t.Icon = fullfile(pathToMLAPP, '', 'icons', 'scaleBigger_t_image.png');
            app.resetScale_t = uipushtool(app.uitoolbar1);
            app.resetScale_t.Tag = 'resetScale';
            app.resetScale_t.Tooltip = 'Reset wave scale to 100';
            app.resetScale_t.ClickedCallback = createCallbackFcn(app, @resetScale_t_Callback, true);
            app.resetScale_t.Icon = fullfile(pathToMLAPP, '', 'icons', 'resetScale_t_image.png');
            app.playPause_t = uitoggletool(app.uitoolbar1);
            app.playPause_t.Tag = 'playPause_t';
            app.playPause_t.Tooltip = {'Play / Pause'};
            app.playPause_t.ClickedCallback = createCallbackFcn(app, @playPause_t_Callback, true);
            app.playPause_t.Icon = fullfile(pathToMLAPP, 'icons', 'playPause_t_image.png');
            app.playPause_t.Separator = 'on';
            app.lightsOff_t = uipushtool(app.uitoolbar1);
            app.lightsOff_t.Tag = 'lightsOff_t';
            app.lightsOff_t.Tooltip = 'Jump to lights off';
            app.lightsOff_t.ClickedCallback = createCallbackFcn(app, @lightsOff_t_Callback, true);
            app.lightsOff_t.Icon = fullfile(pathToMLAPP, '', 'icons', 'lightsOff_t_image.png');
            app.lightsOff_t.Separator = 'on';
            app.lightsOn_t = uipushtool(app.uitoolbar1);
            app.lightsOn_t.Tag = 'lightsOn_t';
            app.lightsOn_t.Tooltip = {'Jump to lights on'};
            app.lightsOn_t.ClickedCallback = createCallbackFcn(app, @lightsOn_t_Callback, true);
            app.lightsOn_t.Icon = fullfile(pathToMLAPP, '', 'icons', 'lightsOn_t_image.png');
            app.jumpTo_t = uipushtool(app.uitoolbar1);
            app.jumpTo_t.Tag = 'jumpTo';
            app.jumpTo_t.Tooltip = 'Jump to an epoch';
            app.jumpTo_t.ClickedCallback = createCallbackFcn(app, @jumpTo_t_Callback, true);
            app.jumpTo_t.Icon = fullfile(pathToMLAPP, '', 'icons', 'jumpTo_t_image.png');
            app.markMode_t = uitoggletool(app.uitoolbar1);
            app.markMode_t.Tooltip = {'Mark events mode On/Off'};
            app.markMode_t.Icon = fullfile(pathToMLAPP, 'icons', 'mark_t_image.png');
            app.markMode_t.Separator = 'on';
            app.markMode_t.OffCallback = createCallbackFcn(app, @markMode_Off_t_Callback, true);
            app.markMode_t.OnCallback = createCallbackFcn(app, @markMode_On_t_Callback, true);
            app.ICA_t = uitoggletool(app.uitoolbar1);
            app.ICA_t.Tag = 'ICA_t';
            app.ICA_t.Tooltip = {'View ICA On/Off'};
            app.ICA_t.Icon = fullfile(pathToMLAPP, 'icons', 'ica_t_image.png');
            app.ICA_t.Separator = 'on';
            app.ICA_t.OffCallback = createCallbackFcn(app, @ICA_Off_t_Callback, true);
            app.ICA_t.OnCallback = createCallbackFcn(app, @ICA_On_t_Callback, true);
            app.help_t = uipushtool(app.uitoolbar1);
            app.help_t.Tag = 'help_t';
            app.help_t.Tooltip = {'Keyboard shortcuts'};
            app.help_t.ClickedCallback = createCallbackFcn(app, @hKeysInfo_m_Callback, true);
            app.help_t.Icon = fullfile(pathToMLAPP, 'icons', 'sheep_t_image.png');
            app.help_t.Separator = 'on';
            app.hypnogram = uiaxes(app.figure1);
            app.hypnogram.XLim = [0 10];
            app.hypnogram.YLim = [0 5];
            app.hypnogram.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.hypnogram.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.hypnogram.YTick = [0 1 2 3 4 5];
            app.hypnogram.YTickLabel = {'None'; 'SWS'; 'N2'; 'N1'; 'REM'; 'Wake'};
            app.hypnogram.ButtonDownFcn = createCallbackFcn(app, @hypnogramButtonDown, true);
            app.hypnogram.Tag = 'hypnogram';
            app.traces = uiaxes(app.figure1);
            app.traces.XLim = [0 30];
            app.traces.YLim = [0 10];
            app.traces.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.traces.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'};
            app.traces.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.traces.YTickLabel = {''; 'CH001'; 'CH002'; 'CH003'; 'CH004'; 'CH005'; 'CH006'; 'CH007'; 'CH008'; 'CH009'; 'CH010'};
            app.traces.ButtonDownFcn = createCallbackFcn(app, @tracesButtonDown, true);
            app.traces.Tag = 'traces';
            app.Panel = uipanel(app.figure1);
            app.dataset_S = uilabel(app.Panel);
            app.dataset_S.Tag = 'dataset_S';
            app.dataset_S.Text = 'No Dataset Loaded';
            app.time_S = uilabel(app.Panel);
            app.time_S.Tag = 'time_S';
            app.time_S.Text = 'Time: 00:00:00 Epoch: 0';
            app.arrowRight_B = uibutton(app.figure1, 'push');
            app.arrowRight_B.ButtonPushedFcn = createCallbackFcn(app, @arrowRight_B_Callback, true);
            app.arrowRight_B.Tag = 'arrowRight_B';
            app.arrowRight_B.Text = '>>';
            app.REM_B = uibutton(app.figure1, 'push');
            app.REM_B.ButtonPushedFcn = createCallbackFcn(app, @REM_B_Callback, true);
            app.REM_B.Tag = 'REM_B';
            app.REM_B.Text = 'REM';
            app.SWS_B = uibutton(app.figure1, 'push');
            app.SWS_B.ButtonPushedFcn = createCallbackFcn(app, @SWS_B_Callback, true);
            app.SWS_B.Tag = 'SWS_B';
            app.SWS_B.Text = 'SWS';
            app.NREM2_B = uibutton(app.figure1, 'push');
            app.NREM2_B.ButtonPushedFcn = createCallbackFcn(app, @NREM2_B_Callback, true);
            app.NREM2_B.Tag = 'NREM2_B';
            app.NREM2_B.Text = 'N2';
            app.NREM1_B = uibutton(app.figure1, 'push');
            app.NREM1_B.ButtonPushedFcn = createCallbackFcn(app, @NREM1_B_Callback, true);
            app.NREM1_B.Tag = 'NREM1_B';
            app.NREM1_B.Text = 'N1';
            app.Wake_B = uibutton(app.figure1, 'push');
            app.Wake_B.ButtonPushedFcn = createCallbackFcn(app, @Wake_B_Callback, true);
            app.Wake_B.Tag = 'Wake_B';
            app.Wake_B.Text = 'Wake';
            app.arrowLeft_B = uibutton(app.figure1, 'push');
            app.arrowLeft_B.ButtonPushedFcn = createCallbackFcn(app, @arrowLeft_B_Callback, true);
            app.arrowLeft_B.Tag = 'arrowLeft_B';
            app.arrowLeft_B.Text = '<<';
            app.SleepScoringToolsLabel = uilabel(app.figure1);
            app.SleepScoringToolsLabel.Text = 'Sleep Scoring Tools';
            app.allChannelsMode = uicheckbox(app.figure1);
            app.allChannelsMode.ValueChangedFcn = createCallbackFcn(app, @allChannelsMode_B_Callback, true);
            app.allChannelsMode.Tag = 'allChannelsMode';
            app.allChannelsMode.Text = 'All Channels';
            app.EventMarkingToolsLabel = uilabel(app.figure1);
            app.EventMarkingToolsLabel.Text = 'Event Marking Tools';
            app.scroll = uibutton(app.figure1, 'push');
            app.scroll.ButtonPushedFcn = createCallbackFcn(app, @scroll_B_Callback, true);
            app.scroll.Tag = 'scroll';
            app.scroll.Enable = 'off';
            app.scroll.Text = '>';
            app.mergeMark = uibutton(app.figure1, 'push');
            app.mergeMark.ButtonPushedFcn = createCallbackFcn(app, @mergeMark_B_Callback, true);
            app.mergeMark.Tag = 'mergeMark';
            app.mergeMark.Text = 'Merge';
            app.delMark = uibutton(app.figure1, 'push');
            app.delMark.ButtonPushedFcn = createCallbackFcn(app, @delMark_B_Callback, true);
            app.delMark.Tag = 'delMark';
            app.delMark.Text = 'Delete';
            app.markStr = uieditfield(app.figure1, 'text');
            app.markStr.InputType = 'alphanumerics';
            app.markStr.Tag = 'markStr';
            app.markStr.Enable = 'off';
            app.markStr.Placeholder = 'new event label';
            app.plotSleepIN = uidropdown(app.figure1);
            app.plotSleepIN.Items = {'sleep_montage_default'};
            app.plotSleepIN.ValueChangedFcn = createCallbackFcn(app, @plotSleepIN_Callback, true);
            app.plotSleepIN.Tag = 'plotSleepIN';
            app.plotSleepIN.Value = 'sleep_montage_default';
            app.DisplayMontageLabel = uilabel(app.figure1);
            app.DisplayMontageLabel.Text = 'Display Montage';
            app.numEpochs = uispinner(app.figure1);
            app.numEpochs.Limits = [1 10];
            app.numEpochs.ValueChangedFcn = createCallbackFcn(app, @numEpochs_B_Callback, true);
            app.numEpochs.Value = 1;
            app.EpochsSpinnerLabel = uilabel(app.figure1);
            app.EpochsSpinnerLabel.Text = '# Epochs';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = countingSheepPSG(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)countingSheep_OpenFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end