classdef fileSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fileSelectFigure       matlab.ui.Figure
        currentSelection       matlab.ui.control.Label
        fileListLabel          matlab.ui.control.Label
        folderListLabel        matlab.ui.control.Label
        dirList                matlab.ui.control.ListBox
        fileList               matlab.ui.control.ListBox
        fileTypeDropDown       matlab.ui.control.DropDown
        FileTypeDropDownLabel  matlab.ui.control.Label
        selectButton           matlab.ui.control.Button
        cancelButton           matlab.ui.control.Button
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    % fileSelect: App to select a single file of a certain type
    %
    % Usage:
    %   fileSelect                      % default: .mat files only
    %   fileSelect('txt')               % files of defined type
    %   fileSelect({'txt'})             % files of defined type
    %   fileSelect({'txt','csv','mat'}) % for multiple file types
    %
    % Created:
    %   April 17, 2023
    %
    % Copyright (C) Stuart Fogel & Sleep Well, 2026.
    %
    % This file is part of 'Counting Sheep PSG'.
    %
    % See https://github.com/stuartfogel/CountingSheepPSG for details.
    %
    % Redistribution and use in source and binary forms, with or without
    % modification, are permitted provided that the following conditions are
    % met:
    %
    % 1. Redistributions of source code must retain the above author, licence,
    % copyright notice, this list of conditions, and the following disclaimer.
    %
    % 2. Redistributions in binary form must reproduce the above author,
    % licence, copyright notice, this list of conditions, and the following
    % disclaimer in the documentation and/or other materials provided with the
    % distribution.
    %
    % 3. Neither the name of the copyright holder nor the names of its
    % contributors may be used to endorse or promote products derived from this
    % software without specific prior written permission.
    %
    % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS
    % "AS IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED
    % TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A
    % PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT HOLDER
    % OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL,
    % EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT LIMITED TO,
    % PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR
    % PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF
    % LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING
    % NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS
    % SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.
    %
    % THIS SOFTWARE IS INTENDED FOR RESEARCH PURPOSES ONLY. ANY COMMERCIAL
    % USE OR MEDICAL USE OF THIS SOFTWARE AND SOURCE CODE IS STRICTLY
    % PROHIBITED.
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        filepath % path for current directory in list
        filename % name of file selected
        filetype % optional input argument for file type, e.g., fileSelect('csv')
        % for multiple file types, e.g., fileSelect({'csv','mat'})
        % do not include as input argument for default: .mat only
        multiselect % optional input argument to enable multiselect, e.g., fileSelect('csv','multiselect','on')
        % default = 'off'
    end

    properties (Access = private)
        callingApp % properties of callingApp
    end

    methods (Access = private)

        function getSubDirectories(app, filepath)
            files = dir(filepath);
            dirFlags = [files.isdir];
            subDirs = files(dirFlags);
            listOfDirs = {subDirs(3:end).name};
            delHidden = startsWith(listOfDirs, '.');
            listOfDirs(delHidden) = [];
            [~,idx] = sort(upper(listOfDirs));
            listOfDirs = listOfDirs(idx);
            app.dirList.Items = [{'..'} listOfDirs];
            focus(app.fileList);
        end

        function getFileList(app, filepath)
            files = dir(filepath);
            dirFlags = [files.isdir];
            listOfFiles = files(~dirFlags);
            listOfFiles = {listOfFiles(:).name};
            delHidden = startsWith(listOfFiles, '.');
            delType = ~endsWith(listOfFiles, app.filetype);
            delFiles = delHidden | delType;
            listOfFiles(delFiles) = [];
            [~,idx] = sort(upper(listOfFiles));
            listOfFiles = listOfFiles(idx);
            app.fileList.Items = listOfFiles;
            app.currentSelection.Text = filepath;
            focus(app.fileList);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, filepath, filename, filetype, multiselect, enable)
            % app.fileSelectFigure.Theme = 'light';
            if nargin < 2
                app.callingApp = [];
            else
                app.callingApp = callingApp;
            end
            if exist('filepath','var') % process filepath input argument
                if ischar(filepath)
                    if ~isempty(filepath)
                        app.filepath = filepath;
                    else
                        app.filepath = pwd;
                    end
                else
                    uialert(app.fileSaveFileAsFigure,'Input must be a string.','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                end
            else
                app.filepath = pwd;
            end
            if exist('filename','var') % process filename input argument
                if ischar(filename)
                    if ~isempty(filename)
                        app.filename = filename;
                    else
                        app.filename = '';
                    end
                else
                    uialert(app.fileSaveFileAsFigure,'Input must be a string.','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                end
            else
                app.filename = '';
            end
            if exist('filetype','var') % process filetype input argument
                if iscell(filetype)
                    app.filetype = ['.' filetype{1}];
                    filetypelist = strcat('.', filetype);
                    app.fileTypeDropDown.Items = filetypelist;
                    if length(filetypelist) == 1
                        app.fileTypeDropDown.Enable = "off";
                    end
                elseif ischar(filetype)
                    app.filetype = ['.' filetype];
                    app.fileTypeDropDown.Items = {app.filetype};
                    app.fileTypeDropDown.Enable = "off";
                else
                    uialert(app.fileSelectFigure,'Must be string or cell array of strings.','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                end
                if exist('filetype','var') && exist('multiselect','var') && exist('enable','var') % process multiselect input argument
                    if strcmp(multiselect,'multiselect') && strcmp(enable,'on')
                        app.fileList.Multiselect = 'on';
                        app.multiselect = 'on';
                    elseif strcmp(multiselect,'multiselect') && strcmp(enable,'off')
                        app.fileList.Multiselect = 'off';
                        app.multiselect = 'off';
                    else
                        uialert(app.fileSelectFigure,'To enable multiselect use: ''multiselect'',''on''','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                        return
                    end
                elseif exist('multiselect','var') && ~exist('enable','var')
                    uialert(app.fileSelectFigure,'Missing value pair to enable multiselct','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                else
                    app.multiselect = 'off';
                end
            else
                app.filetype = '.mat';
                app.fileTypeDropDown.Items = {app.filetype};
                app.fileTypeDropDown.Enable = "off";
            end
            app.fileTypeDropDown.Value = app.filetype;
            getSubDirectories(app, app.filepath);
            getFileList(app, app.filepath);
        end

        % Close request function: fileSelectFigure
        function closeFcn(app, event)
            app.filename = [];
            app.filepath = [];
            f = which('getFileSelectData');
            if ~isempty(f)
                getFileSelectData(app.callingApp,app.filepath,app.filename); % send data back to calling app
            end
            delete(app);
        end

        % Double-clicked callback: dirList
        function dirListDoubleClicked(app, event)
            if strcmp(event.Source.Value,'..') && ~isempty(app.filepath)
                pathParts = strsplit(app.filepath,filesep);
                pathIdx = find(~cellfun(@isempty,pathParts));
                newPath = pathParts(pathIdx(1:end-1));
                if ispc
                    newPath = strcat(newPath, filesep);
                else
                    newPath = strcat(filesep, newPath);
                end
                app.filepath = [newPath{:}];
                if length([app.filepath]) < 80
                    app.currentSelection.Text = [app.filepath];
                else
                    app.currentSelection.Text = [app.filepath(1:40) ' ... ' app.filepath(end-40:end) filesep];
                end
            else
                app.filepath = [app.filepath filesep event.Source.Value];
                if length([app.filepath]) < 80
                    app.currentSelection.Text = [app.filepath];
                else
                    app.currentSelection.Text = [app.filepath(1:40) ' ... ' app.filepath(end-40:end) filesep];
                end
            end
            if ~isempty(app.filepath)
                getSubDirectories(app, app.filepath);
                getFileList(app, app.filepath);
                if length([app.filepath]) < 80
                    app.currentSelection.Text = [app.filepath];
                else
                    app.currentSelection.Text = [app.filepath(1:40) ' ... ' app.filepath(end-40:end) filesep];
                end
            end
        end

        % Double-clicked callback: fileList
        function fileListDoubleClicked(app, event)
            app.filename = event.Source.Value;
            f = which('getFileSelectData');
            if ~isempty(f)
                getFileSelectData(app.callingApp,app.filepath,app.filename); % send data back to calling app
            end
            delete(app);
        end

        % Value changed function: fileList
        function fileListValueChanged(app, event)
            app.filename = event.Source.Value;
        end

        % Value changed function: fileTypeDropDown
        function fileTypeDropDownValueChanged(app, event)
            app.filetype = event.Source.Value;
            getFileList(app, app.filepath);
        end

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            if ~isempty(app.filename)
                f = which('getFileSelectData');
                if ~isempty(f)
                    getFileSelectData(app.callingApp,app.filepath,app.filename); % send data back to calling app
                end
                delete(app);
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.filename = [];
            app.filepath = [];
            closeFcn(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fileSelectFigure = uifigure('Visible', 'off');
            app.fileSelectFigure.Name = 'Select File...';
            app.fileSelectFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.cancelButton = uibutton(app.fileSelectFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'Cancel';
            app.selectButton = uibutton(app.fileSelectFigure, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'Select';
            app.FileTypeDropDownLabel = uilabel(app.fileSelectFigure);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.fileTypeDropDown = uidropdown(app.fileSelectFigure);
            app.fileTypeDropDown.Items = {};
            app.fileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @fileTypeDropDownValueChanged, true);
            app.fileTypeDropDown.Value = {};
            app.fileList = uilistbox(app.fileSelectFigure);
            app.fileList.Items = {};
            app.fileList.ValueChangedFcn = createCallbackFcn(app, @fileListValueChanged, true);
            app.fileList.DoubleClickedFcn = createCallbackFcn(app, @fileListDoubleClicked, true);
            app.fileList.Value = {};
            app.dirList = uilistbox(app.fileSelectFigure);
            app.dirList.Items = {};
            app.dirList.DoubleClickedFcn = createCallbackFcn(app, @dirListDoubleClicked, true);
            app.dirList.Value = {};
            app.folderListLabel = uilabel(app.fileSelectFigure);
            app.folderListLabel.Text = 'Folder List';
            app.fileListLabel = uilabel(app.fileSelectFigure);
            app.fileListLabel.Text = 'File List';
            app.currentSelection = uilabel(app.fileSelectFigure);
            app.currentSelection.Text = '';
            % Show the figure after all components are created
            app.fileSelectFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fileSelect(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fileSelectFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.fileSelectFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fileSelectFigure)
        end
    end
end