classdef montageEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        montageFigure          matlab.ui.Figure
        SleepStageLabel        matlab.ui.control.Label
        SleepStageEdit         matlab.ui.control.EditField
        EpochLabel             matlab.ui.control.Label
        EpochDurationEdit      matlab.ui.control.Spinner
        LightsOffLabel         matlab.ui.control.Label
        LightsOffEdit          matlab.ui.control.EditField
        LightsOnLabel          matlab.ui.control.Label
        LightsOnEdit           matlab.ui.control.EditField
        RecordingStartLabel    matlab.ui.control.Label
        RecordingStartEdit     matlab.ui.control.EditField
        ChannelSelectionPanel  matlab.ui.container.Panel
        ChannelsToAddEdit      matlab.ui.control.EditField
        ChannelstoaddLabel     matlab.ui.control.Label
        AvailableListBox       matlab.ui.control.ListBox
        IncludedListBox        matlab.ui.control.ListBox
        IncludeButton          matlab.ui.control.Button
        RemoveButton           matlab.ui.control.Button
        MovedownButton         matlab.ui.control.Button
        MoveupButton           matlab.ui.control.Button
        ColourPicker           matlab.ui.control.ColorPicker
        ChanbnelColourLabel    matlab.ui.control.Label
        IncludedListBoxLabel   matlab.ui.control.Label
        AvailableListBoxLabel  matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
    end

    
% Copyright (C) Stuart Fogel & Sleep Well, 2026.
% 
% This file is part of 'Counting Sheep PSG'.
%
% See https://github.com/stuartfogel/CountingSheepPSG for details.
% 
% Redistribution and use in source and binary forms, with or without 
% modification, are permitted provided that the following conditions are 
% met:
% 
% 1. Redistributions of source code must retain the above author, licence, 
% copyright notice, this list of conditions, and the following disclaimer.
% 
% 2. Redistributions in binary form must reproduce the above author, 
% licence, copyright notice, this list of conditions, and the following 
% disclaimer in the documentation and/or other materials provided with the 
% distribution.
% 
% 3. Neither the name of the copyright holder nor the names of its 
% contributors may be used to endorse or promote products derived from this
% software without specific prior written permission.
% 
% THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS 
% "AS IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED
% TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A 
% PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT HOLDER
% OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, 
% EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT LIMITED TO, 
% PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR 
% PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF 
% LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING 
% NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS 
% SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE. 
% 
% THIS SOFTWARE IS INTENDED FOR RESEARCH PURPOSES ONLY. ANY COMMERCIAL 
% USE OR MEDICAL USE OF THIS SOFTWARE AND SOURCE CODE IS STRICTLY 
% PROHIBITED.

    properties (Access = private)
        ChannelColours % colour selected for each channel
        callingApp % properties of callingApp
    end
    
    properties (Access = public)
        montageData % montage settings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFunction(app, callingApp, EEG)
            if nargin < 3
                app.AvailableListBox.Items = {};
                app.ChannelColours = cell(1,length(app.IncludedListBox.Items));
            else
                if ~isempty(EEG)
                    if ~isempty(EEG.chanlocs)
                        app.AvailableListBox.Items = {EEG.chanlocs.labels};
                        app.ChannelColours = cell(1,length(app.IncludedListBox.Items));
                        for nCC = 1:length(app.ChannelColours)
                            app.ChannelColours{nCC} = rgb2hex([0 0 0]);
                        end
                    else
                        app.AvailableListBox.Items = {};
                        app.ChannelColours = cell(1,length(app.IncludedListBox.Items));
                    end
                end
            end
            if nargin < 2
                app.callingApp = [];
            else 
                app.callingApp = callingApp;
            end
        end

        % Value changed function: SleepStageEdit
        function SleepStageEditValueChanged(app, event)
            if any(ismember(app.SleepStageEdit.Value,',')) || any(ismember(app.SleepStageEdit.Value,'  '))
                app.SleepStageEdit.Value = strrep(strrep(app.SleepStageEdit.Value,',',' '),'  ',' ');
            end
            app.SleepStageEdit.Value = strip(app.SleepStageEdit.Value);
        end

        % Value changed function: EpochDurationEdit
        function EpochDurationEditValueChanged(app, event)
            app.EpochDurationEdit.Value = round(app.EpochDurationEdit.Value/5)*5; % round to nearest multiple of 5
        end

        % Value changed function: LightsOffEdit
        function LightsOffEditValueChanged(app, event)
            app.LightsOffEdit.Value = strip(app.LightsOffEdit.Value);
        end

        % Value changed function: LightsOnEdit
        function LightsOnEditValueChanged(app, event)
            app.LightsOnEdit.Value = strip(app.LightsOnEdit.Value);
        end

        % Value changed function: RecordingStartEdit
        function RecordingStartEditValueChanged(app, event)
            app.RecordingStartEdit.Value = strip(app.RecordingStartEdit.Value);
        end

        % Value changed function: ChannelsToAddEdit
        function ChannelsToAddChanged(app, event)
            NewChannels = strsplit(app.ChannelsToAddEdit.Value,{' ',','});
            app.ChannelsToAddEdit.Value = '';
            if ~iscell(app.AvailableListBox.Items)
                app.AvailableListBox.Items = NewChannels;
            else
                app.AvailableListBox.Items = unique([app.AvailableListBox.Items NewChannels],'stable');
            end
            app.ChannelsToAddEdit.Value = strip(app.ChannelsToAddEdit.Value);
        end

        % Button pushed function: IncludeButton
        function IncludeButtonPushed(app, event)
            Channels2Include = app.AvailableListBox.Value;
            AvailableChannelOrder = 1:length(app.AvailableListBox.Items);
            IncludedChannels = ismember(app.AvailableListBox.Items, Channels2Include);
            IncludedChannelOrder = IncludedChannels(AvailableChannelOrder);
            Channels2Include = app.AvailableListBox.Items(IncludedChannelOrder);
            app.IncludedListBox.Items = unique([app.IncludedListBox.Items Channels2Include],'stable');
            app.IncludedListBox.Value = app.AvailableListBox.Value;
            app.AvailableListBox.Value = {};
            app.IncludedListBox.Value = {};
            NewColours = cell(1,length(Channels2Include));
            for nNC = 1:length(NewColours)
                NewColours{nNC} = rgb2hex([0 0 0]);
            end
            app.ChannelColours = [app.ChannelColours NewColours];
            for nChColour = 1:length(app.IncludedListBox.Items)
                addStyle(app.IncludedListBox, uistyle(FontColor = app.ChannelColours{nChColour}), "item", nChColour);
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.ChannelColours = app.ChannelColours(~ismember(app.IncludedListBox.Items, app.IncludedListBox.Value));
            app.IncludedListBox.Items = app.IncludedListBox.Items(~ismember(app.IncludedListBox.Items, app.IncludedListBox.Value));
            for nChColour = 1:length(app.IncludedListBox.Items)
                addStyle(app.IncludedListBox, uistyle(FontColor = app.ChannelColours{nChColour}), "item", nChColour);
            end
        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            Channel2MoveUp = app.IncludedListBox.Value;
            channelIdxMap = containers.Map(app.IncludedListBox.Items, 1:length(app.IncludedListBox.Items));
            numChannels = length(app.IncludedListBox.Items);
            IncludedChannelOrder = 1:numChannels;
            for nCh = 1:length(Channel2MoveUp)
                ChannelIdx = channelIdxMap(Channel2MoveUp{nCh});
                if ChannelIdx > 1
                    IncludedChannelOrder([ChannelIdx, ChannelIdx-1]) = IncludedChannelOrder([ChannelIdx-1, ChannelIdx]);
                    app.IncludedListBox.Items = app.IncludedListBox.Items(IncludedChannelOrder);
                    app.ChannelColours = app.ChannelColours(IncludedChannelOrder);
                else
                    return;
                end
            end
            for nChColour = 1:numChannels
                addStyle(app.IncludedListBox, uistyle('FontColor', app.ChannelColours{nChColour}), 'item', nChColour);
            end
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)
            Channel2MoveDown = app.IncludedListBox.Value;
            channelIdxMap = containers.Map(app.IncludedListBox.Items, 1:length(app.IncludedListBox.Items));
            numChannels = length(app.IncludedListBox.Items);
            IncludedChannelOrder = 1:numChannels;
            for nCh = 1:length(Channel2MoveDown)
                ChannelIdx = channelIdxMap(Channel2MoveDown{nCh});
                if ChannelIdx < max(IncludedChannelOrder)
                    IncludedChannelOrder([ChannelIdx, ChannelIdx+1]) = IncludedChannelOrder([ChannelIdx+1, ChannelIdx]);
                    app.IncludedListBox.Items = app.IncludedListBox.Items(IncludedChannelOrder);
                    app.ChannelColours = app.ChannelColours(IncludedChannelOrder);
                else
                    return;
                end
            end
            for nChColour = 1:numChannels
                addStyle(app.IncludedListBox, uistyle('FontColor', app.ChannelColours{nChColour}), 'item', nChColour);
            end
        end

        % Callback function: ColourPicker
        function ColourPickerChanged(app, event)
            NewColour = app.ColourPicker.Value;
            Channel = app.IncludedListBox.Value;
            for nCh = flip(1:length(Channel))
                ChannelIdx = find(ismember(app.IncludedListBox.Items, Channel(nCh)));
                app.ChannelColours{ChannelIdx} = rgb2hex(NewColour);
                addStyle(app.IncludedListBox, uistyle(FontColor = NewColour), "item", ChannelIdx);
            end
            app.ColourPicker.Value = [0 0 0];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            CloseFunction(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: montageFigure
        function CloseFunction(app, event)
            app.montageData.StageNames = app.SleepStageEdit.Value;
            app.montageData.EpochDuration = num2str(app.EpochDurationEdit.Value);
            app.montageData.LightsOff = app.LightsOffEdit.Value;
            app.montageData.LightsOn = app.LightsOnEdit.Value;
            app.montageData.RecordingStart = app.RecordingStartEdit.Value;
            if isempty(app.IncludedListBox.Items)
                choice = uiconfirm(app.montageFigure,'Please include at least one channel.','Missing channel(s)','Icon','warning');
                waitfor(choice);
                return
            else
                app.montageData.ChannelList = app.IncludedListBox.Items;
            end
            app.montageData.ChannelColours = app.ChannelColours;
            getMontageData(app.callingApp, app.montageData); % send data back to calling app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.montageFigure = uifigure('Visible', 'off');
            app.montageFigure.Name = 'Montage Editor';
            app.montageFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFunction, true);
            app.CancelButton = uibutton(app.montageFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.montageFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ChannelSelectionPanel = uipanel(app.montageFigure);
            app.ChannelSelectionPanel.Title = 'Channel selection';
            app.AvailableListBoxLabel = uilabel(app.ChannelSelectionPanel);
            app.AvailableListBoxLabel.Text = 'Available:';
            app.IncludedListBoxLabel = uilabel(app.ChannelSelectionPanel);
            app.IncludedListBoxLabel.Text = 'Included:';
            app.ChanbnelColourLabel = uilabel(app.ChannelSelectionPanel);
            app.ChanbnelColourLabel.Text = 'Channel Colour';
            app.ColourPicker = uicolorpicker(app.ChannelSelectionPanel);
            app.ColourPicker.Value = [0 0 0];
            app.ColourPicker.ValueChangedFcn = createCallbackFcn(app, @ColourPickerChanged, true);
            app.MoveupButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Text = 'Move down';
            app.RemoveButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<---';
            app.IncludeButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.IncludeButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeButtonPushed, true);
            app.IncludeButton.Text = '--->';
            app.IncludedListBox = uilistbox(app.ChannelSelectionPanel);
            app.IncludedListBox.Items = {};
            app.IncludedListBox.Multiselect = 'on';
            app.IncludedListBox.Value = {};
            app.AvailableListBox = uilistbox(app.ChannelSelectionPanel);
            app.AvailableListBox.Items = {};
            app.AvailableListBox.Multiselect = 'on';
            app.AvailableListBox.Value = {};
            app.ChannelstoaddLabel = uilabel(app.ChannelSelectionPanel);
            app.ChannelstoaddLabel.Text = 'Channels to add:';
            app.ChannelsToAddEdit = uieditfield(app.ChannelSelectionPanel, 'text');
            app.ChannelsToAddEdit.ValueChangedFcn = createCallbackFcn(app, @ChannelsToAddChanged, true);
            app.RecordingStartEdit = uieditfield(app.montageFigure, 'text');
            app.RecordingStartEdit.ValueChangedFcn = createCallbackFcn(app, @RecordingStartEditValueChanged, true);
            app.RecordingStartEdit.Value = 'yyyy-MM-dd HH:mm:ss.SSS';
            app.RecordingStartLabel = uilabel(app.montageFigure);
            app.RecordingStartLabel.Text = 'Enter Recordings Start tag date and 24-hr time format (Default: yyyy-MM-dd HH:mm:ss.SSS):';
            app.LightsOnEdit = uieditfield(app.montageFigure, 'text');
            app.LightsOnEdit.ValueChangedFcn = createCallbackFcn(app, @LightsOnEditValueChanged, true);
            app.LightsOnEdit.Value = 'Lights On';
            app.LightsOnLabel = uilabel(app.montageFigure);
            app.LightsOnLabel.Text = 'Enter lights On tag (Default: Lights On):';
            app.LightsOffEdit = uieditfield(app.montageFigure, 'text');
            app.LightsOffEdit.ValueChangedFcn = createCallbackFcn(app, @LightsOffEditValueChanged, true);
            app.LightsOffEdit.Value = 'Lights Off';
            app.LightsOffLabel = uilabel(app.montageFigure);
            app.LightsOffLabel.Text = 'Enter Lights Off tag (Default: Lights Off):';
            app.EpochDurationEdit = uispinner(app.montageFigure);
            app.EpochDurationEdit.Step = 5;
            app.EpochDurationEdit.Limits = [5 300];
            app.EpochDurationEdit.RoundFractionalValues = 'on';
            app.EpochDurationEdit.ValueDisplayFormat = '%.0f';
            app.EpochDurationEdit.ValueChangedFcn = createCallbackFcn(app, @EpochDurationEditValueChanged, true);
            app.EpochDurationEdit.Value = 30;
            app.EpochLabel = uilabel(app.montageFigure);
            app.EpochLabel.Text = 'Enter epoch duration in seconds (Default: 30):';
            app.SleepStageEdit = uieditfield(app.montageFigure, 'text');
            app.SleepStageEdit.ValueChangedFcn = createCallbackFcn(app, @SleepStageEditValueChanged, true);
            app.SleepStageEdit.Value = 'W N1 N2 SWS REM Unscored';
            app.SleepStageLabel = uilabel(app.montageFigure);
            app.SleepStageLabel.Text = 'Enter Sleep Stage labels (Default: W N1 N2 SWS REM Unscored):';
            % Show the figure after all components are created
            app.montageFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montageEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.montageFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFunction(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.montageFigure)
        end
    end
end